classdef winConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Tab3_Grid                       matlab.ui.container.GridLayout
        config_geoAxesSubPanel          matlab.ui.container.Panel
        config_geoAxesSubGrid           matlab.ui.container.GridLayout
        play_Persistance_cLim_Grid2     matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        play_Persistance_cLim2          matlab.ui.control.Spinner
        play_Persistance_cLim1          matlab.ui.control.Spinner
        LimitesLabel                    matlab.ui.control.Label
        LegendadecorLabel               matlab.ui.control.Label
        DropDownLocalBarra              matlab.ui.control.DropDown
        config_Colormap                 matlab.ui.control.DropDown
        config_ColormapLabel            matlab.ui.control.Label
        config_Basemap                  matlab.ui.control.DropDown
        config_BasemapLabel             matlab.ui.control.Label
        config_FolderMapLabel_2         matlab.ui.control.Label
        config_FolderMapPanel_2         matlab.ui.container.Panel
        config_FolderMapGrid_2          matlab.ui.container.GridLayout
        config_Folder_userPathButton_2  matlab.ui.control.Image
        config_Folder_userPath_2        matlab.ui.control.DropDown
        config_Folder_userPathLabel_2   matlab.ui.control.Label
        config_Folder_pythonPathButton_2  matlab.ui.control.Image
        config_Folder_pythonPath_2      matlab.ui.control.EditField
        config_Folder_pythonPathLabel_2  matlab.ui.control.Label
        config_Folder_DataHubPOSTButton_2  matlab.ui.control.Image
        config_Folder_DataHubPOST_2     matlab.ui.control.EditField
        config_Folder_DataHubPOSTLabel_2  matlab.ui.control.Label
        config_Folder_DataHubGETButton_2  matlab.ui.control.Image
        config_Folder_DataHubGET_2      matlab.ui.control.EditField
        config_Folder_DataHubGETLabel_2  matlab.ui.control.Label
        misc_ElevationSourcePanel       matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        EditFieldDist_PARNI             matlab.ui.control.NumericEditField
        EditFieldDistMedicoes           matlab.ui.control.NumericEditField
        DistPARNILabel                  matlab.ui.control.Label
        DisMedicoesLabel                matlab.ui.control.Label
        ANLISELabel                     matlab.ui.control.Label
        config_Refresh                  matlab.ui.control.Image
        config_geoAxesLabel             matlab.ui.control.Label
        GridLayout                      matlab.ui.container.GridLayout
        jsBackDoor                      matlab.ui.control.HTML
        tool_RFDataHubButton            matlab.ui.control.Image
        tool_FiscalizaButton            matlab.ui.control.Image
        tool_LeftPanelVisibility        matlab.ui.control.Image
        Document                        matlab.ui.container.GridLayout
        Folders_Grid                    matlab.ui.container.GridLayout
        config_FolderMapPanel           matlab.ui.container.Panel
        config_FolderMapGrid            matlab.ui.container.GridLayout
        config_Folder_tempPath          matlab.ui.control.EditField
        config_Folder_tempPathLabel     matlab.ui.control.Label
        config_Folder_userPathButton    matlab.ui.control.Image
        config_Folder_userPath          matlab.ui.control.EditField
        config_Folder_userPathLabel     matlab.ui.control.Label
        config_Folder_pythonPathButton  matlab.ui.control.Image
        config_Folder_pythonPath        matlab.ui.control.EditField
        config_Folder_pythonPathLabel   matlab.ui.control.Label
        config_Folder_DataHubPOSTButton  matlab.ui.control.Image
        config_Folder_DataHubPOST       matlab.ui.control.EditField
        config_Folder_DataHubPOSTLabel  matlab.ui.control.Label
        config_Folder_DataHubGETButton  matlab.ui.control.Image
        config_Folder_DataHubGET        matlab.ui.control.EditField
        config_Folder_DataHubGETLabel   matlab.ui.control.Label
        config_FolderMapLabel           matlab.ui.control.Label
        APIFiscaliza_Grid               matlab.ui.container.GridLayout
        config_FiscalizaVersion         matlab.ui.container.ButtonGroup
        config_FiscalizaHM              matlab.ui.control.RadioButton
        config_FiscalizaPD              matlab.ui.control.RadioButton
        config_FiscalizaVersionLabel    matlab.ui.control.Label
        General_Grid                    matlab.ui.container.GridLayout
        general_ElevationPanel          matlab.ui.container.Panel
        general_ElevationGrid           matlab.ui.container.GridLayout
        ElevationForceSearch            matlab.ui.control.CheckBox
        ElevationNPoints                matlab.ui.control.DropDown
        ElevationNPointsLabel           matlab.ui.control.Label
        ElevationAPIServer              matlab.ui.control.DropDown
        ElevationAPIServerLabel         matlab.ui.control.Label
        general_ElevationRefresh        matlab.ui.control.Image
        general_ElevationLabel          matlab.ui.control.Label
        general_GraphicsPanel           matlab.ui.container.Panel
        general_GraphicsGrid            matlab.ui.container.GridLayout
        openAuxiliarApp2Debug           matlab.ui.control.CheckBox
        openAuxiliarAppAsDocked         matlab.ui.control.CheckBox
        imgResolution                   matlab.ui.control.DropDown
        imgResolutionLabel              matlab.ui.control.Label
        imgFormat                       matlab.ui.control.DropDown
        imgFormatLabel                  matlab.ui.control.Label
        gpuType                         matlab.ui.control.DropDown
        gpuTypeLabel                    matlab.ui.control.Label
        graphics_Refresh                matlab.ui.control.Image
        general_GraphicsLabel           matlab.ui.control.Label
        general_FilePanel               matlab.ui.container.Panel
        general_FileGrid                matlab.ui.container.GridLayout
        mergeDistance                   matlab.ui.control.Spinner
        mergeLabel2                     matlab.ui.control.Label
        mergeAntenna                    matlab.ui.control.CheckBox
        mergeDataType                   matlab.ui.control.CheckBox
        mergeLabel1                     matlab.ui.control.Label
        general_mergeRefresh            matlab.ui.control.Image
        general_FileLabel               matlab.ui.control.Label
        general_AppVersionPanel         matlab.ui.container.Panel
        general_AppVersionGrid          matlab.ui.container.GridLayout
        AppVersion                      matlab.ui.control.HTML
        general_AppVersionRefresh       matlab.ui.control.Image
        general_AppVersionLabel         matlab.ui.control.Label
        LeftPanel                       matlab.ui.container.Panel
        LeftPanelGrid                   matlab.ui.container.GridLayout
        LeftPanelRadioGroup             matlab.ui.container.ButtonGroup
        btnFolder                       matlab.ui.control.RadioButton
        btnFiscaliza                    matlab.ui.control.RadioButton
        btnGeneral                      matlab.ui.control.RadioButton
        LeftPanelTitle                  matlab.ui.container.GridLayout
        menuUnderline                   matlab.ui.control.Image
        menu_ButtonGrid                 matlab.ui.container.GridLayout
        menu_ButtonIcon                 matlab.ui.control.Image
        menu_ButtonLabel                matlab.ui.control.Label
    end

    
    properties
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        
        CallingApp
        rootFolder

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        stableVersion
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        DefaultValues = struct('File',      struct('DataType', true, 'DataTypeLabel', 'remove', 'Antenna', true, 'AntennaLabel', 'remove', 'AntennaAttributes', {{'Name', 'Azimuth', 'Elevation', 'Polarization', 'Height', 'SwitchPort', 'LNBChannel'}}, 'Distance', 100), ...
                               'Graphics',  struct('openGL', 'hardware', 'Format', 'jpeg', 'Resolution', '120', 'Dock', true),                               ...
                               'Elevation', struct('Points', '256', 'ForceSearch', false, 'Server', 'Open-Elevation'))
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        % JSBACKDOOR
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource();
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            if app.isDocked
                app.progressDialog = app.CallingApp.progressDialog;
            else
                app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
            end
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)                
                startup_Controller(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)
            drawnow
            
            % Customiza as aspectos estéticos de alguns dos componentes da GUI 
            % (diretamente em JS).
            jsBackDoor_Customizations(app)

            switch app.CallingApp.executionMode
                case 'webApp'
                    app.general_AppVersionRefresh.Enable = 0;
                    app.openAuxiliarAppAsDocked.Enable   = 0;

                otherwise
                    if ~app.isDocked
                        % appUtil.winMinSize(app.UIFigure, class.Constants.WindowMinSize('CONFIG'))
                    end
            end

            if isdeployed
                app.openAuxiliarApp2Debug.Enable = 0;
            end

            % Atualização dos painéis...
            app.progressDialog.Visible = 'visible';

            % startup_checkPythonVersion(app)
            % 
            % AppVersion_updatePanel(app)
            % File_updatePanel(app)
            % Graphics_updatePanel(app)
            % Elevation_updatePanel(app)
            % APIFiscaliza_updatePanel(app)
            % Folder_updatePanel(app)

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function startup_checkPythonVersion(app)
            if isempty(app.CallingApp.General.AppVersion.fiscaliza)
                app.CallingApp.General.AppVersion = fcn.envVersion(app.rootFolder, 'full+Python');
            end
        end

        %-----------------------------------------------------------------%
        function AppVersion_updatePanel(app)
            % Versão
            htmlContent = auxApp.config.htmlCode_AppVersion(app.CallingApp.General, app.CallingApp.executionMode);
            app.AppVersion.HTMLSource = htmlContent;
        end

        %-----------------------------------------------------------------%
        function File_updatePanel(app)
            % Mesclagem de fluxos espectrais
            switch app.CallingApp.General.Merge.DataType
                case 'keep';   app.mergeDataType.Value = 0;
                case 'remove'; app.mergeDataType.Value = 1;
            end

            switch app.CallingApp.General.Merge.Antenna
                case 'keep';   app.mergeAntenna.Value = 0;
                case 'remove'; app.mergeAntenna.Value = 1;
            end

            app.mergeDistance.Value = app.CallingApp.General.Merge.Distance;

            if File_checkEdition(app)
                app.general_mergeRefresh.Visible = 1;
            else
                app.general_mergeRefresh.Visible = 0;
            end
        end

        %-----------------------------------------------------------------%
        function editionFlag = File_checkEdition(app)
            editionFlag = false;

            if (app.mergeDataType.Value ~= app.DefaultValues.File.DataType) || ...
               (app.mergeAntenna.Value  ~= app.DefaultValues.File.Antenna)  || ...
               (abs(app.mergeDistance.Value - app.DefaultValues.File.Distance) > class.Constants.floatDiffTolerance)

                editionFlag = true;
            end
        end

        %-----------------------------------------------------------------%
        function Graphics_updatePanel(app)
            % Imagem relatório
            app.imgFormat.Value     = app.CallingApp.General.Image.Format;
            app.imgResolution.Value = string(app.CallingApp.General.Image.Resolution);

            % Renderizador
            graphRender = opengl('data');
            switch graphRender.HardwareSupportLevel
                case 'basic'; graphRenderSupport = 'hardwarebasic';
                case 'full';  graphRenderSupport = 'hardware';
                case 'none';  graphRenderSupport = 'software';
                otherwise;    graphRenderSupport = graphRender.HardwareSupportLevel; % "driverissue"
            end

            if ~ismember(graphRenderSupport, app.gpuType.Items)
                app.gpuType.Items{end+1} = graphRenderSupport;
            end

            app.gpuType.Value = graphRenderSupport;

            % Modo de operação
            app.openAuxiliarAppAsDocked.Value = app.CallingApp.General.operationMode.Dock;
            app.openAuxiliarApp2Debug.Value   = app.CallingApp.General.operationMode.Debug;

            if Graphics_checkEdition(app)
                app.graphics_Refresh.Visible = 1;
            else
                app.graphics_Refresh.Visible = 0;
            end
        end

        %-----------------------------------------------------------------%
        function editionFlag = Graphics_checkEdition(app)
            editionFlag = false;

            if ~strcmp(app.gpuType.Value,            app.DefaultValues.Graphics.openGL)     || ...
               ~strcmp(app.imgFormat.Value,          app.DefaultValues.Graphics.Format)     || ...
               ~strcmp(app.imgResolution.Value,      app.DefaultValues.Graphics.Resolution) || ...
               (app.openAuxiliarAppAsDocked.Value ~= app.DefaultValues.Graphics.Dock)

                editionFlag = true;
            end
        end

        %-----------------------------------------------------------------%
        function Elevation_updatePanel(app)
            app.ElevationNPoints.Value     = num2str(app.CallingApp.General.Elevation.Points);
            app.ElevationForceSearch.Value = app.CallingApp.General.Elevation.ForceSearch;
            app.ElevationAPIServer.Value   = app.CallingApp.General.Elevation.Server;

            if Elevation_checkEdition(app)
                app.general_ElevationRefresh.Visible = 1;
            else
                app.general_ElevationRefresh.Visible = 0;
            end
        end

        %-----------------------------------------------------------------%
        function editionFlag = Elevation_checkEdition(app)
            editionFlag = false;

            if ~strcmp(app.ElevationNPoints.Value, app.DefaultValues.Elevation.Points)     || ...
               (app.ElevationForceSearch.Value ~= app.DefaultValues.Elevation.ForceSearch) || ...
               ~strcmp(app.ElevationAPIServer.Value, app.DefaultValues.Elevation.Server)
                
                editionFlag = true;
            end
        end

        %-----------------------------------------------------------------%
        function APIFiscaliza_updatePanel(app)
            switch app.CallingApp.General.fiscaliza.systemVersion
                case 'PROD'
                    app.config_FiscalizaPD.Value = 1;
                case 'HOM'
                    app.config_FiscalizaHM.Value = 1;
            end
        end

        %-----------------------------------------------------------------%
        function Folder_updatePanel(app)
            % Na versão webapp, a configuração das pastas não é habilitada.

            switch app.CallingApp.executionMode
                case 'webApp'
                    app.btnFolder.Enable = 0;

                otherwise
                    % DataHub
                    DataHub_GET  = app.CallingApp.General.fileFolder.DataHub_GET;
                    DataHub_POST = app.CallingApp.General.fileFolder.DataHub_POST;
                    if isfolder(DataHub_GET)
                        app.config_Folder_DataHubGET.Value  = DataHub_GET;
                    end
        
                    if isfolder(DataHub_POST)
                        app.config_Folder_DataHubPOST.Value = DataHub_POST;
                    end
        
                    % Python
                    pythonPath = app.CallingApp.General.fileFolder.pythonPath;
                    if isfile(pythonPath)
                        app.config_Folder_pythonPath.Value = pythonPath;
                    else
                        pyEnv = pyenv;
                        app.config_Folder_pythonPath.Value = pyEnv.Executable;
                    end
    
                    % userPath & tempPath
                    app.config_Folder_userPath.Value = app.CallingApp.General.fileFolder.userPath;
                    app.config_Folder_tempPath.Value = app.CallingApp.General.fileFolder.tempPath;
            end
        end

        %-----------------------------------------------------------------%
        function saveGeneralSettings(app)
            appUtil.generalSettingsSave(class.Constants.appName, app.rootFolder, app.CallingApp.General_I, app.CallingApp.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % A razão de ser deste app é possibilitar visualização/edição 
            % de algumas das informações do arquivo "GeneralSettings.json",
            % além de possibilitar o mapeamento do PYTHON, a atualização da
            % lib FISCALIZA e da ETL RFDATAHUB.

            app.CallingApp = mainapp;
            app.rootFolder = app.CallingApp.rootFolder;

            jsBackDoor_Initialization(app)
            % LeftPanelRadioGroupSelectionChanged(app)

            if app.isDocked
                app.GridLayout.Padding(4) = 19;
                startup_Controller(app)
            else
                appUtil.winPosition(app.UIFigure)
                startup_timerCreation(app)
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            appBackDoor(app.CallingApp, app, 'closeFcn', 'CONFIG')
            delete(app)
            
        end

        % Image clicked function: tool_LeftPanelVisibility
        function tool_LeftPanelVisibilityClicked(app, event)
            
            focus(app.jsBackDoor)

            if app.Document.ColumnWidth{1}
                app.tool_LeftPanelVisibility.ImageSource = 'ArrowRight_32.png';
                app.Document.ColumnWidth{1} = 0;
            else
                app.tool_LeftPanelVisibility.ImageSource = 'ArrowLeft_32.png';
                app.Document.ColumnWidth{1} = 320;
            end

        end

        % Image clicked function: tool_FiscalizaButton
        function tool_FiscalizaButtonPushed(app, event)
            
            if strcmp(app.CallingApp.General.AppVersion.fiscaliza, app.stableVersion.fiscaliza)
                app.tool_FiscalizaButton.Enable = 0;
                appUtil.modalWindow(app.UIFigure, 'warning', 'Módulo fiscaliza já atualizado!');
                return
            end

            app.progressDialog.Visible = 'visible';

            try
                Python = pyenv;
                system(sprintf('"%s" %s', fullfile(Python.Home, 'Scripts', 'pip'), sprintf('install fiscaliza==%s', app.stableVersion.fiscaliza)));

                app.CallingApp.General.AppVersion.fiscaliza = app.stableVersion.fiscaliza;
                app.tool_FiscalizaButton.Enable = 0;

                Layout(app)

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end

            app.progressDialog.Visible = 'hidden';

        end

        % Image clicked function: tool_RFDataHubButton
        function tool_RFDataHubButtonPushed(app, event)
            
            if isequal(app.CallingApp.General.AppVersion.RFDataHub,  app.stableVersion.RFDataHub)
                app.tool_RFDataHubButton.Enable = 0;
                appUtil.modalWindow(app.UIFigure, 'warning', 'Módulo RFDataHub já atualizado!');
                return
            end

            d = appUtil.modalWindow(app.UIFigure, "progressdlg", 'Em andamento... esse processo pode demorar alguns minutos!');

            try
                [~, ~, rfdatahubLink] = fcn.PublicLinks(app.rootFolder);

                tempDir = tempname;
                mkdir(tempDir)

                websave(fullfile(tempDir, 'estacoes.parquet.gzip'), rfdatahubLink.Table);
                websave(fullfile(tempDir, 'log.parquet.gzip'),      rfdatahubLink.Log);
                websave(fullfile(tempDir, 'Release.json'),          rfdatahubLink.Release);

                if isfile(fullfile(app.rootFolder, 'DataBase', 'RFDataHub_old.mat'))
                    delete(fullfile(app.rootFolder, 'DataBase', 'RFDataHub_old.mat'))
                end

                while true
                    status = system(sprintf('rename "%s" "%s"', fullfile(app.rootFolder, 'DataBase', 'RFDataHub.mat'), 'RFDataHub_old.mat'));
                    if ~status
                        break
                    end
                    pause(.1)
                end

                % Apaga as variáveis globais, lendo os novos arquivos.
                clear global RFDataHub
                clear global RFDataHubLog
                clear global RFDataHub_info
                class.RFDataHub.read(app.rootFolder, tempDir)

                % Apaga os arquivos temporários.
                rmdir(tempDir, 's')

                % Atualiza versão.
                global RFDataHub_info
                app.CallingApp.General.AppVersion.RFDataHub = RFDataHub_info;
                app.tool_RFDataHubButton.Enable = 0;
                
            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end

            AppVersion_updatePanel(app)

            delete(d)

        end

        % Selection changed function: LeftPanelRadioGroup
        function LeftPanelRadioGroupSelectionChanged(app, event)
            
            selectedButton = app.LeftPanelRadioGroup.SelectedObject;
            switch selectedButton
                case app.btnGeneral;   app.Document.ColumnWidth(2:end) = {'1x',0,0};
                case app.btnFiscaliza; app.Document.ColumnWidth(2:end) = {0,'1x',0};
                case app.btnFolder;    app.Document.ColumnWidth(2:end) = {0,0,'1x'};
            end
            
        end

        % Image clicked function: general_AppVersionRefresh
        function AppVersion_refreshButtonPushed(app, event)
            
            app.progressDialog.Visible = 'visible';

            [htmlContent, app.stableVersion, updatedModule] = auxApp.config.htmlCode_CheckAvailableUpdate(app.CallingApp.General, app.rootFolder);
            appUtil.modalWindow(app.UIFigure, "info", htmlContent);
            
            if ~ismember('RFDataHub', updatedModule)
                app.tool_RFDataHubButton.Enable = 1;
            end

            if ~ismember('fiscaliza', updatedModule)
                app.tool_FiscalizaButton.Enable = 1;
            end            

            app.progressDialog.Visible = 'hidden';

        end

        % Callback function: general_mergeRefresh, mergeAntenna, 
        % ...and 2 other components
        function File_ParameterValueChanged(app, event)
            
            switch event.Source
                %---------------------------------------------------------%
                case {app.mergeDataType, app.mergeAntenna, app.mergeDistance}                    
                    switch app.mergeDataType.Value
                        case 0; DataTypeStatus = 'keep';
                        case 1; DataTypeStatus = 'remove';
                    end
                    
                    switch app.mergeAntenna.Value
                        case 0; AntennaStatus  = 'keep';
                        case 1; AntennaStatus  = 'remove';
                    end
                                
                    app.CallingApp.General.Merge.DataType = DataTypeStatus;
                    app.CallingApp.General.Merge.Antenna  = AntennaStatus;
                    app.CallingApp.General.Merge.Distance = app.mergeDistance.Value;

                %---------------------------------------------------------%
                case app.general_mergeRefresh
                    app.CallingApp.General.Merge = struct('DataType',          app.DefaultValues.File.DataTypeLabel,     ...
                                                          'Antenna',           app.DefaultValues.File.AntennaLabel,      ...
                                                          'AntennaAttributes', app.DefaultValues.File.AntennaAttributes, ...
                                                          'Distance',          app.DefaultValues.File.Distance);
            end

            app.CallingApp.General_I.Merge = app.CallingApp.General.Merge;            
            saveGeneralSettings(app)
            File_updatePanel(app)  

        end

        % Callback function: gpuType, graphics_Refresh, imgFormat, 
        % ...and 3 other components
        function Graphics_ParameterValueChanged(app, event)
            
            switch event.Source
                case app.gpuType
                    if ismember(app.gpuType.Value, {'software', 'hardware', 'hardwarebasic'})
                        eval(sprintf('opengl %s', app.gpuType.Value))

                        graphRender = opengl('data');
                        
                        app.CallingApp.General.openGL = app.gpuType.Value;
                        app.CallingApp.General.AppVersion.OpenGL = rmfield(graphRender, {'MaxTextureSize', 'Visual', 'SupportsGraphicsSmoothing', 'SupportsDepthPeelTransparency', 'SupportsAlignVertexCenters', 'Extensions', 'MaxFrameBufferSize'});
                    end

                case {app.imgFormat, app.imgResolution}
                    app.CallingApp.General.Image = struct('Format', app.imgFormat.Value, 'Resolution', str2double(app.imgResolution.Value));

                case app.openAuxiliarAppAsDocked
                    app.CallingApp.General.operationMode.Dock = app.openAuxiliarAppAsDocked.Value;

                case app.openAuxiliarApp2Debug
                    app.CallingApp.General.operationMode.Debug = app.openAuxiliarApp2Debug.Value;

                case app.graphics_Refresh
                    if ~strcmp(app.gpuType.Value, app.DefaultValues.Graphics.openGL)
                        app.gpuType.Value = app.DefaultValues.Graphics.openGL;
                        Graphics_ParameterValueChanged(app, struct('Source', app.gpuType))
                    end

                    app.CallingApp.General.Image = struct('Format', app.DefaultValues.Graphics.Format, 'Resolution', app.DefaultValues.Graphics.Resolution);
                    app.CallingApp.General.operationMode.Dock  = app.DefaultValues.Graphics.Dock;
            end

            app.CallingApp.General_I.openGL        = app.CallingApp.General.openGL;
            app.CallingApp.General_I.Image         = app.CallingApp.General.Image;
            app.CallingApp.General_I.operationMode = app.CallingApp.General.operationMode;
            saveGeneralSettings(app)
            Graphics_updatePanel(app)

        end

        % Callback function: ElevationAPIServer, ElevationForceSearch, 
        % ...and 2 other components
        function Elevation_ParameterValueChanged(app, event)

            switch event.Source
                case app.ElevationNPoints
                    app.CallingApp.General.Elevation.Points      = str2double(app.ElevationNPoints.Value);

                case app.ElevationForceSearch
                    app.CallingApp.General.Elevation.ForceSearch = app.ElevationForceSearch.Value;

                case app.ElevationAPIServer
                    app.CallingApp.General.Elevation.Server      = app.ElevationAPIServer.Value;

                case app.general_ElevationRefresh
                    app.CallingApp.General.Elevation = struct('Points',      str2double(app.DefaultValues.Elevation.Points), ...
                                                              'ForceSearch', app.DefaultValues.Elevation.ForceSearch,        ...
                                                              'Server',      app.DefaultValues.Elevation.Server);
            end

            app.CallingApp.General_I.Elevation = app.CallingApp.General.Elevation;
            saveGeneralSettings(app)
            Elevation_updatePanel(app)
            
        end

        % Selection changed function: config_FiscalizaVersion
        function config_FiscalizaMode(app, event)
            
            selectedButton = app.config_FiscalizaVersion.SelectedObject;
            switch selectedButton
                case app.config_FiscalizaPD
                    app.CallingApp.General.fiscaliza.systemVersion = 'PROD';
                case app.config_FiscalizaHM
                    app.CallingApp.General.fiscaliza.systemVersion = 'HOM';
            end

            app.CallingApp.General_I.fiscaliza = app.CallingApp.General.fiscaliza;
            saveGeneralSettings(app)
            appBackDoor(app.CallingApp, app, 'FiscalizaModeChanged')
            
        end

        % Image clicked function: config_Folder_DataHubGETButton, 
        % ...and 3 other components
        function config_getFolder(app, event)
            
            try
                relatedFolder = eval(sprintf('app.config_Folder_%s.Value', event.Source.Tag));                    
            catch
                relatedFolder = app.CallingApp.General.fileFolder.(event.Source.Tag);
            end
            
            if isfolder(relatedFolder)
                initialFolder = relatedFolder;
            elseif isfile(relatedFolder)
                initialFolder = fileparts(relatedFolder);
            else
                initialFolder = app.config_Folder_userPath.Value;
            end
            
            selectedFolder = uigetdir(initialFolder);
            figure(app.UIFigure)

            if selectedFolder
                switch event.Source
                    case app.config_Folder_DataHubGETButton
                        appName  = class.Constants.appName;
                        repoName = 'DataHub - GET';

                        if strcmp(app.CallingApp.General.fileFolder.DataHub_GET, selectedFolder) 
                            return
                        elseif all(cellfun(@(x) contains(selectedFolder, x), {repoName, appName}))
                            app.progressDialog.Visible = 'visible';

                            app.config_Folder_DataHubGET.Value = selectedFolder;
                            app.CallingApp.General.fileFolder.DataHub_GET = selectedFolder;
                        else
                            appUtil.modalWindow(app.UIFigure, 'error', sprintf('Não identificado se tratar da pasta "%s" do repositório "%s".', appName, repoName));
                        end

                    case app.config_Folder_DataHubPOSTButton
                        appName  = class.Constants.appName;
                        repoName = 'DataHub - POST';

                        if strcmp(app.CallingApp.General.fileFolder.DataHub_POST, selectedFolder) 
                            return
                        elseif all(cellfun(@(x) contains(selectedFolder, x), {repoName, appName}))
                            app.config_Folder_DataHubPOST.Value = selectedFolder;
                            app.CallingApp.General.fileFolder.DataHub_POST = selectedFolder;
                        else
                            appUtil.modalWindow(app.UIFigure, 'error', sprintf('Não identificado se tratar da pasta "%s" do repositório "%s".', appName, repoName));
                        end

                    case app.config_Folder_pythonPathButton
                        pythonPath = fullfile(selectedFolder, ccTools.fcn.OperationSystem('pythonExecutable'));
                        if isfile(pythonPath)
                            app.progressDialog.Visible = 'visible';

                            try
                                pyenv('Version', pythonPath);
                                app.CallingApp.General.fileFolder.pythonPath = pythonPath;

                            catch ME
                                appUtil.modalWindow(app.UIFigure, 'error', 'O <i>app</i> deverá ser reinicializado para que a alteração tenha efeito.');
                            end

                            app.progressDialog.Visible = 'hidden';

                        else
                            appUtil.modalWindow(app.UIFigure, 'error', 'Não encontrado o arquivo executável do Python.');
                            return
                        end

                    case app.config_Folder_userPathButton
                        app.config_Folder_userPath.Value = selectedFolder;
                        app.CallingApp.General.fileFolder.userPath = selectedFolder;
                end

                app.CallingApp.General_I.fileFolder = app.CallingApp.General.fileFolder;
                saveGeneralSettings(app)
                Folder_updatePanel(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Document = uigridlayout(app.GridLayout);
            app.LeftPanelTitle = uigridlayout(app.Document);
            app.menu_ButtonGrid = uigridlayout(app.LeftPanelTitle);
            app.menu_ButtonLabel = uilabel(app.menu_ButtonGrid);
            app.menu_ButtonLabel.Text = 'CONFIGURAÇÕES';
            app.menu_ButtonIcon = uiimage(app.menu_ButtonGrid);
            app.menu_ButtonIcon.Tag = '1';
            app.menu_ButtonIcon.ImageSource = 'Settings_18.png';
            app.menuUnderline = uiimage(app.LeftPanelTitle);
            app.menuUnderline.ImageSource = 'LineH.png';
            app.LeftPanel = uipanel(app.Document);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.LeftPanelRadioGroup = uibuttongroup(app.LeftPanelGrid);
            app.LeftPanelRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @LeftPanelRadioGroupSelectionChanged, true);
            app.btnGeneral = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnGeneral.Text = 'Aspectos gerais';
            app.btnGeneral.Value = true;
            app.btnFiscaliza = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnFiscaliza.Text = 'API Fiscaliza';
            app.btnFolder = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnFolder.Text = 'Mapeamento de pastas';
            app.General_Grid = uigridlayout(app.Document);
            app.general_AppVersionLabel = uilabel(app.General_Grid);
            app.general_AppVersionLabel.Text = 'ASPECTOS GERAIS';
            app.general_AppVersionRefresh = uiimage(app.General_Grid);
            app.general_AppVersionRefresh.ImageClickedFcn = createCallbackFcn(app, @AppVersion_refreshButtonPushed, true);
            app.general_AppVersionRefresh.Tooltip = {'Verifica atualizações'};
            app.general_AppVersionRefresh.ImageSource = 'Refresh_18.png';
            app.general_AppVersionPanel = uipanel(app.General_Grid);
            app.general_AppVersionGrid = uigridlayout(app.general_AppVersionPanel);
            app.AppVersion = uihtml(app.general_AppVersionGrid);
            app.AppVersion.HTMLSource = ' ';
            app.general_FileLabel = uilabel(app.General_Grid);
            app.general_FileLabel.Text = 'LEITURA DE ARQUIVOS';
            app.general_mergeRefresh = uiimage(app.General_Grid);
            app.general_mergeRefresh.ImageClickedFcn = createCallbackFcn(app, @File_ParameterValueChanged, true);
            app.general_mergeRefresh.Visible = 'off';
            app.general_mergeRefresh.Tooltip = {'Volta à configuração inicial'};
            app.general_mergeRefresh.ImageSource = 'Refresh_18.png';
            app.general_FilePanel = uipanel(app.General_Grid);
            app.general_FileGrid = uigridlayout(app.general_FilePanel);
            app.mergeLabel1 = uilabel(app.general_FileGrid);
            app.mergeLabel1.Text = 'Lista de metadados a ignorar no processo de mesclagem de fluxos espectrais:';
            app.mergeDataType = uicheckbox(app.general_FileGrid);
            app.mergeDataType.ValueChangedFcn = createCallbackFcn(app, @File_ParameterValueChanged, true);
            app.mergeDataType.Text = 'DataType';
            app.mergeDataType.Value = true;
            app.mergeAntenna = uicheckbox(app.general_FileGrid);
            app.mergeAntenna.ValueChangedFcn = createCallbackFcn(app, @File_ParameterValueChanged, true);
            app.mergeAntenna.Text = 'Antenna';
            app.mergeAntenna.Value = true;
            app.mergeLabel2 = uilabel(app.general_FileGrid);
            app.mergeLabel2.Text = 'Distância máxima entre locais de monitoração registrados em diferentes arquivos para que os fluxos espectrais possam ser mesclados (metros):';
            app.mergeDistance = uispinner(app.general_FileGrid);
            app.mergeDistance.Step = 50;
            app.mergeDistance.Limits = [50 Inf];
            app.mergeDistance.RoundFractionalValues = 'on';
            app.mergeDistance.ValueDisplayFormat = '%.0f';
            app.mergeDistance.ValueChangedFcn = createCallbackFcn(app, @File_ParameterValueChanged, true);
            app.mergeDistance.Value = 100;
            app.general_GraphicsLabel = uilabel(app.General_Grid);
            app.general_GraphicsLabel.Text = 'GRÁFICO';
            app.graphics_Refresh = uiimage(app.General_Grid);
            app.graphics_Refresh.ImageClickedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.graphics_Refresh.Visible = 'off';
            app.graphics_Refresh.Tooltip = {'Volta à configuração inicial'};
            app.graphics_Refresh.ImageSource = 'Refresh_18.png';
            app.general_GraphicsPanel = uipanel(app.General_Grid);
            app.general_GraphicsGrid = uigridlayout(app.general_GraphicsPanel);
            app.gpuTypeLabel = uilabel(app.general_GraphicsGrid);
            app.gpuTypeLabel.Text = 'Unidade gráfica:';
            app.gpuType = uidropdown(app.general_GraphicsGrid);
            app.gpuType.Items = {'hardwarebasic', 'hardware', 'software'};
            app.gpuType.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.gpuType.Value = 'hardware';
            app.imgFormatLabel = uilabel(app.general_GraphicsGrid);
            app.imgFormatLabel.Text = 'Formato da imagem a ser criada no relatório:';
            app.imgFormat = uidropdown(app.general_GraphicsGrid);
            app.imgFormat.Items = {'jpeg', 'png'};
            app.imgFormat.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.imgFormat.Value = 'jpeg';
            app.imgResolutionLabel = uilabel(app.general_GraphicsGrid);
            app.imgResolutionLabel.Text = {'Resolução'; 'imagem (dpi):'};
            app.imgResolution = uidropdown(app.general_GraphicsGrid);
            app.imgResolution.Items = {'100', '120', '150', '200'};
            app.imgResolution.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.imgResolution.Value = '120';
            app.openAuxiliarAppAsDocked = uicheckbox(app.general_GraphicsGrid);
            app.openAuxiliarAppAsDocked.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.openAuxiliarAppAsDocked.Text = 'Modo DOCK: módulos auxiliares abertos na própria janela do appAnalise.';
            app.openAuxiliarApp2Debug = uicheckbox(app.general_GraphicsGrid);
            app.openAuxiliarApp2Debug.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.openAuxiliarApp2Debug.Text = 'Modo DEBUG';
            app.general_ElevationLabel = uilabel(app.General_Grid);
            app.general_ElevationLabel.Text = 'ELEVAÇÃO';
            app.general_ElevationRefresh = uiimage(app.General_Grid);
            app.general_ElevationRefresh.ImageClickedFcn = createCallbackFcn(app, @Elevation_ParameterValueChanged, true);
            app.general_ElevationRefresh.Visible = 'off';
            app.general_ElevationRefresh.ImageSource = 'Refresh_18.png';
            app.general_ElevationPanel = uipanel(app.General_Grid);
            app.general_ElevationGrid = uigridlayout(app.general_ElevationPanel);
            app.ElevationAPIServerLabel = uilabel(app.general_ElevationGrid);
            app.ElevationAPIServerLabel.Text = 'Fonte:';
            app.ElevationAPIServer = uidropdown(app.general_ElevationGrid);
            app.ElevationAPIServer.Items = {'Open-Elevation', 'MathWorks WMS Server'};
            app.ElevationAPIServer.ValueChangedFcn = createCallbackFcn(app, @Elevation_ParameterValueChanged, true);
            app.ElevationAPIServer.Value = 'MathWorks WMS Server';
            app.ElevationNPointsLabel = uilabel(app.general_ElevationGrid);
            app.ElevationNPointsLabel.Text = 'Pontos enlace:';
            app.ElevationNPoints = uidropdown(app.general_ElevationGrid);
            app.ElevationNPoints.Items = {'64', '128', '256', '512', '1024'};
            app.ElevationNPoints.ValueChangedFcn = createCallbackFcn(app, @Elevation_ParameterValueChanged, true);
            app.ElevationNPoints.Value = '256';
            app.ElevationForceSearch = uicheckbox(app.general_ElevationGrid);
            app.ElevationForceSearch.ValueChangedFcn = createCallbackFcn(app, @Elevation_ParameterValueChanged, true);
            app.ElevationForceSearch.Text = 'Força consulta ao servidor (ignorando eventual informação em cache).';
            app.APIFiscaliza_Grid = uigridlayout(app.Document);
            app.config_FiscalizaVersionLabel = uilabel(app.APIFiscaliza_Grid);
            app.config_FiscalizaVersionLabel.Text = 'API FISCALIZA';
            app.config_FiscalizaVersion = uibuttongroup(app.APIFiscaliza_Grid);
            app.config_FiscalizaVersion.SelectionChangedFcn = createCallbackFcn(app, @config_FiscalizaMode, true);
            app.config_FiscalizaPD = uiradiobutton(app.config_FiscalizaVersion);
            app.config_FiscalizaPD.Text = 'FISCALIZA PRODUÇÃO';
            app.config_FiscalizaHM = uiradiobutton(app.config_FiscalizaVersion);
            app.config_FiscalizaHM.Text = 'FISCALIZA <font style="color: red;">HOMOLOGAÇÃO</font> (versão destinada a testes)';
            app.config_FiscalizaHM.Interpreter = 'html';
            app.config_FiscalizaHM.Value = true;
            app.Folders_Grid = uigridlayout(app.Document);
            app.config_FolderMapLabel = uilabel(app.Folders_Grid);
            app.config_FolderMapLabel.Text = 'MAPEAMENTO DE PASTAS';
            app.config_FolderMapPanel = uipanel(app.Folders_Grid);
            app.config_FolderMapGrid = uigridlayout(app.config_FolderMapPanel);
            app.config_Folder_DataHubGETLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_DataHubGETLabel.Text = 'DataHub - GET:';
            app.config_Folder_DataHubGET = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_DataHubGET.Editable = 'off';
            app.config_Folder_DataHubGETButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_DataHubGETButton.ImageClickedFcn = createCallbackFcn(app, @config_getFolder, true);
            app.config_Folder_DataHubGETButton.Tag = 'DataHub_GET';
            app.config_Folder_DataHubGETButton.ImageSource = 'OpenFile_36x36.png';
            app.config_Folder_DataHubPOSTLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_DataHubPOSTLabel.Text = 'DataHub - POST:';
            app.config_Folder_DataHubPOST = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_DataHubPOST.Editable = 'off';
            app.config_Folder_DataHubPOSTButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_DataHubPOSTButton.ImageClickedFcn = createCallbackFcn(app, @config_getFolder, true);
            app.config_Folder_DataHubPOSTButton.Tag = 'DataHub_POST';
            app.config_Folder_DataHubPOSTButton.ImageSource = 'OpenFile_36x36.png';
            app.config_Folder_pythonPathLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_pythonPathLabel.Text = 'Pasta do ambiente virtual Python (lib fiscaliza):';
            app.config_Folder_pythonPath = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_pythonPath.Editable = 'off';
            app.config_Folder_pythonPathButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_pythonPathButton.ImageClickedFcn = createCallbackFcn(app, @config_getFolder, true);
            app.config_Folder_pythonPathButton.Tag = 'pythonPath';
            app.config_Folder_pythonPathButton.ImageSource = 'OpenFile_36x36.png';
            app.config_Folder_userPathLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_userPathLabel.Text = 'Pasta do usuário:';
            app.config_Folder_userPath = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_userPath.Editable = 'off';
            app.config_Folder_userPathButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_userPathButton.ImageClickedFcn = createCallbackFcn(app, @config_getFolder, true);
            app.config_Folder_userPathButton.Tag = 'userPath';
            app.config_Folder_userPathButton.ImageSource = 'OpenFile_36x36.png';
            app.config_Folder_tempPathLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_tempPathLabel.Text = 'Pasta temporária:';
            app.config_Folder_tempPath = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_tempPath.Editable = 'off';
            app.tool_LeftPanelVisibility = uiimage(app.GridLayout);
            app.tool_LeftPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_LeftPanelVisibilityClicked, true);
            app.tool_LeftPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            app.tool_FiscalizaButton = uiimage(app.GridLayout);
            app.tool_FiscalizaButton.ImageClickedFcn = createCallbackFcn(app, @tool_FiscalizaButtonPushed, true);
            app.tool_FiscalizaButton.Enable = 'off';
            app.tool_FiscalizaButton.Tooltip = {'Atualiza biblioteca fiscaliza'};
            app.tool_FiscalizaButton.ImageSource = 'Redmine_32.png';
            app.tool_RFDataHubButton = uiimage(app.GridLayout);
            app.tool_RFDataHubButton.ImageClickedFcn = createCallbackFcn(app, @tool_RFDataHubButtonPushed, true);
            app.tool_RFDataHubButton.Enable = 'off';
            app.tool_RFDataHubButton.Tooltip = {'Atualiza RFDataHub'};
            app.tool_RFDataHubButton.ImageSource = 'mosaic_32.png';
            app.jsBackDoor = uihtml(app.GridLayout);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Tab3_Grid = uigridlayout(app.Panel);
            app.config_geoAxesLabel = uilabel(app.Tab3_Grid);
            app.config_geoAxesLabel.Text = 'MAPA';
            app.config_Refresh = uiimage(app.Tab3_Grid);
            app.config_Refresh.Visible = 'off';
            app.config_Refresh.Tooltip = {'Volta à configuração inicial'};
            app.config_Refresh.ImageSource = 'Refresh_18.png';
            app.ANLISELabel = uilabel(app.Tab3_Grid);
            app.ANLISELabel.Text = 'ANÁLISE';
            app.misc_ElevationSourcePanel = uipanel(app.Tab3_Grid);
            app.GridLayout8 = uigridlayout(app.misc_ElevationSourcePanel);
            app.DisMedicoesLabel = uilabel(app.GridLayout8);
            app.DisMedicoesLabel.Text = 'Distância limite entre ponto de medição e estação sob análise (m):';
            app.DistPARNILabel = uilabel(app.GridLayout8);
            app.DistPARNILabel.Text = 'Distância da estação (PA_RNI) (m):';
            app.EditFieldDistMedicoes = uieditfield(app.GridLayout8, 'numeric');
            app.EditFieldDistMedicoes.Value = 200;
            app.EditFieldDist_PARNI = uieditfield(app.GridLayout8, 'numeric');
            app.EditFieldDist_PARNI.Value = 200;
            app.config_FolderMapPanel_2 = uipanel(app.Tab3_Grid);
            app.config_FolderMapGrid_2 = uigridlayout(app.config_FolderMapPanel_2);
            app.config_Folder_DataHubGETLabel_2 = uilabel(app.config_FolderMapGrid_2);
            app.config_Folder_DataHubGETLabel_2.Text = 'DataHub - GET:';
            app.config_Folder_DataHubGET_2 = uieditfield(app.config_FolderMapGrid_2, 'text');
            app.config_Folder_DataHubGET_2.Editable = 'off';
            app.config_Folder_DataHubGETButton_2 = uiimage(app.config_FolderMapGrid_2);
            app.config_Folder_DataHubGETButton_2.Tag = 'DataHub_GET';
            app.config_Folder_DataHubGETButton_2.ImageSource = 'OpenFile_36x36.png';
            app.config_Folder_DataHubPOSTLabel_2 = uilabel(app.config_FolderMapGrid_2);
            app.config_Folder_DataHubPOSTLabel_2.Text = 'DataHub - POST:';
            app.config_Folder_DataHubPOST_2 = uieditfield(app.config_FolderMapGrid_2, 'text');
            app.config_Folder_DataHubPOST_2.Editable = 'off';
            app.config_Folder_DataHubPOSTButton_2 = uiimage(app.config_FolderMapGrid_2);
            app.config_Folder_DataHubPOSTButton_2.Tag = 'DataHub_POST';
            app.config_Folder_DataHubPOSTButton_2.ImageSource = 'OpenFile_36x36.png';
            app.config_Folder_pythonPathLabel_2 = uilabel(app.config_FolderMapGrid_2);
            app.config_Folder_pythonPathLabel_2.Text = 'Pasta do ambiente virtual Python (lib fiscaliza):';
            app.config_Folder_pythonPath_2 = uieditfield(app.config_FolderMapGrid_2, 'text');
            app.config_Folder_pythonPath_2.Editable = 'off';
            app.config_Folder_pythonPathButton_2 = uiimage(app.config_FolderMapGrid_2);
            app.config_Folder_pythonPathButton_2.Tag = 'pythonPath';
            app.config_Folder_pythonPathButton_2.ImageSource = 'OpenFile_36x36.png';
            app.config_Folder_userPathLabel_2 = uilabel(app.config_FolderMapGrid_2);
            app.config_Folder_userPathLabel_2.Text = 'Pasta do usuário:';
            app.config_Folder_userPath_2 = uidropdown(app.config_FolderMapGrid_2);
            app.config_Folder_userPath_2.Items = {''};
            app.config_Folder_userPath_2.Value = '';
            app.config_Folder_userPathButton_2 = uiimage(app.config_FolderMapGrid_2);
            app.config_Folder_userPathButton_2.Tag = 'userPath';
            app.config_Folder_userPathButton_2.ImageSource = 'OpenFile_36x36.png';
            app.config_FolderMapLabel_2 = uilabel(app.Tab3_Grid);
            app.config_FolderMapLabel_2.Text = 'MAPEAMENTO DE PASTAS';
            app.config_geoAxesSubPanel = uipanel(app.Tab3_Grid);
            app.config_geoAxesSubGrid = uigridlayout(app.config_geoAxesSubPanel);
            app.config_BasemapLabel = uilabel(app.config_geoAxesSubGrid);
            app.config_BasemapLabel.Text = 'Basemap:';
            app.config_Basemap = uidropdown(app.config_geoAxesSubGrid);
            app.config_Basemap.Items = {'none', 'darkwater', 'streets-light', 'streets-dark', 'satellite', 'topographic', 'grayterrain'};
            app.config_Basemap.Value = 'none';
            app.config_ColormapLabel = uilabel(app.config_geoAxesSubGrid);
            app.config_ColormapLabel.Text = 'Mapa de cor:';
            app.config_Colormap = uidropdown(app.config_geoAxesSubGrid);
            app.config_Colormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.config_Colormap.Value = 'winter';
            app.DropDownLocalBarra = uidropdown(app.config_geoAxesSubGrid);
            app.DropDownLocalBarra.Items = {'off', 'east', 'eastoutside', 'south', 'southoutside'};
            app.DropDownLocalBarra.Value = 'off';
            app.LegendadecorLabel = uilabel(app.config_geoAxesSubGrid);
            app.LegendadecorLabel.Text = 'Legenda de cor:';
            app.LimitesLabel = uilabel(app.config_geoAxesSubGrid);
            app.LimitesLabel.Text = 'Limites:';
            app.play_Persistance_cLim_Grid2 = uigridlayout(app.config_geoAxesSubGrid);
            app.play_Persistance_cLim1 = uispinner(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim1.Step = 0.1;
            app.play_Persistance_cLim1.Limits = [0 Inf];
            app.play_Persistance_cLim1.ValueDisplayFormat = '%.3f';
            app.play_Persistance_cLim1.Enable = 'off';
            app.play_Persistance_cLim1.Tooltip = {''};
            app.play_Persistance_cLim2 = uispinner(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim2.Limits = [0 Inf];
            app.play_Persistance_cLim2.ValueDisplayFormat = '%.3f';
            app.play_Persistance_cLim2.Enable = 'off';
            app.play_Persistance_cLim2.Tooltip = {''};
            app.play_Persistance_cLim2.Value = 1;
            app.Label = uilabel(app.play_Persistance_cLim_Grid2);
            app.Label.Enable = 'off';
            app.Label.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end