classdef winExternalRequest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        TreePoints                   matlab.ui.container.Tree
        AddNewPointPanel             matlab.ui.container.Panel
        AddNewPointGrid              matlab.ui.container.GridLayout
        NewPointDescription          matlab.ui.control.EditField
        NewPointDescriptionLabel     matlab.ui.control.Label
        NewPointLongitude            matlab.ui.control.NumericEditField
        NewPointLongitudeLabel       matlab.ui.control.Label
        NewPointLatitude             matlab.ui.control.NumericEditField
        NewPointLatitudeLabel        matlab.ui.control.Label
        NewPointStation              matlab.ui.control.NumericEditField
        NewPointStationLabel         matlab.ui.control.Label
        NewPointType                 matlab.ui.control.DropDown
        NewPointTypeLabel            matlab.ui.control.Label
        AddNewPointCancel            matlab.ui.control.Image
        AddNewPointConfirm           matlab.ui.control.Image
        AddNewPointMode              matlab.ui.control.Image
        AddPointsFromFile            matlab.ui.control.Image
        TreePointsLabel              matlab.ui.control.Label
        TreeFileLocations            matlab.ui.container.CheckBoxTree
        config_geoAxesLabel          matlab.ui.control.Label
        play_ControlsTab1Grid_2      matlab.ui.container.GridLayout
        menu_Button1Label            matlab.ui.control.Label
        play_ControlsTab1Image_2     matlab.ui.control.Image
        toolGrid                     matlab.ui.container.GridLayout
        tool_ExportFiles             matlab.ui.control.Image
        jsBackDoor                   matlab.ui.control.HTML
        tool_TableVisibility         matlab.ui.control.Image
        tool_ControlPanelVisibility  matlab.ui.control.Image
        UITable                      matlab.ui.control.Table
        axesToolbarGrid              matlab.ui.container.GridLayout
        axesTool_RegionZoom          matlab.ui.control.Image
        axesTool_RestoreView         matlab.ui.control.Image
        plotPanel                    matlab.ui.container.Panel
        filter_ContextMenu           matlab.ui.container.ContextMenu
        filter_delButton             matlab.ui.container.Menu
        filter_delAllButton          matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false

        mainApp
        rootFolder
        
        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog
        
        %-----------------------------------------------------------------%
        % ESPECIFICIDADES
        %-----------------------------------------------------------------%
        projectData

        % Instância da classe class.metaData contendo a organização da
        % informação lida dos arquivos de medida. 
        measData  = class.measData.empty

        % measTable é a concatenação de todas as timetables de measData,
        % uma para cada arquivo.
        measTable

        % Handle do eixo e propriedade que armazena os limites automáticos
        UIAxes
        restoreView = struct('ID', {}, 'xLim', {}, 'yLim', {}, 'cLim', {})        
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        % JSBACKDOOR
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource();
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            if app.isDocked
                app.progressDialog = app.mainApp.progressDialog;
            else
                app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
            end

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        'body',                           ...
                                                                                   'classAttributes', ['--tabButton-border-color: #fff;' ...
                                                                                                       '--tabContainer-border-color: #fff;']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                   'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                       '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                       '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);'        ...
                                                                                                       '--mw-backgroundColor-tab: #fff;']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                   'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));

            ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid, 'borderBottomLeftRadius', '5px', 'borderBottomRightRadius', '5px')
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)

                startup_Controller(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)
            drawnow

            % Customiza aspectos estéticos de alguns dos componentes da GUI 
            % (diretamente em JS).
            jsBackDoor_Customizations(app)

            % Define tamanho mínimo do app (não aplicável à versão webapp).
            if ~strcmp(app.mainApp.executionMode, 'webApp') && ~app.isDocked
                appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
            end

            app.progressDialog.Visible = 'visible';

            startup_GUIComponents(app)
            Analysis(app)

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            if app.mainApp.General.MonitoringPlan.FieldValue ~= 14
                app.UITable.ColumnName{5} = sprintf('Qtd.|> %.0f V/m', app.mainApp.General.MonitoringPlan.FieldValue);
            end

            startup_AxesCreation(app)
            layout_TreeFileLocationBuilding(app)
            
            app.tool_TableVisibility.UserData = 1;

            % Especificidades do auxApp.winExternalRequest em relação ao
            % auxApp.winMonitoringPlan:
            layout_TreePointsBuilding(app)
            app.NewPointType.Items = [{''}; app.mainApp.General.ExternalRequest.TypeOfLocation];
            layout_newPointPanel(app, 'off')
        end

        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            % Eixo geográfico: MAPA
            app.plotPanel.AutoResizeChildren = 'off';
            app.UIAxes = plot.axes.Creation(app.plotPanel, 'Geographic', {'Units',    'normalized',                                    ...
                                                                          'Position', [0 0 1 1 ],                                      ...
                                                                          'Basemap',  app.mainApp.General.Plot.GeographicAxes.Basemap, ...
                                                                          'UserData', struct('CLimMode', 'auto', 'Colormap', '')});

            set(app.UIAxes.LatitudeAxis,  'TickLabels', {}, 'Color', 'none')
            set(app.UIAxes.LongitudeAxis, 'TickLabels', {}, 'Color', 'none')
            
            geolimits(app.UIAxes, 'auto')
            app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');

            plot.axes.Colormap(app.UIAxes, app.mainApp.General.Plot.GeographicAxes.Colormap)
            plot.axes.Colorbar(app.UIAxes, app.mainApp.General.Plot.GeographicAxes.Colorbar)

            % Legenda
            legend(app.UIAxes, 'Location', 'southwest', 'Color', [.94,.94,.94], 'EdgeColor', [.9,.9,.9], 'NumColumns', 4, 'LineWidth', .5, 'FontSize', 7.5)

            % Axes interactions:
            plot.axes.Interactivity.DefaultCreation(app.UIAxes, [dataTipInteraction, zoomInteraction, panInteraction])
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function Analysis(app)
            app.progressDialog.Visible = 'visible';

            [idxFile, selectedFileLocations] = FileIndex(app);

            if ~isempty(idxFile)
                % Concatena as tabelas de LATITUDE, LONGITUDE E NÍVEL de cada um
                % dos arquivos cuja localidade coincide com o que foi selecionado
                % em tela. 
                listOfTables = {app.measData(idxFile).Data};            
                app.measTable = sortrows(vertcat(listOfTables{:}), 'Timestamp');

            else
                app.measTable = [];
            end

            app.projectData.selectedFileLocations     = selectedFileLocations;

            identifyMeasuresForEachPoint(app)

            initialSelection = updateTable(app);
            layout_TableStyle(app)

            % PLOT
            prePlot(app)
            plot_Measures(app)
            plot_RiskMeasures(app)
            plot_Points(app)

            if ~isempty(initialSelection)
                [~, idxRow] = ismember(initialSelection, app.UITable.UserData);
                if idxRow
                    app.UITable.Selection = idxRow;
                    UITableSelectionChanged(app, struct('PreviousSelection', 0, 'Selection', idxRow))
                end
            end

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function [idxFile, selectedFileLocations] = FileIndex(app)
            if ~isempty(app.TreeFileLocations.CheckedNodes)
                selectedFileLocations = {app.TreeFileLocations.CheckedNodes.Text};
                idxFile = find(ismember({app.measData.Location}, selectedFileLocations));
            else
                selectedFileLocations = {};
                idxFile = [];
            end
        end

        %-----------------------------------------------------------------%
        function initialSelection = updateTable(app)
            initialSelection = [];
            if ~isempty(app.UITable.Selection)
                initialSelection = app.UITable.Selection;
            end

            table2Render = app.mainApp.pointsTable(:, {'ID',                    ...
                                                       'Description',           ...                                                                                    
                                                       'numberOfMeasures',      ...
                                                       'numberOfRiskMeasures',  ...
                                                       'minDistanceForMeasure', ...
                                                       'minFieldValue',         ...
                                                       'meanFieldValue',        ...
                                                       'maxFieldValue',         ...
                                                       'Justificativa'});
            set(app.UITable, 'Data', table2Render, 'Selection', [])
        end

        %-----------------------------------------------------------------%
        function prePlot(app)
            cla(app.UIAxes)
            geolimits(app.UIAxes, 'auto')
            app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');
        end

        %-----------------------------------------------------------------%
        function plot_Measures(app)
            if ~isempty(app.measTable)
                hPlot = geoscatter(app.UIAxes, app.measTable.Latitude, app.measTable.Longitude, [], app.measTable.FieldValue, 'filled', 'DisplayName', 'Medidas', 'Tag', 'Measures');
                hPlot.DataTipTemplate.DataTipRows(3).Label  = 'Nivel';
                hPlot.DataTipTemplate.DataTipRows(3).Format = '%0.2f V/m';

                % Abaixo estabelece como limites do eixo os limites atuais,
                % configurados automaticamente pelo MATLAB. Ao fazer isso,
                % contudo, esses limites serão orientados às medidas e não às
                % estações.
                plot_AxesDefaultLimits(app, 'measures')
            end
        end

        %-----------------------------------------------------------------%
        function plot_RiskMeasures(app)
            if ~isempty(app.measTable)
                idxRisk = find(app.measTable.FieldValue > app.mainApp.General.MonitoringPlan.FieldValue);

                if ~isempty(idxRisk)
                    latitudeArray  = app.measTable.Latitude(idxRisk);
                    longitudeArray = app.measTable.Longitude(idxRisk);
    
                    geoscatter(app.UIAxes, latitudeArray, longitudeArray,                      ...
                        'Marker', '^', 'MarkerFaceColor', app.mainApp.General.Plot.RiskMeasures.Color, ...
                        'MarkerEdgeColor', app.mainApp.General.Plot.RiskMeasures.Color,                ...
                        'SizeData',        app.mainApp.General.Plot.RiskMeasures.Size,                 ...
                        'DisplayName',     sprintf('> %.0f V/m', app.mainApp.General.MonitoringPlan.FieldValue), ...
                        'Tag',             'RiskMeasures');
                end
            end
        end

        %-----------------------------------------------------------------%
        function plot_Points(app)            
            if ~isempty(app.UITable.Data)
                latitudeArray  = app.mainApp.pointsTable.Latitude;
                longitudeArray = app.mainApp.pointsTable.Longitude;

                geoscatter(app.UIAxes, latitudeArray, longitudeArray,                          ...
                    'Marker', '^', 'MarkerFaceColor', app.mainApp.General.Plot.Stations.Color, ...
                    'MarkerEdgeColor', app.mainApp.General.Plot.Stations.Color,                ...
                    'SizeData',        app.mainApp.General.Plot.Stations.Size,                 ...
                    'DisplayName',     'Pontos críticos',                                      ...
                    'Tag',             'Points');
            end

            plot_AxesDefaultLimits(app, 'stations/points')
        end
        %-----------------------------------------------------------------%
        function plot_SelectedPoint(app)
            delete(findobj(app.UIAxes.Children, 'Tag', 'SelectedPoint', '-or', 'Tag', 'FieldPeak'))

            if ~isempty(app.UITable.Selection)
                % (a) Ponto selecionado
                idxSelectedPoint   = app.UITable.Selection;
                stationLatitude    = app.mainApp.pointsTable.Latitude(idxSelectedPoint);
                stationLongitude   = app.mainApp.pointsTable.Longitude(idxSelectedPoint);
                stationNumber      = sprintf('Ponto ID %s', app.mainApp.pointsTable.ID{idxSelectedPoint});

                geoscatter(app.UIAxes, stationLatitude, stationLongitude,              ...
                    'Marker',          '^',                                            ...
                    'MarkerFaceColor', app.mainApp.General.Plot.SelectedStation.Color, ...
                    'MarkerEdgeColor', app.mainApp.General.Plot.SelectedStation.Color, ...
                    'SizeData',        app.mainApp.General.Plot.SelectedStation.Size,  ...
                    'DisplayName',     stationNumber,                                  ...
                    'Tag',             'SelectedPoint');
    
                % (b) Círculo entorno da estação
                drawcircle(app.UIAxes,                                                          ...
                    'Position',        [stationLatitude, stationLongitude],                     ...
                    'Radius',          km2deg(app.mainApp.General.ExternalRequest.Distance_km), ...
                    'Color',           app.mainApp.General.Plot.CircleRegion.Color,             ...
                    'FaceAlpha',       app.mainApp.General.Plot.CircleRegion.FaceAlpha,         ...
                    'EdgeAlpha',       app.mainApp.General.Plot.CircleRegion.EdgeAlpha,         ...
                    'FaceSelectable',  0, 'InteractionsAllowed', 'none',                        ...
                    'Tag',            'SelectedPoint');
    
                % (c) Maior nível em torno da estação
                maxFieldValue      = app.mainApp.pointsTable.maxFieldValue(idxSelectedPoint);
                if maxFieldValue > 0
                    maxFieldLatitude   = app.mainApp.pointsTable.maxFieldLatitude(idxSelectedPoint);
                    maxFieldLongitude  = app.mainApp.pointsTable.maxFieldLongitude(idxSelectedPoint);
    
                    geoscatter(app.UIAxes, maxFieldLatitude, maxFieldLongitude, maxFieldValue, ...
                        'Marker',          'square',                                           ...
                        'MarkerFaceColor', app.mainApp.General.Plot.FieldPeak.Color,           ...
                        'SizeData',        app.mainApp.General.Plot.FieldPeak.Size,            ...
                        'DisplayName',     'Maior nível em torno da estação',                  ...
                        'Tag',             'FieldPeak');
                end

                % Zoom automático em torno da estação
                if app.mainApp.General.Plot.SelectedStation.AutomaticZoom
                    arclen         = km2deg(app.mainApp.General.Plot.SelectedStation.AutomaticZoomFactor * app.mainApp.General.MonitoringPlan.Distance_km);
                    [~, lim_long1] = reckon(stationLatitude, stationLongitude, arclen, -90);
                    [~, lim_long2] = reckon(stationLatitude, stationLongitude, arclen,  90);    
                    [lim_lat1, ~]  = reckon(stationLatitude, stationLongitude, arclen, 180);
                    [lim_lat2, ~]  = reckon(stationLatitude, stationLongitude, arclen,   0);
        
                    geolimits(app.UIAxes, [lim_lat1, lim_lat2], [lim_long1, lim_long2]);
                end
            end
        end

        %-----------------------------------------------------------------%
        function plot_AxesDefaultLimits(app, zoomOrientation)
            arguments
                app
                zoomOrientation {mustBeMember(zoomOrientation, {'stations/points', 'measures'})}
            end

            if strcmp(app.mainApp.General.Plot.GeographicAxes.ZoomOrientation, zoomOrientation) || (isempty(app.measTable) && strcmp(app.mainApp.General.Plot.GeographicAxes.ZoomOrientation, 'measures'))
                geolimits(app.UIAxes, app.UIAxes.LatitudeLimits, app.UIAxes.LongitudeLimits)
                app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');
            end
        end
        
        %-----------------------------------------------------------------%
        function identifyMeasuresForEachPoint(app)
            DIST_km = app.mainApp.General.ExternalRequest.Distance_km;

            for ii = 1:height(app.mainApp.pointsTable)
                if app.mainApp.pointsTable.AnalysisFlag(ii)
                    continue
                end

                app.mainApp.pointsTable.AnalysisFlag(ii) = true;

                % Inicialmente, afere a distância do ponto a cada uma das
                % medidas, identificando aquelas no entorno.
                pointDistance      = deg2km(distance(app.mainApp.pointsTable.Latitude(ii), app.mainApp.pointsTable.Longitude(ii), app.measTable.Latitude, app.measTable.Longitude));                
                idxLogicalMeasures = pointDistance <= DIST_km;

                if any(idxLogicalMeasures)
                    pointMeasures = app.measTable(idxLogicalMeasures, :);
                    [maxFieldValue, idxMaxFieldValue] = max(pointMeasures.FieldValue);

                    app.mainApp.pointsTable.numberOfMeasures(ii)     = height(pointMeasures);
                    app.mainApp.pointsTable.numberOfRiskMeasures(ii) = sum(pointMeasures.FieldValue > app.mainApp.General.ExternalRequest.FieldValue);
                    app.mainApp.pointsTable.minFieldValue(ii)        = min(pointMeasures.FieldValue);
                    app.mainApp.pointsTable.meanFieldValue(ii)       = mean(pointMeasures.FieldValue);
                    app.mainApp.pointsTable.maxFieldValue(ii)        = maxFieldValue;
                    app.mainApp.pointsTable.maxFieldLatitude(ii)     = pointMeasures.Latitude(idxMaxFieldValue);
                    app.mainApp.pointsTable.maxFieldLongitude(ii)    = pointMeasures.Longitude(idxMaxFieldValue);

                else
                    app.mainApp.pointsTable.numberOfMeasures(ii)     = 0;
                    app.mainApp.pointsTable.numberOfRiskMeasures(ii) = 0;
                    app.mainApp.pointsTable.minFieldValue(ii)        = 0;
                    app.mainApp.pointsTable.meanFieldValue(ii)       = 0;
                    app.mainApp.pointsTable.maxFieldValue(ii)        = 0;
                    app.mainApp.pointsTable.maxFieldLatitude(ii)     = 0;
                    app.mainApp.pointsTable.maxFieldLongitude(ii)    = 0;
                end

                app.mainApp.pointsTable.minDistanceForMeasure(ii)    = min(pointDistance); % km
            end
        end

        %-----------------------------------------------------------------%
        function [idxMissingInfo, idxRiskMeasures] = layout_searchUnexpectedTableValues(app)
            idxMissingInfo   = [];
            idxRiskMeasures  = [];

            if ~isempty(app.UITable.Data)
                idxMissingInfo  = find(((app.mainApp.pointsTable.numberOfMeasures == 0) & (app.mainApp.pointsTable.("Justificativa") == "-1")));
                idxRiskMeasures = find(app.mainApp.pointsTable.numberOfRiskMeasures > 0);
            end
        end

        %-----------------------------------------------------------------%
        function layout_TableStyle(app)
            removeStyle(app.UITable)

            if ~isempty(app.UITable.Data)
                % Identifica pontos que NÃO tiveram medições no seu entorno. 
                % Ou pontos que apresentaram medições com níveis acima de 14 V/m.
                [idxMissingInfo, idxRiskMeasures] = layout_searchUnexpectedTableValues(app);

                if ~isempty(idxMissingInfo)
                    columnIndex1 = find(ismember(app.UITable.Data.Properties.VariableNames, 'Justificativa'));
                    s1 = uistyle('BackgroundColor', '#c80b0f', 'FontColor', 'white');                
                    addStyle(app.UITable, s1, "cell", [idxMissingInfo, repmat(columnIndex1, numel(idxMissingInfo), 1)])
                end

                if ~isempty(idxRiskMeasures)
                    columnIndex2 = find(ismember(app.UITable.Data.Properties.VariableNames, 'numberOfRiskMeasures'));

                    s2 = uistyle('BackgroundColor', '#c80b0f', 'FontColor', 'white');
                    addStyle(app.UITable, s2, "cell", [idxRiskMeasures, repmat(columnIndex2, numel(idxRiskMeasures), 1)])
                end
                
                app.tool_ExportFiles.Enable = 1;

            else
                app.tool_ExportFiles.Enable = 0;
            end
        end

        %-----------------------------------------------------------------%
        function layout_TreeFileLocationBuilding(app)
            if ~isempty(app.TreeFileLocations.Children)
                delete(app.TreeFileLocations.Children)
            end

            listOfFileLocations = unique({app.measData.Location});
            for ii = 1:numel(listOfFileLocations)
                treeNode = uitreenode(app.TreeFileLocations, 'Text', listOfFileLocations{ii});

                if ismember(listOfFileLocations{ii}, app.projectData.selectedFileLocations)
                    app.TreeFileLocations.CheckedNodes = [app.TreeFileLocations.CheckedNodes; treeNode];
                end
            end

            if isempty(app.TreeFileLocations.CheckedNodes)
                app.TreeFileLocations.CheckedNodes = app.TreeFileLocations.Children(1);
            end
        end

        %-----------------------------------------------------------------%
        function layout_TreePointsBuilding(app)
            if ~isempty(app.TreePoints.Children)
                delete(app.TreePoints.Children)
            end

            for ii = 1:height(app.mainApp.pointsTable)
                uitreenode(app.TreePoints, 'Text', app.mainApp.pointsTable.ID{ii});
            end
        end

        %-----------------------------------------------------------------%
        function layout_newPointPanel(app, editionStatus)
            arguments
                app 
                editionStatus char {mustBeMember(editionStatus, {'on', 'off'})}
            end            

            switch editionStatus
                case 'on'
                    set(app.AddNewPointMode, 'ImageSource', 'addFiles_32Filled.png', 'Tooltip', 'Desabilita painel de inclusão de ponto', 'UserData', true)
                    
                    app.GridLayout2.RowHeight{5} = 170;
                    app.GridLayout2.ColumnWidth(end-1:end) = {16,16};
                    app.AddNewPointConfirm.Enable = 1;
                    app.AddNewPointCancel.Enable  = 1;

                case 'off'
                    set(app.AddNewPointMode, 'ImageSource', 'addFiles_32.png',       'Tooltip', 'Habilita painel de inclusão de ponto',   'UserData', false)

                    app.GridLayout2.RowHeight{5} = 0;
                    app.GridLayout2.ColumnWidth(end-1:end) = {0,0};
                    app.AddNewPointConfirm.Enable = 0;
                    app.AddNewPointCancel.Enable  = 0;
            end
        end
    end


    methods
        %-----------------------------------------------------------------%
        function appBackDoor(app, callingApp, operationType, varargin)
            try
                switch class(callingApp)
                    case {'winMonitorRNI', 'winMonitorRNI_exported'}
                        switch operationType
                            case 'ExternalRequest: updatePlot'
                                Analysis(app)

                            case 'ExternalRequest: updateAnalysis'
                                app.UITable.ColumnName{4} = sprintf('Qtd.|> %.0f V/m', app.mainApp.General.ExternalRequest.FieldValue);

                                if ~isempty(app.mainApp.pointTable)
                                    app.mainApp.pointTable.AnalysisFlag(:) = false;
                                    Analysis(app)
                                end

                            case 'ExternalRequest: updateAxes'
                                if ~isequal(app.UIAxes.Basemap, app.mainApp.General.Plot.GeographicAxes.Basemap)
                                    app.UIAxes.Basemap = app.mainApp.General.Plot.GeographicAxes.Basemap;
                                end

                                plot.axes.Colormap(app.UIAxes, app.mainApp.General.Plot.GeographicAxes.Colormap)
                                plot.axes.Colorbar(app.UIAxes, app.mainApp.General.Plot.GeographicAxes.Colorbar)

                            otherwise
                                error('UnexpectedCall')
                        end
    
                    otherwise
                        error('UnexpectedCall')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            
            app.mainApp     = callingApp;
            app.rootFolder  = callingApp.rootFolder;
            app.projectData = callingApp.projectData;
            app.measData    = callingApp.measData;

            jsBackDoor_Initialization(app)

            if app.isDocked
                app.GridLayout.Padding(4) = 21;
                startup_Controller(app)
            else
                appUtil.winPosition(app.UIFigure)
                startup_timerCreation(app)
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            appBackDoor(app.mainApp, app, 'closeFcn', 'EXTERNALREQUEST')
            delete(app)
            
        end

        % Image clicked function: tool_ControlPanelVisibility, 
        % ...and 1 other component
        function tool_InteractionImageClicked(app, event)
            
            focus(app.jsBackDoor)
            
            switch event.Source
                case app.tool_ControlPanelVisibility
                    if app.GridLayout.ColumnWidth{2}
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowRight_32.png';
                        app.GridLayout.ColumnWidth(2:3) = {0,0};
                    else
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
                        app.GridLayout.ColumnWidth(2:3) = {325,10};
                    end

                case app.tool_TableVisibility
                    app.tool_TableVisibility.UserData = mod(app.tool_TableVisibility.UserData+1, 3);

                    switch app.tool_TableVisibility.UserData
                        case 0
                            app.UITable.Visible = 0;
                            app.GridLayout.RowHeight(2:5) = {22,'1x', 0, 0};
                        case 1
                            app.UITable.Visible = 1;
                            app.GridLayout.RowHeight(2:5) = {22, '1x', 10, 175};
                        case 2
                            app.UITable.Visible = 1;
                            app.GridLayout.RowHeight(2:5) = {0, 0, 0, '1x'};
                    end
            end

        end

        % Image clicked function: tool_ExportFiles
        function tool_ExportTableAsExcelSheet(app, event)
            
            % VALIDAÇÕES
            % (a) Inicialmente, verifica se o campo "Justificativa" foi devidamente 
            %     preenchido...
            if ~isempty(layout_searchUnexpectedTableValues(app, 'TableExport'))
                msgQuestion   = ['Há registro de pontos críticos sob análise para os quais '                             ...
                                 'não foram identificadas medidas no entorno. Nesse caso específico, deve-se preencher ' ...
                                 'o campo "Justificativa".'                                                              ...
                                 '<br><br>Deseja ignorar esse alerta, exportando o resultado?'];
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                if userSelection == "Não"
                    return
                end
            end

            % (b) Solicita ao usuário nome do arquivo de saída...
            appName       = class.Constants.appName;
            nameFormatMap = {'*.zip', [appName, ' (*.zip)']};
            defaultName   = appUtil.DefaultFileName(app.mainApp.General.fileFolder.userPath, appName, '-1');
            fileZIP       = appUtil.modalWindow(app.UIFigure, 'uiputfile', '', nameFormatMap, defaultName);
            if isempty(fileZIP)
                return
            end

            % ARQUIVOS DE SAÍDA
            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento a criação do arquivo de medidas no formato "xlsx".');

            savedFiles   = {};
            errorFiles   = {};

            baseName     = appUtil.DefaultFileName(app.mainApp.General.fileFolder.userPath, appName, '-1');


            % (a) Arquivo no formato .XLSX
            %     (um único arquivo de saída)
            fileName_XLSX = [baseName '.xlsx'];
            [status, msgError] = fileWriter.ExternalRequest(fileName_XLSX, app.mainApp.pointsTable, timetable2table(app.measTable), app.mainApp.General.ExternalRequest.FieldValue, app.mainApp.General.ExternalRequest.Export.XLSX);
            if status
                savedFiles{end+1} = fileName_XLSX;
            else
                errorFiles{end+1} = msgError;
            end

            % (b) Arquivos no formato .KML: "Measures" e "Route"
            %     (um único arquivo de medições, além de um arquivo de rota 
            %      por arquivo de medição)
            if app.mainApp.General.ExternalRequest.Export.KML
                hMeasPlot = findobj(app.UIAxes.Children, 'Tag', 'Measures');

                % (b.1) KML:Measures
                d.Message = 'Em andamento a criação do arquivo de medidas no formato "kml".';

                fileName_KML1 = sprintf('%s_Measures.kml', baseName);
                [status, msgError] = fileWriter.KML(fileName_KML1, 'Measures', timetable2table(app.measTable), hMeasPlot);
                if status
                    savedFiles{end+1} = fileName_KML1;
                else
                    errorFiles{end+1} = msgError;
                end

                % (b.2) KML:Route
                d.Message = 'Em andamento a criação do arquivo de rotas no formato "kml".';

                idxFile = FileIndex(app);
                for ii = 1:numel(idxFile)
                    fileName_KML2 = sprintf('%s_Route (%d).kml', baseName, ii);
                    [status, msgError] = fileWriter.KML(fileName_KML2, 'Route', timetable2table(app.measData(idxFile(ii)).Data));
                    if status
                        savedFiles{end+1} = fileName_KML2;
                    else
                        errorFiles{end+1} = msgError;
                    end
                end
            end

            % (c) Arquivo no formato .ZIP
            if ~isempty(savedFiles)
                zip(fileZIP, savedFiles)

                fileFolder = fileparts(baseName);
                savedFiles = replace(savedFiles, fileFolder, '.');
                appUtil.modalWindow(app.UIFigure, 'info', sprintf('Lista de arquivos criados:\n%s', strjoin(savedFiles, '\n')));
            end

            if ~isempty(errorFiles)
                appUtil.modalWindow(app.UIFigure, 'error', strjoin(errorFiles, '\n'));
            end

            delete(d)

        end

        % Image clicked function: axesTool_RegionZoom, axesTool_RestoreView
        function axesTool_InteractionImageClicked(app, event)
            
            switch event.Source
                case app.axesTool_RestoreView
                    geolimits(app.UIAxes, app.restoreView(1).xLim, app.restoreView(1).yLim)

                case app.axesTool_RegionZoom
                    plot.axes.Interactivity.GeographicRegionZoomInteraction(app.UIAxes, app.axesTool_RegionZoom)
            end

        end

        % Callback function: TreeFileLocations
        function TreeFileLocationsCheckedNodesChanged(app, event)
            
            if isempty(app.TreeFileLocations.CheckedNodes)
                app.TreeFileLocations.CheckedNodes = event.PreviousCheckedNodes;
                return
            end

            Analysis(app)

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

            idxPoint = event.Indices(1);
            app.mainApp.pointsTable.("Justificativa")(idxPoint) = event.NewData;
            
            layout_TableStyle(app)

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            
            plot_SelectedPoint(app)

        end

        % Image clicked function: AddNewPointCancel, AddNewPointConfirm, 
        % ...and 2 other components
        function AddNewPointEditionModeCallbacks(app, event)
            
            switch event.Source
                case app.AddPointsFromFile
                    % idxStation = selectedStation(app);
                    % app.Latitude.Value  = app.mainApp.stationTable.("Lat")(idxStation);
                    % app.Longitude.Value = app.mainApp.stationTable.("Long")(idxStation);
                    % 
                    % FormValueChanged(app, struct('Source', event.Source))

                case app.AddNewPointMode
                    app.AddNewPointMode.UserData = ~app.AddNewPointMode.UserData;
                    
                    if app.AddNewPointMode.UserData
                        layout_newPointPanel(app, 'on')
                        focus(app.NewPointType)
                    else
                        layout_newPointPanel(app, 'off')
                    end

                case app.AddNewPointConfirm
                    % VALIDAÇÃO
                    if isempty(app.NewPointType.Value) || ((app.NewPointLatitude.Value == -1) && (app.NewPointLongitude.Value == -1))
                        msgWarning = 'Um novo ponto crítico somente poderá ser incluído se definido o seu tipo e coordenadas geográficas diferentes de (-1, -1).';
                        appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                        return
                    end
        
                    % CRIA ID DO NOVO PONTO
                    switch app.NewPointType.Value
                        case 'Estação'
                            ID = sprintf('Estação nº %d', app.NewPointStation.Value);
                        otherwise
                            ID = app.NewPointType.Value;
                    end
                    ID = sprintf('%s @ (%.6f, %.6f)', ID, app.NewPointLatitude.Value, app.NewPointLongitude.Value);
        
                    % VERIFICA SE ESSE ID JÁ TINHA SIDO INCLUÍDO
                    if any(strcmp(app.mainApp.pointsTable.ID, ID))
                        appUtil.modalWindow(app.UIFigure, 'warning', 'Registro já consta na lista de pontos sob análise.');
                        return
                    end

                    columsn2Fill = {'ID', 'Type', 'Station', 'Latitude', 'Longitude', 'Description', 'Justificativa', 'AnalysisFlag'};        
                    app.mainApp.pointsTable(end+1, columsn2Fill) = {ID,                            ...
                                                                    app.NewPointType.Value,        ...
                                                                    app.NewPointStation.Value,     ...
                                                                    app.NewPointLatitude.Value,    ...
                                                                    app.NewPointLongitude.Value,   ...
                                                                    app.NewPointDescription.Value, ...
                                                                    categorical("-1"),             ...
                                                                    false};
        
        
                    % ATUALIZA ÁRVORE DE PONTOS CRÍTICOS
                    layout_TreePointsBuilding(app)
        
                    % ANÁLISA DOS PONTOS CRÍTICOS, ATUALIZANDO TABELA E PLOT
                    Analysis(app)

                    % DESABILITA MODO DE INCLUSÃO DE PONTO
                    layout_newPointPanel(app, 'off')

                case app.AddNewPointCancel
                    layout_newPointPanel(app, 'off')
            end

        end

        % Value changed function: NewPointType
        function NewPointTypeValueChanged(app, event)
            
            switch app.NewPointType.Value
                case 'Estação'
                    app.NewPointStation.Enable = 1;
                    set(app.NewPointLatitude,  'Value', -1, 'Enable', 0)
                    set(app.NewPointLongitude, 'Value', -1, 'Enable', 0)

                otherwise
                    set(app.NewPointStation, 'Value', -1, 'Enable', 0)
                    app.NewPointLatitude.Enable  = 1;
                    app.NewPointLongitude.Enable = 1;
            end

        end

        % Value changed function: NewPointStation
        function NewPointStationValueChanged(app, event)
            
            idxRFDataHub = find(app.mainApp.rfDataHub.Station == app.NewPointStation.Value, 1);

            if ~isempty(idxRFDataHub)
                latStation  = round(app.mainApp.rfDataHub.Latitude(idxRFDataHub), 6);
                longStation = round(app.mainApp.rfDataHub.Longitude(idxRFDataHub), 6);

                set(app.NewPointLatitude,  'Value', latStation,  'Enable', 1)
                set(app.NewPointLongitude, 'Value', longStation, 'Enable', 1)

            else
                set(app.NewPointLatitude,  'Value', -1, 'Enable', 1)
                set(app.NewPointLongitude, 'Value', -1, 'Enable', 1)
            end

            focus(app.NewPointLatitude);

        end

        % Value changed function: NewPointLatitude
        function NewPointLatitudeValueChanged(app, event)
            
            focus(app.NewPointLongitude)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorRNI';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.plotPanel = uipanel(app.GridLayout);
            app.axesToolbarGrid = uigridlayout(app.GridLayout);
            app.axesTool_RestoreView = uiimage(app.axesToolbarGrid);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RestoreView.Tooltip = {'RestoreView'};
            app.axesTool_RestoreView.ImageSource = 'Home_18.png';
            app.axesTool_RegionZoom = uiimage(app.axesToolbarGrid);
            app.axesTool_RegionZoom.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RegionZoom.Tooltip = {'RegionZoom'};
            app.axesTool_RegionZoom.ImageSource = 'ZoomRegion_20.png';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'ID'; 'Descrição'; 'Qtd.|Medidas'; 'Qtd.|> 14 V/m'; 'Dmin|(km)'; 'Emin|(V/m)'; 'Emean|(V/m)'; 'Emax|(V/m)'; 'Justificativa'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false true false false false false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.toolGrid = uigridlayout(app.GridLayout);
            app.tool_ControlPanelVisibility = uiimage(app.toolGrid);
            app.tool_ControlPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            app.tool_TableVisibility = uiimage(app.toolGrid);
            app.tool_TableVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.tool_TableVisibility.Tooltip = {'Visibilidade da tabela'};
            app.tool_TableVisibility.ImageSource = 'View_16.png';
            app.jsBackDoor = uihtml(app.toolGrid);
            app.tool_ExportFiles = uiimage(app.toolGrid);
            app.tool_ExportFiles.ImageClickedFcn = createCallbackFcn(app, @tool_ExportTableAsExcelSheet, true);
            app.tool_ExportFiles.Enable = 'off';
            app.tool_ExportFiles.Tooltip = {'Exporta plot e tabela como arquivos'; '(.KML e .XLSX)'};
            app.tool_ExportFiles.ImageSource = 'Export_16.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.play_ControlsTab1Grid_2 = uigridlayout(app.GridLayout2);
            app.play_ControlsTab1Grid_2.Tag = 'COLORLOCKED';
            app.play_ControlsTab1Image_2 = uiimage(app.play_ControlsTab1Grid_2);
            app.play_ControlsTab1Image_2.ImageSource = 'Classification_128.png';
            app.menu_Button1Label = uilabel(app.play_ControlsTab1Grid_2);
            app.menu_Button1Label.Text = 'DEMANDAS EXTERNAS';
            app.config_geoAxesLabel = uilabel(app.GridLayout2);
            app.config_geoAxesLabel.Interpreter = 'html';
            app.config_geoAxesLabel.Text = {'LOCALIDADES:'; '<font style="color: gray; font-size: 9px;">(relacionadas aos arquivos de medição)</font>'};
            app.TreeFileLocations = uitree(app.GridLayout2, 'checkbox');
            % Assign Checked Nodes
            app.TreeFileLocations.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeFileLocationsCheckedNodesChanged, true);
            app.TreePointsLabel = uilabel(app.GridLayout2);
            app.TreePointsLabel.Interpreter = 'html';
            app.TreePointsLabel.Text = {'PONTOS CRÍTICOS SOB ANÁLISE:'; '<font style="color: gray; font-size: 9px;">(relacionados àquilo que fora pedido pelo demandante)</font>'};
            app.AddPointsFromFile = uiimage(app.GridLayout2);
            app.AddPointsFromFile.ImageClickedFcn = createCallbackFcn(app, @AddNewPointEditionModeCallbacks, true);
            app.AddPointsFromFile.Tooltip = {'Inclui pontos a partir de arquivo'};
            app.AddPointsFromFile.ImageSource = 'OpenFile_36x36.png';
            app.AddNewPointMode = uiimage(app.GridLayout2);
            app.AddNewPointMode.ImageClickedFcn = createCallbackFcn(app, @AddNewPointEditionModeCallbacks, true);
            app.AddNewPointMode.Tooltip = {'Habilita painel de inclusão de ponto'};
            app.AddNewPointMode.ImageSource = 'addFiles_32.png';
            app.AddNewPointConfirm = uiimage(app.GridLayout2);
            app.AddNewPointConfirm.ImageClickedFcn = createCallbackFcn(app, @AddNewPointEditionModeCallbacks, true);
            app.AddNewPointConfirm.Enable = 'off';
            app.AddNewPointConfirm.Tooltip = {'Confirma edição'};
            app.AddNewPointConfirm.ImageSource = 'Ok_32Green.png';
            app.AddNewPointCancel = uiimage(app.GridLayout2);
            app.AddNewPointCancel.ImageClickedFcn = createCallbackFcn(app, @AddNewPointEditionModeCallbacks, true);
            app.AddNewPointCancel.Enable = 'off';
            app.AddNewPointCancel.Tooltip = {'Cancela edição'};
            app.AddNewPointCancel.ImageSource = 'Delete_32Red.png';
            app.AddNewPointPanel = uipanel(app.GridLayout2);
            app.AddNewPointGrid = uigridlayout(app.AddNewPointPanel);
            app.NewPointTypeLabel = uilabel(app.AddNewPointGrid);
            app.NewPointTypeLabel.Text = 'Tipo:';
            app.NewPointType = uidropdown(app.AddNewPointGrid);
            app.NewPointType.Items = {''};
            app.NewPointType.ValueChangedFcn = createCallbackFcn(app, @NewPointTypeValueChanged, true);
            app.NewPointType.Value = '';
            app.NewPointStationLabel = uilabel(app.AddNewPointGrid);
            app.NewPointStationLabel.Text = 'Estação:';
            app.NewPointStation = uieditfield(app.AddNewPointGrid, 'numeric');
            app.NewPointStation.Limits = [-1 Inf];
            app.NewPointStation.RoundFractionalValues = 'on';
            app.NewPointStation.ValueDisplayFormat = '%d';
            app.NewPointStation.ValueChangedFcn = createCallbackFcn(app, @NewPointStationValueChanged, true);
            app.NewPointStation.Enable = 'off';
            app.NewPointStation.Value = -1;
            app.NewPointLatitudeLabel = uilabel(app.AddNewPointGrid);
            app.NewPointLatitudeLabel.Text = 'Latitude:';
            app.NewPointLatitude = uieditfield(app.AddNewPointGrid, 'numeric');
            app.NewPointLatitude.Limits = [-90 90];
            app.NewPointLatitude.ValueDisplayFormat = '%.6f';
            app.NewPointLatitude.ValueChangedFcn = createCallbackFcn(app, @NewPointLatitudeValueChanged, true);
            app.NewPointLatitude.Value = -1;
            app.NewPointLongitudeLabel = uilabel(app.AddNewPointGrid);
            app.NewPointLongitudeLabel.Text = 'Longitude:';
            app.NewPointLongitude = uieditfield(app.AddNewPointGrid, 'numeric');
            app.NewPointLongitude.Limits = [-180 180];
            app.NewPointLongitude.ValueDisplayFormat = '%.6f';
            app.NewPointLongitude.Value = -1;
            app.NewPointDescriptionLabel = uilabel(app.AddNewPointGrid);
            app.NewPointDescriptionLabel.Text = 'Descrição:';
            app.NewPointDescription = uieditfield(app.AddNewPointGrid, 'text');
            app.TreePoints = uitree(app.GridLayout2);
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.Text = 'Excluir todos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winExternalRequest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end