classdef winMonitoringPlan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        toolGrid                     matlab.ui.container.GridLayout
        tool_UploadFinalFile         matlab.ui.control.Image
        jsBackDoor                   matlab.ui.control.HTML
        tool_ExportFiles             matlab.ui.control.Image
        tool_TableVisibility         matlab.ui.control.Image
        tool_ControlPanelVisibility  matlab.ui.control.Image
        Document                     matlab.ui.container.GridLayout
        LocationListLabel            matlab.ui.control.Label
        LocationListEdit             matlab.ui.control.Image
        LocationList                 matlab.ui.control.TextArea
        Card4_stationsOutRoute       matlab.ui.control.Label
        Card3_stationsOnRoute        matlab.ui.control.Label
        Card2_numberOfRiskStations   matlab.ui.control.Label
        Card1_numberOfStations       matlab.ui.control.Label
        TreeFileLocations            matlab.ui.container.CheckBoxTree
        config_geoAxesLabel_2        matlab.ui.control.Label
        play_ControlsTab1Grid_2      matlab.ui.container.GridLayout
        play_ControlsTab1Label_2     matlab.ui.control.Label
        play_ControlsTab1Image_2     matlab.ui.control.Image
        UITable                      matlab.ui.control.Table
        axesToolbarGrid              matlab.ui.container.GridLayout
        axesTool_RegionZoom          matlab.ui.control.Image
        axesTool_RestoreView         matlab.ui.control.Image
        plotPanel                    matlab.ui.container.Panel
        ContextMenu                  matlab.ui.container.ContextMenu
        EditSelectedUITableRow       matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false

        mainApp
        rootFolder
        
        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog
        popupContainer
        
        %-----------------------------------------------------------------%
        % ESPECIFICIDADES
        %-----------------------------------------------------------------%
        projectData

        % Instância da classe class.metaData contendo a organização da
        % informação lida dos arquivos de medida. 
        measData  = class.measData.empty

        % measTable é a concatenação de todas as timetables de measData,
        % uma para cada arquivo.
        measTable

        % Handle do eixo e propriedade que armazena os limites automáticos
        UIAxes
        restoreView = struct('ID', {}, 'xLim', {}, 'yLim', {}, 'cLim', {})
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        % JSBACKDOOR
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = appUtil.jsBackDoorHTMLSource();
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            if app.isDocked
                app.progressDialog = app.mainApp.progressDialog;
            else
                app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
            end

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        'body',                           ...
                                                                                   'classAttributes', ['--tabButton-border-color: #fff;' ...
                                                                                                       '--tabContainer-border-color: #fff;']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                   'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                       '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                       '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);'        ...
                                                                                                       '--mw-backgroundColor-tab: #fff;']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                   'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));

            ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid, 'borderBottomLeftRadius', '5px', 'borderBottomRightRadius', '5px')
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)

                startup_Controller(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)
            drawnow

            % Customiza aspectos estéticos de alguns dos componentes da GUI 
            % (diretamente em JS).
            jsBackDoor_Customizations(app)

            % Define tamanho mínimo do app (não aplicável à versão webapp).
            if ~strcmp(app.mainApp.executionMode, 'webApp') && ~app.isDocked
                appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
            end

            app.progressDialog.Visible = 'visible';

            startup_GUIComponents(app)
            Analysis(app)

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            if app.mainApp.General.MonitoringPlan.FieldValue ~= 14
                app.UITable.ColumnName{5} = sprintf('Qtd.|> %.0f V/m', app.mainApp.General.MonitoringPlan.FieldValue);
            end

            startup_AxesCreation(app)
            layout_TreeFileLocationBuilding(app)
                        
            app.tool_TableVisibility.UserData = 1;
        end

        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            % Eixo geográfico: MAPA
            app.plotPanel.AutoResizeChildren = 'off';
            app.UIAxes = plot.axes.Creation(app.plotPanel, 'Geographic', {'Units',    'normalized',                                    ...
                                                                          'Position', [0 0 1 1 ],                                      ...
                                                                          'Basemap',  app.mainApp.General.Plot.GeographicAxes.Basemap, ...
                                                                          'UserData', struct('CLimMode', 'auto', 'Colormap', '')});

            set(app.UIAxes.LatitudeAxis,  'TickLabels', {}, 'Color', 'none')
            set(app.UIAxes.LongitudeAxis, 'TickLabels', {}, 'Color', 'none')
            
            geolimits(app.UIAxes, 'auto')
            app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');

            plot.axes.Colormap(app.UIAxes, app.mainApp.General.Plot.GeographicAxes.Colormap)
            plot.axes.Colorbar(app.UIAxes, app.mainApp.General.Plot.GeographicAxes.Colorbar)

            % Legenda
            legend(app.UIAxes, 'Location', 'southwest', 'Color', [.94,.94,.94], 'EdgeColor', [.9,.9,.9], 'NumColumns', 4, 'LineWidth', .5, 'FontSize', 7.5)

            % Axes interactions:
            plot.axes.Interactivity.DefaultCreation(app.UIAxes, [dataTipInteraction, zoomInteraction, panInteraction])
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function Analysis(app)
            app.progressDialog.Visible = 'visible';

            [idxFile, selectedFileLocations] = FileIndex(app);

            if ~isempty(idxFile)
                % Concatena as tabelas de LATITUDE, LONGITUDE E NÍVEL de cada um
                % dos arquivos cuja localidade coincide com o que foi selecionado
                % em tela. 
                listOfTables = {app.measData(idxFile).Data};            
                app.measTable = sortrows(vertcat(listOfTables{:}), 'Timestamp');
                
                % Identifica localidades relacionadas à monitoração sob análise.
                listOfLocations = {};
                DIST_km = app.mainApp.General.MonitoringPlan.Distance_km;
    
                for ii = idxFile
                    % Limites de latitude e longitude relacionados à rota, acrescentando 
                    % a distância máxima à estação p/ fins de cômputo de medidas válidas 
                    % no entorno de uma estação.
                    [maxLatitude, maxLongitude] = reckon(app.measData(ii).LatitudeLimits(2), app.measData(ii).LongitudeLimits(2), km2deg(DIST_km), 45);
                    [minLatitude, minLongitude] = reckon(app.measData(ii).LatitudeLimits(1), app.measData(ii).LongitudeLimits(1), km2deg(DIST_km), 225);
    
                    idxLogicalStation = app.mainApp.stationTable.Latitude  >= minLatitude  & ...
                                        app.mainApp.stationTable.Latitude  <= maxLatitude  & ...
                                        app.mainApp.stationTable.Longitude >= minLongitude & ...
                                        app.mainApp.stationTable.Longitude <= maxLongitude;
    
                    if any(idxLogicalStation)
                        listOfLocations = [listOfLocations; unique(app.mainApp.stationTable.Location(idxLogicalStation))];
                    end
                end
                listOfLocation = unique(listOfLocations);
            else
                app.measTable  = [];
                listOfLocation = {};
            end

            app.projectData.selectedFileLocations     = selectedFileLocations;
            app.projectData.listOfLocations.Automatic = listOfLocation;

            fullListOfLocation = union(app.projectData.listOfLocations.Manual, app.projectData.listOfLocations.Automatic);

            idxStations = find(ismember(app.mainApp.stationTable.Location, fullListOfLocation));
            if ~isempty(app.measTable)
                identifyMeasuresForEachStation(app, idxStations)
            end
            initialSelection = updateTable(app, idxStations);
            layout_TableStyle(app)

            if ~isempty(fullListOfLocation)
                app.LocationList.Value = fullListOfLocation;
            else
                app.LocationList.Value = '';
            end
            
            nStations         = numel(idxStations);
            nRiskStations     = sum(app.UITable.Data.numberOfRiskMeasures > 0);
            nStationsOnRoute  = sum(app.UITable.Data.numberOfMeasures > 0);
            nStationsOutRoute = nStations - nStationsOnRoute;

            % Atualiza painel com quantitativo de estações...
            app.Card1_numberOfStations.Text     = sprintf('<p style="margin: 10 2 0 2px;"><font style="color: black;   font-size: 32px;">%d</font>\nESTAÇÕES LOCALIZADAS NOS MUNICÍPIOS SOB ANÁLISE</p>',                nStations);
            app.Card2_numberOfRiskStations.Text = sprintf('<p style="margin: 10 2 0 2px;"><font style="color: #a2142f; font-size: 32px;">%d</font>\nESTAÇÕES NO ENTORNO DE REGISTROS DE NÍVEIS ACIMA DE 14 V/m</p></p>', nRiskStations);
            app.Card3_stationsOnRoute .Text     = sprintf('<p style="margin: 10 2 0 2px;"><font style="color: black;   font-size: 32px;">%d</font>\nESTAÇÕES INSTALADAS NO ENTORNO DA ROTA</p>',                         nStationsOnRoute);
            app.Card4_stationsOutRoute.Text     = sprintf('<p style="margin: 10 2 0 2px;"><font style="color: #a2142f; font-size: 32px;">%d</font>\nESTAÇÕES INSTALADAS FORA DA ROTA</p>',                               nStationsOutRoute);

            % PLOT
            prePlot(app)
            plot_Measures(app)
            plot_RiskMeasures(app)
            plot_Stations(app)

            if ~isempty(initialSelection)
                [~, idxRow] = ismember(initialSelection, app.UITable.UserData);
                if idxRow
                    app.UITable.Selection = idxRow;
                    UITableSelectionChanged(app, struct('PreviousSelection', 0, 'Selection', idxRow))
                end
            end

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function [idxFile, selectedFileLocations] = FileIndex(app)
            if ~isempty(app.TreeFileLocations.CheckedNodes)
                selectedFileLocations = {app.TreeFileLocations.CheckedNodes.Text};
                idxFile = find(ismember({app.measData.Location}, selectedFileLocations));
            else
                selectedFileLocations = {};
                idxFile = [];
            end
        end

        %-----------------------------------------------------------------%
        function initialSelection = updateTable(app, idxStations)
            initialSelection = [];
            if ~isempty(app.UITable.Selection)
                initialSelection = app.UITable.UserData(app.UITable.Selection);
            end

            table2Render = app.mainApp.stationTable(idxStations, {'N° estacao',            ...
                                                                  'Location',              ...
                                                                  'Serviço',               ...                                                                                      
                                                                  'numberOfMeasures',      ...
                                                                  'numberOfRiskMeasures',  ...
                                                                  'minDistanceForMeasure', ...
                                                                  'minFieldValue',         ...
                                                                  'meanFieldValue',        ...
                                                                  'maxFieldValue',         ...
                                                                  'Justificativa'});
            set(app.UITable, 'Data', table2Render, 'UserData', idxStations, 'Selection', [])
        end

        %-----------------------------------------------------------------%
        function prePlot(app)
            cla(app.UIAxes)
            geolimits(app.UIAxes, 'auto')
            app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');
        end

        %-----------------------------------------------------------------%
        function plot_Measures(app)
            if ~isempty(app.measTable)
                hPlot = geoscatter(app.UIAxes, app.measTable.Latitude, app.measTable.Longitude, [], app.measTable.FieldValue, 'filled', 'DisplayName', 'Medidas', 'Tag', 'Measures');
                hPlot.DataTipTemplate.DataTipRows(3).Label  = 'Nivel';
                hPlot.DataTipTemplate.DataTipRows(3).Format = '%0.2f V/m';

                % Abaixo estabelece como limites do eixo os limites atuais,
                % configurados automaticamente pelo MATLAB. Ao fazer isso,
                % contudo, esses limites serão orientados às medidas e não às
                % estações.
                plot_AxesDefaultLimits(app, 'measures')
            end
        end

        %-----------------------------------------------------------------%
        function plot_RiskMeasures(app)
            if ~isempty(app.measTable)
                idxRisk = find(app.measTable.FieldValue > app.mainApp.General.MonitoringPlan.FieldValue);

                if ~isempty(idxRisk)
                    latitudeArray  = app.measTable.Latitude(idxRisk);
                    longitudeArray = app.measTable.Longitude(idxRisk);
    
                    geoscatter(app.UIAxes, latitudeArray, longitudeArray,                      ...
                        'Marker', '^', 'MarkerFaceColor', app.mainApp.General.Plot.RiskMeasures.Color, ...
                        'MarkerEdgeColor', app.mainApp.General.Plot.RiskMeasures.Color,                ...
                        'SizeData',        app.mainApp.General.Plot.RiskMeasures.Size,                 ...
                        'DisplayName',     sprintf('> %.0f V/m', app.mainApp.General.MonitoringPlan.FieldValue), ...
                        'Tag',             'RiskMeasures');
                end
            end
        end

        %-----------------------------------------------------------------%
        function plot_Stations(app)
            if ~isempty(app.UITable.Data)
                idxStations    = app.UITable.UserData;
                latitudeArray  = app.mainApp.stationTable.Latitude(idxStations);
                longitudeArray = app.mainApp.stationTable.Longitude(idxStations);

                geoscatter(app.UIAxes, latitudeArray, longitudeArray,                          ...
                    'Marker', '^', 'MarkerFaceColor', app.mainApp.General.Plot.Stations.Color, ...
                    'MarkerEdgeColor', app.mainApp.General.Plot.Stations.Color,                ...
                    'SizeData',        app.mainApp.General.Plot.Stations.Size,                 ...
                    'DisplayName',     'Estações de referência PM-RNI',                        ...
                    'Tag',             'Stations');
            end

            plot_AxesDefaultLimits(app, 'stations/points')
        end

        %-----------------------------------------------------------------%
        function plot_SelectedStation(app)
            delete(findobj(app.UIAxes.Children, 'Tag', 'SelectedStation', '-or', 'Tag', 'FieldPeak'))

            if ~isempty(app.UITable.Selection)
                % (a) Estação selecionada
                idxSelectedStation = app.UITable.UserData(app.UITable.Selection);
                stationLatitude    = app.mainApp.stationTable.Latitude(idxSelectedStation);
                stationLongitude   = app.mainApp.stationTable.Longitude(idxSelectedStation);
                stationNumber      = sprintf('Estação nº %d', app.mainApp.stationTable.("N° estacao")(idxSelectedStation));

                geoscatter(app.UIAxes, stationLatitude, stationLongitude,      ...
                    'Marker',          '^',                                    ...
                    'MarkerFaceColor', app.mainApp.General.Plot.SelectedStation.Color, ...
                    'MarkerEdgeColor', app.mainApp.General.Plot.SelectedStation.Color, ...
                    'SizeData',        app.mainApp.General.Plot.SelectedStation.Size,  ...
                    'DisplayName',     stationNumber,                          ...
                    'Tag',             'SelectedStation');
    
                % (b) Círculo entorno do ponto
                drawcircle(app.UIAxes,                                                 ...
                    'Position',        [stationLatitude, stationLongitude],            ...
                    'Radius',          km2deg(app.mainApp.General.MonitoringPlan.Distance_km), ...
                    'Color',           app.mainApp.General.Plot.CircleRegion.Color,            ...
                    'FaceAlpha',       app.mainApp.General.Plot.CircleRegion.FaceAlpha,        ...
                    'EdgeAlpha',       app.mainApp.General.Plot.CircleRegion.EdgeAlpha,        ...
                    'FaceSelectable',  0, 'InteractionsAllowed', 'none',               ...
                    'Tag',            'SelectedStation');
    
                % (c) Maior nível em torno da estação
                maxFieldValue      = app.mainApp.stationTable.maxFieldValue(idxSelectedStation);
                if maxFieldValue > 0
                    maxFieldLatitude   = app.mainApp.stationTable.maxFieldLatitude(idxSelectedStation);
                    maxFieldLongitude  = app.mainApp.stationTable.maxFieldLongitude(idxSelectedStation);
    
                    geoscatter(app.UIAxes, maxFieldLatitude, maxFieldLongitude, maxFieldValue, ...
                        'Marker',          'square',                          ...
                        'MarkerFaceColor', app.mainApp.General.Plot.FieldPeak.Color,  ...
                        'SizeData',        app.mainApp.General.Plot.FieldPeak.Size,   ...
                        'DisplayName',     'Maior nível em torno da estação', ...
                        'Tag',             'FieldPeak');
                end

                % Zoom automático em torno da estação
                if app.mainApp.General.Plot.SelectedStation.AutomaticZoom
                    arclen         = km2deg(app.mainApp.General.Plot.SelectedStation.AutomaticZoomFactor * app.mainApp.General.MonitoringPlan.Distance_km);
                    [~, lim_long1] = reckon(stationLatitude, stationLongitude, arclen, -90);
                    [~, lim_long2] = reckon(stationLatitude, stationLongitude, arclen,  90);    
                    [lim_lat1, ~]  = reckon(stationLatitude, stationLongitude, arclen, 180);
                    [lim_lat2, ~]  = reckon(stationLatitude, stationLongitude, arclen,   0);
        
                    geolimits(app.UIAxes, [lim_lat1, lim_lat2], [lim_long1, lim_long2]);
                end
            end
        end

        %-----------------------------------------------------------------%
        function plot_AxesDefaultLimits(app, zoomOrientation)
            arguments
                app
                zoomOrientation {mustBeMember(zoomOrientation, {'stations/points', 'measures'})}
            end

            if strcmp(app.mainApp.General.Plot.GeographicAxes.ZoomOrientation, zoomOrientation) || (isempty(app.measTable) && strcmp(app.mainApp.General.Plot.GeographicAxes.ZoomOrientation, 'measures'))
                geolimits(app.UIAxes, app.UIAxes.LatitudeLimits, app.UIAxes.LongitudeLimits)
                app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');
            end
        end
        
        %-----------------------------------------------------------------%
        function identifyMeasuresForEachStation(app, idxStations)
            DIST_km = app.mainApp.General.MonitoringPlan.Distance_km;

            for ii = idxStations'
                if app.mainApp.stationTable.AnalysisFlag(ii)
                    continue
                end

                app.mainApp.stationTable.AnalysisFlag(ii) = true;

                % Inicialmente, afere a distância da estação a cada uma das
                % medidas, identificando aquelas no entorno.
                stationDistance    = deg2km(distance(app.mainApp.stationTable.Latitude(ii), app.mainApp.stationTable.Longitude(ii), app.measTable.Latitude, app.measTable.Longitude));                
                idxLogicalMeasures = stationDistance <= DIST_km;

                if any(idxLogicalMeasures)
                    stationMeasures = app.measTable(idxLogicalMeasures, :);
                    [maxFieldValue, idxMaxFieldValue] = max(stationMeasures.FieldValue);

                    app.mainApp.stationTable.numberOfMeasures(ii)     = height(stationMeasures);
                    app.mainApp.stationTable.numberOfRiskMeasures(ii) = sum(stationMeasures.FieldValue > app.mainApp.General.MonitoringPlan.FieldValue);
                    app.mainApp.stationTable.minFieldValue(ii)        = min(stationMeasures.FieldValue);
                    app.mainApp.stationTable.meanFieldValue(ii)       = mean(stationMeasures.FieldValue);
                    app.mainApp.stationTable.maxFieldValue(ii)        = maxFieldValue;
                    app.mainApp.stationTable.maxFieldTimestamp(ii)    = stationMeasures.Timestamp(idxMaxFieldValue);
                    app.mainApp.stationTable.maxFieldLatitude(ii)     = stationMeasures.Latitude(idxMaxFieldValue);
                    app.mainApp.stationTable.maxFieldLongitude(ii)    = stationMeasures.Longitude(idxMaxFieldValue);

                else
                    app.mainApp.stationTable.numberOfMeasures(ii)     = 0;
                    app.mainApp.stationTable.numberOfRiskMeasures(ii) = 0;
                    app.mainApp.stationTable.minFieldValue(ii)        = 0;
                    app.mainApp.stationTable.meanFieldValue(ii)       = 0;
                    app.mainApp.stationTable.maxFieldValue(ii)        = 0;                
                    app.mainApp.stationTable.maxFieldTimestamp(ii)    = NaT;
                    app.mainApp.stationTable.maxFieldLatitude(ii)     = 0;
                    app.mainApp.stationTable.maxFieldLongitude(ii)    = 0;
                end

                app.mainApp.stationTable.minDistanceForMeasure(ii)    = min(stationDistance); % km
            end
        end

        %-----------------------------------------------------------------%
        function [idxMissingInfo, idxManualEdition, idxRiskMeasures, idxUpload2POST] = layout_searchUnexpectedTableValues(app)
            idxMissingInfo   = [];
            idxManualEdition = [];
            idxRiskMeasures  = [];
            idxUpload2POST   = [];

            if ~isempty(app.UITable.Data)
                % Tabela sob análise.                
                idxStations = app.UITable.UserData;
                relatedStationTable = app.mainApp.stationTable(idxStations, :);

                % "Registros anotados" por meio da inclusão de uma observação 
                % ou da edição das coordenadas geográficas da estação.
                addedObservationLogical = cellfun(@(x) ~isempty(x), relatedStationTable.("Observações"));
                editedLocationLogical   = (relatedStationTable.("Lat") ~= relatedStationTable.("Latitude")) | (relatedStationTable.("Long") ~= relatedStationTable.("Longitude"));                

                % Registros ainda pendentes de edição, seja por não ter sido 
                % especificada uma "Justificativa" válida (precisa ser diferente 
                % de "-1" quando não identificada medição no entorno da estação) 
                % ou porque foi especificada uma válida, porém que demanda a 
                % inclusão de uma observação ou a edição das coordenadas da estação.
                reasonsThatRequireObservation = app.mainApp.General.MonitoringPlan.ReasonsThatRequireObservation;
                reasonsThatRequireNewLocation = app.mainApp.General.MonitoringPlan.ReasonsThatRequireEditionOfLocation;

                idxMissingInfo   = find(((relatedStationTable.numberOfMeasures == 0) & (relatedStationTable.("Justificativa") == "-1"))             | ...
                                        (ismember(relatedStationTable.("Justificativa"), reasonsThatRequireObservation) & ~addedObservationLogical) | ...
                                        (ismember(relatedStationTable.("Justificativa"), reasonsThatRequireNewLocation) & ~editedLocationLogical));
                idxManualEdition = find(addedObservationLogical | editedLocationLogical);
                idxRiskMeasures  = find(relatedStationTable.numberOfRiskMeasures > 0);
                idxUpload2POST   = find(relatedStationTable.UploadResultFlag);
            end
        end

        %-----------------------------------------------------------------%
        function layout_TableStyle(app)
            removeStyle(app.UITable)

            if ~isempty(app.UITable.Data)
                % Identifica estações que NÃO tiveram medições no seu entorno, 
                % apesar da rota englobar o município em que está instalada a
                % estação. Ou estações que apresentaram medições com níveis
                % acima de 14 V/m.
                [idxMissingInfo, idxManualEdition, idxRiskMeasures, idxUpload2POST] = layout_searchUnexpectedTableValues(app);

                if ~isempty(idxMissingInfo)
                    columnIndex1 = find(ismember(app.UITable.Data.Properties.VariableNames, 'Justificativa'));
                    s1 = uistyle('BackgroundColor', '#c80b0f', 'FontColor', 'white');                
                    addStyle(app.UITable, s1, "cell", [idxMissingInfo, repmat(columnIndex1, numel(idxMissingInfo), 1)])
                end

                if ~isempty(idxManualEdition)
                    s2 = uistyle('Icon', 'Edit_18Gray.png', 'IconAlignment', 'leftmargin');
                    addStyle(app.UITable, s2, "cell", [idxManualEdition, ones(numel(idxManualEdition), 1)])
                end

                if ~isempty(idxRiskMeasures)
                    columnIndex2 = find(ismember(app.UITable.Data.Properties.VariableNames, 'numberOfRiskMeasures'));

                    s3 = uistyle('BackgroundColor', '#c80b0f', 'FontColor', 'white');
                    addStyle(app.UITable, s3, "cell", [idxRiskMeasures, repmat(columnIndex2, numel(idxRiskMeasures), 1)])
                end

                if ~isempty(idxUpload2POST)
                    s4 = uistyle('FontColor', [.5,.5,.5]);
                    addStyle(app.UITable, s4, "row", idxUpload2POST)
                end

                app.tool_ExportFiles.Enable = 1;
                if isempty(idxMissingInfo)
                    app.tool_UploadFinalFile.Enable = 1;
                else
                    app.tool_UploadFinalFile.Enable = 0;
                end

            else
                app.tool_ExportFiles.Enable     = 0;
                app.tool_UploadFinalFile.Enable = 0;
            end
        end

        %-----------------------------------------------------------------%
        function layout_TreeFileLocationBuilding(app)
            if ~isempty(app.TreeFileLocations.Children)
                delete(app.TreeFileLocations.Children)
            end

            listOfFileLocations = unique({app.measData.Location});
            for ii = 1:numel(listOfFileLocations)
                treeNode = uitreenode(app.TreeFileLocations, 'Text', listOfFileLocations{ii});

                if ismember(listOfFileLocations{ii}, app.projectData.selectedFileLocations)
                    app.TreeFileLocations.CheckedNodes = [app.TreeFileLocations.CheckedNodes; treeNode];
                end
            end

            if isempty(app.TreeFileLocations.CheckedNodes)
                app.TreeFileLocations.CheckedNodes = app.TreeFileLocations.Children(1);
            end
        end

        %-----------------------------------------------------------------%
        function [idxStation, idxRow] = selectedStation(app)
            idxRow     = app.UITable.Selection;
            idxStation = app.UITable.UserData(idxRow);
        end
    end


    methods
        %-----------------------------------------------------------------%
        function appBackDoor(app, callingApp, operationType, varargin)
            try
                switch class(callingApp)
                    case {'winMonitorRNI', 'winMonitorRNI_exported'}
                        switch operationType
                            case {'PM-RNI: updateReferenceTable', 'PM-RNI: updatePlot'}
                                Analysis(app)

                            case 'PM-RNI: updateAnalysis'
                                app.UITable.ColumnName{5} = sprintf('Qtd.|> %.0f V/m', app.mainApp.General.MonitoringPlan.FieldValue);

                                idxStations = app.UITable.UserData;
                                if ~isempty(idxStations)
                                    app.mainApp.stationTable.AnalysisFlag(idxStations) = false;
                                    Analysis(app)
                                end

                            case 'PM-RNI: updateAxes'
                                if ~isequal(app.UIAxes.Basemap, app.mainApp.General.Plot.GeographicAxes.Basemap)
                                    app.UIAxes.Basemap = app.mainApp.General.Plot.GeographicAxes.Basemap;
                                end

                                plot.axes.Colormap(app.UIAxes, app.mainApp.General.Plot.GeographicAxes.Colormap)
                                plot.axes.Colorbar(app.UIAxes, app.mainApp.General.Plot.GeographicAxes.Colorbar)

                            otherwise
                                error('UnexpectedCall')
                        end

                    case {'auxApp.dockStationInfo',    'auxApp.dockStationInfo_exported', ...
                          'auxApp.dockListOfLocation', 'auxApp.dockListOfLocation_exported'}

                        % Chamadas implementadas:
                        % (a) 'StationTableValueChanged: ReasonOrNote'
                        %     Atualizam-se as colunas "Justificativa" e "Observações" da "tabela mãe"
                        %     (app.mainApp.stationTable), além da coluna "Justificativa" da uitable 
                        %     (já que a coluna "Observações" não é renderizada na uitable).
                        %
                        % (b) 'StationTableValueChanged: Location'
                        %     Atualizam-se as colunas "Latitude", "Longitude" e "AnalysisFlag" da "tabela mãe"
                        %     (app.mainApp.stationTable), executando-se novamente a análise.
                        %
                        % (c) 'UITableSelectionChanged'
                        %     Troca-se a seleção da uitable.
                        %
                        % (d) 'ListOfLocationChanged'

                        updateFlag = varargin{1};
                        returnFlag = varargin{2};

                        if updateFlag
                            switch operationType
                                case 'StationTableValueChanged: ReasonOrNote'
                                    newReason      = varargin{3};
                                    newObservation = varargin{4};

                                    [idxStation, idxRow] = selectedStation(app);
                                    app.mainApp.stationTable.("Justificativa")(idxStation) = newReason;
                                    app.mainApp.stationTable.("Observações"){idxStation}   = newObservation;
                                    app.UITable.Data.("Justificativa")(idxRow)             = newReason;
                                    layout_TableStyle(app)

                                case 'StationTableValueChanged: Location'
                                    newLatitude  = varargin{3};
                                    newLongitude = varargin{4};

                                    idxStation   = selectedStation(app);
                                    app.mainApp.stationTable.("Latitude")(idxStation)  = newLatitude;
                                    app.mainApp.stationTable.("Longitude")(idxStation) = newLongitude;
                                    app.mainApp.stationTable.AnalysisFlag(idxStation)  = false;

                                    Analysis(app)

                                case 'UITableSelectionChanged'
                                    newRowSelection = varargin{3};
                                    app.UITable.Selection = newRowSelection;
                                    scroll(app.UITable, 'row', newRowSelection)

                                    UITableSelectionChanged(app)

                                case 'ListOfLocationChanged'
                                    Analysis(app)

                                otherwise
                                    error('UnexpectedCall')
                            end
                        end

                        if returnFlag
                            return
                        end
                        
                        app.popupContainer.Parent.Visible = 0;
    
                    otherwise
                        error('UnexpectedCall')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)

            app.mainApp     = callingApp;
            app.rootFolder  = callingApp.rootFolder;
            app.projectData = callingApp.projectData;
            app.measData    = callingApp.measData;

            jsBackDoor_Initialization(app)

            if app.isDocked
                app.GridLayout.Padding(4) = 21;
                startup_Controller(app)
            else
                appUtil.winPosition(app.UIFigure)
                startup_timerCreation(app)
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            appBackDoor(app.mainApp, app, 'closeFcn', 'MONITORINGPLAN')
            delete(app)
            
        end

        % Image clicked function: tool_ControlPanelVisibility, 
        % ...and 1 other component
        function tool_InteractionImageClicked(app, event)
            
            focus(app.jsBackDoor)
            
            switch event.Source
                case app.tool_ControlPanelVisibility
                    if app.GridLayout.ColumnWidth{2}
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowRight_32.png';
                        app.GridLayout.ColumnWidth(2:3) = {0,0};
                    else
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
                        app.GridLayout.ColumnWidth(2:3) = {325,10};
                    end

                case app.tool_TableVisibility
                    app.tool_TableVisibility.UserData = mod(app.tool_TableVisibility.UserData+1, 3);

                    switch app.tool_TableVisibility.UserData
                        case 0
                            app.UITable.Visible = 0;
                            app.GridLayout.RowHeight(2:5) = {22,'1x', 0, 0};
                        case 1
                            app.UITable.Visible = 1;
                            app.GridLayout.RowHeight(2:5) = {22, '1x', 10, 175};
                        case 2
                            app.UITable.Visible = 1;
                            app.GridLayout.RowHeight(2:5) = {0, 0, 0, '1x'};
                    end
            end

        end

        % Image clicked function: tool_ExportFiles
        function tool_ExportTableAsExcelSheet(app, event)
            
            % VALIDAÇÕES
            % (a) Inicialmente, verifica se o campo "Justificativa" foi devidamente 
            %     preenchido...
            if ~isempty(layout_searchUnexpectedTableValues(app))
                msgQuestion   = ['Há registro de estações instaladas na(s) localidade(s) sob análise para as quais '     ...
                                 'não foram identificadas medidas no entorno. Nesse caso específico, deve-se preencher ' ...
                                 'o campo "Justificativa" e anotar os registros, caso aplicável.'                        ...
                                 '<br><br>Deseja ignorar esse alerta, exportando o resultado?'];
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                if userSelection == "Não"
                    return
                end
            end

            % (b) Solicita ao usuário nome do arquivo de saída...
            appName       = class.Constants.appName;
            nameFormatMap = {'*.zip', [appName, ' (*.zip)']};
            defaultName   = appUtil.DefaultFileName(app.mainApp.General.fileFolder.userPath, appName, '-1');
            fileZIP       = appUtil.modalWindow(app.UIFigure, 'uiputfile', '', nameFormatMap, defaultName);
            if isempty(fileZIP)
                return
            end

            % ARQUIVOS DE SAÍDA
            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento a criação do arquivo de medidas no formato "xlsx".');

            savedFiles   = {};
            errorFiles   = {};

            baseName     = appUtil.DefaultFileName(app.mainApp.General.fileFolder.userPath, appName, '-1');
            idxStations  = app.UITable.UserData;

            % (a) Arquivo no formato .XLSX
            %     (um único arquivo de saída)
            fileName_XLSX = [baseName '.xlsx'];
            [status, msgError] = fileWriter.MonitoringPlan(fileName_XLSX, app.mainApp.stationTable(idxStations, :), timetable2table(app.measTable), app.mainApp.General.MonitoringPlan.FieldValue, app.mainApp.General.MonitoringPlan.Export.XLSX);
            if status
                savedFiles{end+1} = fileName_XLSX;
            else
                errorFiles{end+1} = msgError;
            end
    
            % (b) Arquivos no formato .KML: "Measures" e "Route"
            %     (um único arquivo de medições, além de um arquivo de rota 
            %      por arquivo de medição)
            if app.mainApp.General.MonitoringPlan.Export.KML
                hMeasPlot = findobj(app.UIAxes.Children, 'Tag', 'Measures');

                % (b.1) KML:Measures
                d.Message = 'Em andamento a criação do arquivo de medidas no formato "kml".';

                fileName_KML1 = sprintf('%s_Measures.kml', baseName);
                [status, msgError] = fileWriter.KML(fileName_KML1, 'Measures', timetable2table(app.measTable), hMeasPlot);
                if status
                    savedFiles{end+1} = fileName_KML1;
                else
                    errorFiles{end+1} = msgError;
                end

                % (b.2) KML:Route
                d.Message = 'Em andamento a criação do arquivo de rotas no formato "kml".';

                idxFile = FileIndex(app);
                for ii = 1:numel(idxFile)
                    fileName_KML2 = sprintf('%s_Route (%d).kml', baseName, ii);
                    [status, msgError] = fileWriter.KML(fileName_KML2, 'Route', timetable2table(app.measData(idxFile(ii)).Data));
                    if status
                        savedFiles{end+1} = fileName_KML2;
                    else
                        errorFiles{end+1} = msgError;
                    end
                end
            end

            % (c) Arquivo no formato .ZIP
            if ~isempty(savedFiles)
                zip(fileZIP, savedFiles)

                fileFolder = fileparts(baseName);
                savedFiles = replace(savedFiles, fileFolder, '.');
                appUtil.modalWindow(app.UIFigure, 'info', sprintf('Lista de arquivos criados:\n%s', strjoin(savedFiles, '\n')));
            end

            if ~isempty(errorFiles)
                appUtil.modalWindow(app.UIFigure, 'error', strjoin(errorFiles, '\n'));
            end

            delete(d)

        end

        % Image clicked function: axesTool_RegionZoom, axesTool_RestoreView
        function axesTool_InteractionImageClicked(app, event)
            
            switch event.Source
                case app.axesTool_RestoreView
                    geolimits(app.UIAxes, app.restoreView(1).xLim, app.restoreView(1).yLim)

                case app.axesTool_RegionZoom
                    plot.axes.Interactivity.GeographicRegionZoomInteraction(app.UIAxes, app.axesTool_RegionZoom)
            end

        end

        % Callback function: TreeFileLocations
        function TreeFileLocationsCheckedNodesChanged(app, event)
            
            if isempty(app.TreeFileLocations.CheckedNodes)
                app.TreeFileLocations.CheckedNodes = event.PreviousCheckedNodes;
                return
            end

            Analysis(app)

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

            idxStation = app.UITable.UserData(event.Indices(1));
            app.mainApp.stationTable.("Justificativa")(idxStation) = event.NewData;
            
            layout_TableStyle(app)

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            
            if exist('event', 'var') && ~isempty(event.PreviousSelection) && (event.Selection == event.PreviousSelection)
                return
            end

            if isempty(app.UITable.ContextMenu)
                app.UITable.ContextMenu = app.ContextMenu;
            end

            plot_SelectedStation(app)
            
        end

        % Menu selected function: EditSelectedUITableRow
        function tool_EditRowImageClicked(app, event)
            
            if ~isempty(app.UITable.Selection)
                app.progressDialog.Visible = 'visible';

                if isempty(app.popupContainer)
                    popupContainerGrid = uigridlayout(app.UIFigure, [1, 1], "BackgroundColor", "white", "ColumnWidth", {'1x', 540, '1x'}, "RowHeight", {'1x', 440, '1x'}, "Visible", "off");
                    app.popupContainer = uipanel(popupContainerGrid, "Title", "");
                    app.popupContainer.Layout.Row = 2;
                    app.popupContainer.Layout.Column = 2;
                    drawnow

                    ccTools.compCustomizationV2(app.jsBackDoor, popupContainerGrid, 'backgroundColor', 'rgba(255,255,255,0.65)')
                    sendEventToHTMLSource(app.jsBackDoor, "panelDialog", struct('componentDataTag', struct(app.popupContainer).Controller.ViewModel.Id))
                end

                auxApp.dockStationInfo_exported(app.popupContainer, app)
                app.popupContainer.Parent.Visible = "on";

                app.progressDialog.Visible = 'hidden';
            end

        end

        % Image clicked function: LocationListEdit
        function LocationListEditClicked(app, event)
            
            app.progressDialog.Visible = 'visible';

            if isempty(app.popupContainer)
                popupContainerGrid = uigridlayout(app.UIFigure, [1, 1], "BackgroundColor", "white", "ColumnWidth", {'1x', 540, '1x'}, "RowHeight", {'1x', 440, '1x'}, "Visible", "off");
                app.popupContainer = uipanel(popupContainerGrid, "Title", "");
                app.popupContainer.Layout.Row = 2;
                app.popupContainer.Layout.Column = 2;
                drawnow

                ccTools.compCustomizationV2(app.jsBackDoor, popupContainerGrid, 'backgroundColor', 'rgba(255,255,255,0.65)')
                sendEventToHTMLSource(app.jsBackDoor, "panelDialog", struct('componentDataTag', struct(app.popupContainer).Controller.ViewModel.Id))
            end

            auxApp.dockListOfLocation_exported(app.popupContainer, app)
            app.popupContainer.Parent.Visible = "on";

            app.progressDialog.Visible = 'hidden';

        end

        % Image clicked function: tool_UploadFinalFile
        function tool_UploadFinalFileImageClicked(app, event)
            
            % VALIDAÇÕES
            % (a) O botão app.tool_UploadFinalFile só está ATIVO quando a tabela 
            %     sob análise é não vazia, e o campo "Justificativa" foi corretamente 
            %     preenchido. Implementado em layout_TableStyle(app)

            % (b) A pasta "POST" do Sharepoint" deve ter sido mapeada.
            if ~isfolder(app.mainApp.General.fileFolder.DataHub_POST)
                appUtil.modalWindow(app.UIFigure, 'warning', 'Pendente mapear pasta do Sharepoint.');
                return
            end

            % (c) Verifica se já foi feito o upload de registros da tabela
            %     na presente sessão do app.
            idxStations = app.UITable.UserData;
            relatedStationTable = app.mainApp.stationTable(idxStations, :);

            if     all(relatedStationTable.UploadResultFlag)
                msgQuestion   = ['Todos os registros de estações já tiveram os seus resultados exportados para a pasta "POST" ' ...
                                 'do Sharepoint na presente sessão. Esses registros estão destacados com a fonte cinza.' ...
                                 '<br><br>Deseja realizar uma nova exportação?'];
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                if userSelection == "Não"
                    return
                end

            elseif any(relatedStationTable.UploadResultFlag)
                msgQuestion   = ['Há registros de estações que já tiveram os seus resultados exportados para a pasta "POST" ' ...
                                 'do Sharepoint na presente sessão. Esses registros estão destacados com a fonte cinza.' ...
                                 '<br><br>O que deseja fazer?'];
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Exportar tabela completa', 'Exportar novos registros', 'Cancelar'}, 3, 3);
                switch userSelection
                    case 'Exportar novos registros'
                        relatedStationTable(relatedStationTable.UploadResultFlag, :) = [];
                    case 'Cancelar'
                        return
                end
            end

            % SALVA ARQUIVO NA PASTA "POST" DO SHAREPOINT
            fileName = [appUtil.DefaultFileName(app.mainApp.General.fileFolder.DataHub_POST, class.Constants.appName, '-1') '.xlsx'];

            [status, msgError] = fileWriter.MonitoringPlan(fileName, relatedStationTable, [], app.mainApp.General.MonitoringPlan.FieldValue);
            if status
                app.mainApp.stationTable.UploadResultFlag(idxStations) = true;
                layout_TableStyle(app)

                appUtil.modalWindow(app.UIFigure, 'info', 'Operação concluída com sucesso.');
            else
                appUtil.modalWindow(app.UIFigure, 'error', msgError);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorRNI';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.plotPanel = uipanel(app.GridLayout);
            app.axesToolbarGrid = uigridlayout(app.GridLayout);
            app.axesTool_RestoreView = uiimage(app.axesToolbarGrid);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RestoreView.Tooltip = {'RestoreView'};
            app.axesTool_RestoreView.ImageSource = 'Home_18.png';
            app.axesTool_RegionZoom = uiimage(app.axesToolbarGrid);
            app.axesTool_RegionZoom.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RegionZoom.Tooltip = {'RegionZoom'};
            app.axesTool_RegionZoom.ImageSource = 'ZoomRegion_20.png';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Estação'; 'Localidade'; 'Serviço'; 'Qtd.|Medidas'; 'Qtd.|> 14 V/m'; 'Dmin|(km)'; 'Emin|(V/m)'; 'Emean|(V/m)'; 'Emax|(V/m)'; 'Justificativa'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true true true true true false];
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false false false false false false false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.Document = uigridlayout(app.GridLayout);
            app.play_ControlsTab1Grid_2 = uigridlayout(app.Document);
            app.play_ControlsTab1Grid_2.Tag = 'COLORLOCKED';
            app.play_ControlsTab1Image_2 = uiimage(app.play_ControlsTab1Grid_2);
            app.play_ControlsTab1Image_2.ImageSource = 'Playback_32.png';
            app.play_ControlsTab1Label_2 = uilabel(app.play_ControlsTab1Grid_2);
            app.play_ControlsTab1Label_2.Text = 'PM-RNI';
            app.config_geoAxesLabel_2 = uilabel(app.Document);
            app.config_geoAxesLabel_2.Interpreter = 'html';
            app.config_geoAxesLabel_2.Text = {'LOCALIDADES:'; '<font style="color: gray; font-size: 9px;">(relacionadas aos arquivos de medição)</font>'};
            app.TreeFileLocations = uitree(app.Document, 'checkbox');
            % Assign Checked Nodes
            app.TreeFileLocations.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeFileLocationsCheckedNodesChanged, true);
            app.Card1_numberOfStations = uilabel(app.Document);
            app.Card1_numberOfStations.Interpreter = 'html';
            app.Card1_numberOfStations.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: BLACK; font-size: 32px;">0</font>'; 'ESTAÇÕES LOCALIZADAS NOS MUNICÍPIOS SOB ANÁLISE</p>'};
            app.Card2_numberOfRiskStations = uilabel(app.Document);
            app.Card2_numberOfRiskStations.Interpreter = 'html';
            app.Card2_numberOfRiskStations.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: #a2142f; font-size: 32px;">0</font>'; 'ESTAÇÕES NO ENTORNO DE REGISTROS DE NÍVEIS ACIMA DE 14 V/m</p>'};
            app.Card3_stationsOnRoute = uilabel(app.Document);
            app.Card3_stationsOnRoute.Interpreter = 'html';
            app.Card3_stationsOnRoute.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: black; font-size: 32px;">0</font>'; 'ESTAÇÕES INSTALADAS NO ENTORNO DA ROTA</p>'};
            app.Card4_stationsOutRoute = uilabel(app.Document);
            app.Card4_stationsOutRoute.Interpreter = 'html';
            app.Card4_stationsOutRoute.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: #a2142f; font-size: 32px;">0</font>'; 'ESTAÇÕES INSTALADAS FORA DA ROTA</p>'};
            app.LocationList = uitextarea(app.Document);
            app.LocationList.Editable = 'off';
            app.LocationListEdit = uiimage(app.Document);
            app.LocationListEdit.ImageClickedFcn = createCallbackFcn(app, @LocationListEditClicked, true);
            app.LocationListEdit.Tooltip = {'Edita lista de localidades'};
            app.LocationListEdit.ImageSource = 'Edit_32.png';
            app.LocationListLabel = uilabel(app.Document);
            app.LocationListLabel.Interpreter = 'html';
            app.LocationListLabel.Text = {'LOCALIDADES SOB ANÁLISE:'; '<font style="color: gray; font-size: 9px;">(relacionadas às estações previstas no PM-RNI)</font>'};
            app.toolGrid = uigridlayout(app.GridLayout);
            app.tool_ControlPanelVisibility = uiimage(app.toolGrid);
            app.tool_ControlPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            app.tool_TableVisibility = uiimage(app.toolGrid);
            app.tool_TableVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.tool_TableVisibility.Tooltip = {'Visibilidade da tabela'};
            app.tool_TableVisibility.ImageSource = 'View_16.png';
            app.tool_ExportFiles = uiimage(app.toolGrid);
            app.tool_ExportFiles.ImageClickedFcn = createCallbackFcn(app, @tool_ExportTableAsExcelSheet, true);
            app.tool_ExportFiles.Enable = 'off';
            app.tool_ExportFiles.Tooltip = {'Exporta análise'};
            app.tool_ExportFiles.ImageSource = 'Export_16.png';
            app.jsBackDoor = uihtml(app.toolGrid);
            app.tool_UploadFinalFile = uiimage(app.toolGrid);
            app.tool_UploadFinalFile.ImageClickedFcn = createCallbackFcn(app, @tool_UploadFinalFileImageClicked, true);
            app.tool_UploadFinalFile.Enable = 'off';
            app.tool_UploadFinalFile.Tooltip = {'Upload do arquivo final para o Sharepoint'};
            app.tool_UploadFinalFile.ImageSource = 'Up_24.png';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.EditSelectedUITableRow = uimenu(app.ContextMenu);
            app.EditSelectedUITableRow.MenuSelectedFcn = createCallbackFcn(app, @tool_EditRowImageClicked, true);
            app.EditSelectedUITableRow.Text = 'Editar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMonitoringPlan(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end