classdef AppRNI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Button                          matlab.ui.control.Button
        ImageOpenFile                   matlab.ui.control.Image
        SelectFilesLabel                matlab.ui.control.Label
        UIpanel                         matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        MedicoesTab                     matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        MedicoesLabel                   matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        TreeLocalRNI                    matlab.ui.container.CheckBoxTree
        DistMedicoesLabel               matlab.ui.control.Label
        LocaisdasmediesdasRNILabel      matlab.ui.control.Label
        MeasUFTab                       matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ClearButtonCyties               matlab.ui.control.Button
        PARNILabel                      matlab.ui.control.Label
        DistMaiorRNILabel               matlab.ui.control.Label
        SelecioneosGRUOsdesejadosLabel  matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        SelecioneasLocalidadesdesejadasLabel  matlab.ui.control.Label
        ListBox_GR_UO                   matlab.ui.control.ListBox
        PointsTab                       matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        EditField_LongGrau_2            matlab.ui.control.NumericEditField
        EditFieldIncrPoints             matlab.ui.control.NumericEditField
        DropDown_Tipo                   matlab.ui.control.DropDown
        TipologiaLabel                  matlab.ui.control.Label
        EditFieldNEstPont               matlab.ui.control.NumericEditField
        Tree_CoordEnd                   matlab.ui.container.Tree
        Button_AddPoints                matlab.ui.control.Button
        LongitudeLabel_3                matlab.ui.control.Label
        LatitudeLabel_3                 matlab.ui.control.Label
        EditField_LatGrau_2             matlab.ui.control.NumericEditField
        EndereoscrticosondeforamavaliadosasRNIsLabel  matlab.ui.control.Label
        EditField_LongGrau              matlab.ui.control.NumericEditField
        EditField_LatGrau               matlab.ui.control.NumericEditField
        EditField_DistPont              matlab.ui.control.NumericEditField
        DropDown_Serv                   matlab.ui.control.DropDown
        DropDown_Local                  matlab.ui.control.DropDown
        DropDown_UF                     matlab.ui.control.DropDown
        LongitudeLabel                  matlab.ui.control.Label
        CalcularMaioresnveisButton      matlab.ui.control.Button
        UFLabel                         matlab.ui.control.Label
        PlotarKMLButton                 matlab.ui.control.Button
        DistnciaLabel                   matlab.ui.control.Label
        metrosLabel                     matlab.ui.control.Label
        LatitudeLabel                   matlab.ui.control.Label
        ServioLabel                     matlab.ui.control.Label
        LocalidadeLabel                 matlab.ui.control.Label
        NEstaoLabel                     matlab.ui.control.Label
        DemandaspontuaisMPFentidadesexternasetcLabel  matlab.ui.control.Label
        StationTab                      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        TabelaCheckBox                  matlab.ui.control.CheckBox
        VisualizaoLabel                 matlab.ui.control.Label
        GravardadosinseridosnatabelaLabel  matlab.ui.control.Label
        GravarButton                    matlab.ui.control.Button
        MapaCheckBox                    matlab.ui.control.CheckBox
        HTML                            matlab.ui.control.HTML
        ConfigTab                       matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        EditFieldDist_PARNI             matlab.ui.control.NumericEditField
        EditFieldDistMedicoes           matlab.ui.control.NumericEditField
        RangeLabel                      matlab.ui.control.Label
        DropDownlocalNivelMaior         matlab.ui.control.DropDown
        DropDownlocalNivelMenor         matlab.ui.control.DropDown
        DropDownLocalBarra              matlab.ui.control.DropDown
        DropDownIntesidade              matlab.ui.control.DropDown
        NveisdeEmxVmLabel               matlab.ui.control.Label
        LocaldabarradeEVmLabel          matlab.ui.control.Label
        VariaodascoresmapaLabel         matlab.ui.control.Label
        ConfiguraesdoMapadoAppRNILabel  matlab.ui.control.Label
        DistPARNILabel                  matlab.ui.control.Label
        DisMedicoesLabel                matlab.ui.control.Label
        ConfiguraesdasdistnciasdoAppRNILabel  matlab.ui.control.Label
        ListaPARNILabel                 matlab.ui.control.Label
    end

   
    properties (Access = private)

        Data_PA_RNI;            % Dados das estações do Plano Anual de RNI
        Data_Meas_Probes;       % Dados das medições obtidas pelas em campo pelas sondas       
        Data_Meas_cache;        % Dados de todas as informações de Data_PA_RNI em cache 
        UTTable_Formated;       % Dados de todas as informações da UITable inicial formatada em cache
        Data_Meas_cache_Select; % Dados filtrados de Data_PA_RNI em cache
        Data_Meas_cache_All     % Dados de todas as informações Calculadas de de Data_PA_RNI em cache 
        Data_PA_RNI_Out         % Dados das  medições de RNI calculados que serão gravados no arquivo XLS  
        Data_Localidades        % Dados da Tabela das Localidades do Brasil
        Data_Serv_Rad_Tel       % Dados dos serviços de Radiodifusão e de Telecom fiscalizados pela Anatel
        EditFieldRow;           % Dado do número da linha selecionada da tabela UITable
        mapAxes;                % Eixo do mapa
        Data_Points;            % Dados dos pontos (locais) de interesses nas demandas pontuais        
        % Estrutura dos de todos obtidos para avaliação
        metaData = struct('Filename', {}, 'Measures', {}, 'Sensor', {}, 'Data', {}, 'LatitudeLimits', {}, 'LongitudeLimits', {}, 'MetaDataProbe', {})

    end 
    
    methods (Access = private)
        
        function Map_Config(app)
          
            % Defini as características do mapa
            app.UIpanel.AutoResizeChildren = 'off';
            app.mapAxes = geoaxes(app.UIpanel, "Units","normalized", "Position",[0 0 1 1 ], "FontSize",9, "Box","off", "TickDir","none", "Grid","off");
            
            % Elimina as informações dos Labels do mapa
            app.mapAxes.LatitudeLabel.String  = '';
            app.mapAxes.LongitudeLabel.String = '';
       
            % Define limtes geográficos com ênfase no mapa do Brasil
            geolimits(app.mapAxes, [-50, 35], [-90, 15]);

            % Configura o toolbar do mapa
            tb = axtoolbar(app.mapAxes,"default");
            addToolbarMapButton(tb, 'basemap');
           
        end

        function Colors_FieldValue(app)
                     
            % Possibilita adicionar novos pontos no mapAxes sem apagar os existentes
            hold(app.mapAxes, 'on');

            % Define limtes geográficos conforme arquivos de medições
            geolimits(app.mapAxes, 'auto')
            
            % Plota os pontos no mapAxes atribuindo cores aos E(V/m) em cada coordenada
            hPlot = geoscatter(app.mapAxes, app.Data_Meas_Probes.Latitude, app.Data_Meas_Probes.Longitude, [], app.Data_Meas_Probes.FieldValue, 'filled');
            hPlot.DataTipTemplate.DataTipRows(3).Label  = 'Nivel';
            hPlot.DataTipTemplate.DataTipRows(3).Format = '%2.2f V/m';
            
            % Fecha o mapAxes
            hold(app.mapAxes, 'off');

            % Configura inicialmente a barra de níveis no mapa
            colorbar(app.mapAxes, 'off');  % Remove a colorbar do geoaxes;
            colorbar(app.mapAxes, 'Location', app.DropDownLocalBarra.Value);
            app.mapAxes.CLimMode = 'auto';
            colormap(app.mapAxes, app.DropDownIntesidade.Value);
            
        end

        function Format_UITable(app)
            
            % Cria uma matriz de strings de vazios e insere novas colunas na UITable
            matrix_nan       = strings(height(app.UITable.Data), numel(class.Constants.GUINewColumns));
            app.UITable.Data = [app.UITable.Data array2table(matrix_nan)];

            app.UITable.ColumnName  = class.Constants.GUIColumnsSelect;
            visibleData             = app.UITable.Data(:, [2, 3, 4, 6, 9, 10, 11, 12, 13, 14, 15]);
            app.UITable.Data        = visibleData;
            app.UITable.ColumnWidth = class.Constants.GuiColumnWidthCalc;

            % Destaca a apresentação das colunas da tabela com cores
            s = uistyle("BackgroundColor","cyan");
            addStyle(app.UITable,s,"column",5:9)
            s = uistyle("BackgroundColor","yellow");
            addStyle(app.UITable,s,"column",10:11)
        
        end
        
        function Calc_Dist_Emax(app, Dist_Max)

            % Armazena a distancia máxima em relação as estações, para avaliação das medições de RNI 
            Dist_Max_Level = Dist_Max; 
            
             % Testa se há algum arquivo de medições de RNI para cálculos
            if isempty(app.Data_Meas_Probes)
                uialert(app.UIFigure, ['Não foram encontrados arquivos referentes as medições! Insira os arquivos das medições' ...
                    ' e tente novamente!'], 'Go!','Icon','success')
               return
            end

            % Obtém os vetores dos dados das latitudes e longitudes das medições de RNI
            Lat_Meas_Probes  = app.Data_Meas_Probes.Latitude;
            Long_Meas_Probes = app.Data_Meas_Probes.Longitude;

            % Distancia máxima das estação em metros
            Dist_EMax = (Dist_Max_Level/111.12)/1000;

            format long;

            for jj=1:height(app.UITable.Data)

                % Obtém as latitudes e longitudes de cada estação do PA_RNI selecionados
                Lat_Estacao   = app.Data_Meas_cache_Select.('Latitude da Estação')(jj);
                Long_Estacao  = app.Data_Meas_cache_Select.('Longitude da Estação')(jj);

                % Define as coordenadas de Lat. e Long. máx. e Lat. e Long. min. em relação a dist_EMax
                Lat_Max  = Lat_Estacao + Dist_EMax;
                Lat_Min  = Lat_Estacao - Dist_EMax;
                Long_Max = Long_Estacao + Dist_EMax;
                Long_Min = Long_Estacao - Dist_EMax;

                % Filtra de app.Data_Meas_Probes apenas os dados das medições até a distância máx. e min. da estação
                filteredT     = app.Data_Meas_Probes((Lat_Meas_Probes >= Lat_Min & Lat_Meas_Probes <= Lat_Max) & (Long_Meas_Probes >= Long_Min & Long_Meas_Probes <= Long_Max), :);
                [~, maxIndexField] =  max(filteredT.FieldValue);

                if ~isempty(maxIndexField)
                    Lat_filteredT  = filteredT.Latitude;                    
                    Long_filteredT = filteredT.Longitude;
                    
                    % Realiza os cálculos entre a distância das coordenadas da estação e os pontos das medições do RNI
                    dist_Meas_St = deg2km(distance(Lat_Estacao, Long_Estacao, Lat_filteredT, Long_filteredT))*1000;

                    % Filtra apenas os dados das medições do RNI que esteja dentro do raio definido em dist_EMax
                    Dist_Max_fT    = filteredT((find(dist_Meas_St <= Dist_Max_Level)), :);

                    % Obtém as coordenadas e datatime associados ao índice e Field_Dist_Max
                    Field_Dist_Max = Dist_Max_fT.FieldValue;
                    Lat_Dist_Max   = Dist_Max_fT.Latitude;                    
                    Long_Dist_Max  = Dist_Max_fT.Longitude;
                    Data_Med_Max   = Dist_Max_fT.Timestamp;

                    % Obtém o indice do Emáx <= a Dist_EMax     
                    [~, maxIndexField]  =  max(Field_Dist_Max);

                    % Obtém do EMax as coordenadas e o datatime referentes ao indice maxIndex 
                    E_Level_Max   = Field_Dist_Max(maxIndexField);
                    Lat_Max_Calc  = Lat_Dist_Max(maxIndexField);
                    Long_Max_Calc = Long_Dist_Max(maxIndexField);
                    Data_Med_Calc = Data_Med_Max(maxIndexField);
                    ContMaior14Vm = sum(E_Level_Max >= Dist_Max_Level);

                    % Insere os valores das coordenadas, datatime, associados ao Emáx na UITable
                    if ~isempty(E_Level_Max)
                        app.UITable.Data.('Emáx (V/m)')(jj)      = E_Level_Max;
                        app.UITable.Data.('Latitude Emáx')(jj)   = Lat_Max_Calc;
                        app.UITable.Data.('Longitude Emáx')(jj)  = Long_Max_Calc;
                        app.UITable.Data.('> 14 V/M')(jj)        = ContMaior14Vm;
                        app.UITable.Data.('Data da Medição')(jj) = Data_Med_Calc; 
                        
                        % Inseri os valores calculados na tabela de cache app.Data_Meas_cache_Select
                        app.Data_Meas_cache_Select.('Emáx (V/m)')(jj)      = E_Level_Max;
                        app.Data_Meas_cache_Select.('Latitude Emáx')(jj)   = Lat_Max_Calc;
                        app.Data_Meas_cache_Select.('Longitude Emáx')(jj)  = Long_Max_Calc;
                        app.Data_Meas_cache_Select.('> 14 V/M')(jj)        = ContMaior14Vm;
                        app.Data_Meas_cache_Select.('Data da Medição')(jj) = Data_Med_Calc;   
                    end             
                end               
            end

            % Apresenta na UITable apenanas os dados das estações onde foram realizadas as avaliações de RNI
            app.UITable.Data = app.UITable.Data(~isnan(app.UITable.Data{:, 6}), :);
            app.Data_Meas_cache_Select = app.Data_Meas_cache_Select(~isnan(app.Data_Meas_cache_Select{:, 11}), :);
            app.UITable.ColumnEditable = [false false false false false false false false false true true];
          
            uialert(app.UIFigure, sprintf('Cálculos dos Campos elétricos máximos (EMax) realizados com sucesso para %d metros de distância das estações!', app.EditFieldDistMedicoes.Value), 'Tudo certo!', 'Icon', 'success');
        end
        
        
        function Mapa_Table(app)

              if app.TabelaCheckBox.Value && app.MapaCheckBox.Value
                app.UITable.Visible = "on";
                app.UIpanel.Visible = "on";
                % Defini a area inical ocupada pelo uipanel no Grid
                app.UIpanel.Layout.Row    =  2;
                app.UIpanel.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo table
                app.UITable.Layout.Row    =  3;
                app.UITable.Layout.Column =  [2, 3];
            elseif app.TabelaCheckBox.Value
                % Defini a area inical ocupada pelo table
                app.UITable.Layout.Row    =  [2, 3];
                app.UITable.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo uipanel no Grid
                app.UIpanel.Visible = "off";
                app.UITable.Visible = "on";
            elseif app.MapaCheckBox.Value
                % Defini a area inical ocupada pelo uipanel no Grid
                app.UIpanel.Layout.Row    =  [2, 3];
                app.UIpanel.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo uipanel no Grid
                app.UITable.Visible = "off";
                app.UIpanel.Visible = "on";
            else
                app.UITable.Visible = "on";
                app.UIpanel.Visible = "on";
                % Defini a area inical ocupada pelo uipanel no Grid
                app.UIpanel.Layout.Row    =  2;
                app.UIpanel.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo table
                app.UITable.Layout.Row    =  3;
                app.UITable.Layout.Column =  [2, 3];
              end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Centraliza a Tela do AppRNI ao abrir o aplicativo
            movegui(app.UIFigure, 'center');

            % Inicializa a tela pincipal do AppRNI maximizada
            app.UIFigure.WindowState = 'maximized';
            
            % Insere texto no app.HTML
            app.HTML.HTMLSource = '<!DOCTYPE html><html lang="en"><head><meta charset="UTF-8"><title>Modificar Tabela</title><style>#infoText {text-align: center; font-weight: bold; color: blue;}</style></head><body><p id="infoText">Para visualização das informações da estação monitorada clique em uma das linhas da tabela ao lado:</p></body></html>';
            
            % Insere o título no cabeçário da tabela dos dados do PA_RNI 
            app.ListaPARNILabel.Text = sprintf('Estações do Plano de Fiscalização de RNI do ano de %d',year(datetime('now')));
            
            % Realiza a leitura do arquivo csv do PA_RNI e cria a UITable
            app.Data_PA_RNI        = fcn.ReadFile_PA_RNI(app);
            app.UITable.Data       = app.Data_PA_RNI;
            app.UITable.ColumnName = class.Constants.GUIColumnsAll;

            % Armazena no vetor app.Data_Localidades as localidades do Brasil
            app.Data_Localidades  = fcn.ReadFile_Loc_Serv(app, "Local");

            % Armazena no vetor app.Data_Serv_Rad_Tel a lista de seviços de Radiod. e de Telecom fiscalizados pela Anatel.
            app.Data_Serv_Rad_Tel = fcn.ReadFile_Loc_Serv(app, "Serv");

            % Armazena em cache todos os dados da tabela do PA_RNI
            app.Data_Meas_cache   = app.UITable.Data;

            % Formata dos dados a serem apresntados na UITable
            Format_UITable(app);

            app.UTTable_Formated = app.UITable.Data; 

            figure(app.UIFigure);     
            
            % Insere em app.DropDown_UF a lista das UFs do Brasil
            app.DropDown_UF.Items = {'', 'AC', 'AL', 'AM', 'AP',	'BA', 'CE',	'ES', 'GO', 'MA', 'MG', 'MS', 'MT',	'PA', 'PB',	'PE', 'PI',	'PR', 'RJ', 'RN', 'RO',	'RR', 'RS', 'SC', 'SE',	'SP', 'TO'};
           
            % Insere em app.DropDown_Tipo.Items os tipos de locais
            app.DropDown_Tipo.Items = {'', 'Escola', 'Hospital', 'Creche', 'Escola', 'Casa', 'Apartamento', 'Outros'};
            
            app.MedicoesLabel.Text = string(app.EditFieldDistMedicoes.Value);
            app.PARNILabel.Text    = string(app.EditFieldDist_PARNI.Value);

            % Configurações do Mapa
            Map_Config(app);
                       
        end

        % Value changed function: ListBox_GR_UO
        function ListBox_GR_UOValueChanged(app, event)
           
            % Armazena em ER_UO as informações das URs da tabela do PA_RNI  
            ER_UO = app.Data_PA_RNI.('Unidade regional');

            app.UITable.Data       = app.Data_PA_RNI;
            Format_UITable(app);
            
            % Busca na Base de dados do PA_RNI as localidades da Unidade Operacional selecionada 
            Index_Local             = find(strcmp(ER_UO, app.ListBox_GR_UO.Value));
            app.UITable.Data        =  app.UITable.Data(Index_Local,:);
            app.Data_Meas_cache_All = app.Data_Meas_cache(Index_Local,:);
            Localidades             = unique(app.Data_Meas_cache.Municipio(Index_Local)); 

            delete(app.Tree.Children);

            % Criar um nó raiz 'Localidades'
            rootNode = uitreenode(app.Tree, 'Text', 'Localidades');
            
            % Escreve na app.Tree as informações relacionadas a localidade correpondente a Unidade Regional selecionada 
            for ii = 1:numel(Localidades)
                childNode{ii} = uitreenode(rootNode, 'Text', Localidades(ii));
            end
            expand(app.Tree)

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            
            % Aramazena os dados selecionado dos Checkboxes
            selectedNodes = app.Tree.CheckedNodes;

            if ~isempty(selectedNodes)
                idxLogical_Cach = [];

            % Busca na Base de dados do PA_RNI as localidades da Unidade Operacional selecionada 
            GR_UO_Select     = string(app.ListBox_GR_UO.Value);
            idxLogical_GR_UO = find(strcmp(app.Data_Meas_cache_All.('Unidade regional'), GR_UO_Select));
            Data_PA_RNI_idx  = app.Data_Meas_cache_All(idxLogical_GR_UO,:);

            % Escreve na app.Tree as informações relacionadas a localidade correpondente a Unidade Regional selecionada 
            for pp = 1:numel(selectedNodes)
                idxLogical      = find(strcmp(Data_PA_RNI_idx.('Municipio'), string(selectedNodes(pp).Text)));
                idxLogical      = unique([idxLogical_Cach; idxLogical], 'stable');
                idxLogical_Cach = idxLogical;
                
            end
            if ~isempty(idxLogical_Cach)
                app.UITable.Data           = Data_PA_RNI_idx(idxLogical,:);
                app.Data_Meas_cache_Select = app.Data_Meas_cache_All(idxLogical,:);
            else
                app.UITable.Data           = Data_PA_RNI_idx;
                app.Data_Meas_cache_Select = app.Data_Meas_cache_All;
            end

            Format_UITable(app);

            % Defini o valor de Dist_Max_Level e chama a função Calc_Dist_Emax para realização dos cáculos
            Dist_Max_Level = app.EditFieldDist_PARNI.Value;
            Calc_Dist_Emax(app, Dist_Max_Level);

            else
                app.UITable.Data = app.UTTable_Formated;
            end

        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)

            app.TabGroup.SelectedTab = app.StationTab;
            app.EditFieldRow         = event.InteractionInformation.DisplayRow;

            if isempty(app.Data_Meas_cache_Select)
                uialert(app.UIFigure, 'Realize o cálculo dos parâmetros de Emáx do(s) arquivo(s) RNI e após selecione com duplo click a visualização das infomeções da estação desejada', 'Aviso')
                return
            end
            
            % Escreve os textos referentes aos dados  app.Data_Meas_cache_Select da estação selecionada em htmlContent
            htmlContent = sprintf([ ...
                '<!DOCTYPE html><html lang="en"><head>'...
                '<meta charset="UTF-8">'...
                '<meta name="viewport" content="width=device-width, initial-scale=1.0">'...
                '<title>Modificar Tabela</title>'...
                '<style>'...  
                'p.infoText { width: 100%%; text-align: left; font-weight: bold; color: red; margin: 10px 0; font-family: Times New Roman, sans-serif; font-size: 16px; }'... % Centraliza o texto, negrito, vermelho, Arial 12px
                'table { width: 100%%; border-collapse: collapse; margin-bottom: 18px; }'...
                'td { color: blue; font-weight: bold; border: 1px solid black; padding: 6px; text-align: left; }'...
                'td.greenText { color: green; }'... % Classe para texto verde
                '</style>'...
                '</head><body>'...
                '<p class="infoText">Dados da estação do Plano anual do RNI:</p>'...
                '<table>'...
                '<tr><td class="greenText">Estação N º: </td><td>%.0f</td></tr>'...
                '<tr><td class="greenText">Município/UF: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Serviço: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Latitude: </td><td>%.6f</td></tr>'...
                '<tr><td class="greenText">Longitude: </td><td>%.6f</td></tr>'...
                '</table>'...
                '<p class="infoText">Dados do monitoramento:</p>'...
                '<table>'...
                '<tr><td class="greenText">Data da medição: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Campo maior V/m: </td><td>%.3f</td></tr>'...
                '<tr><td class="greenText">Latitude Maior V/m: </td><td>%.6f</td></tr>'...
                '<tr><td class="greenText">Longitude maior V/m: </td><td>%.6f</td></tr>'...
                '<tr><td class="greenText">Medidas > 14 V/M: </td><td>%.0f</td></tr>'...
                '<tr><td class="greenText">Justificativa de NV: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Observações: </td><td>%s</td></tr>'...
                '</table>'...
                '</body></html>'], ...
                app.Data_Meas_cache_Select.('N° da Estacao')(app.EditFieldRow), ...
                strcat(app.Data_Meas_cache_Select.Municipio(app.EditFieldRow),"/",app.Data_Meas_cache_Select.UF(app.EditFieldRow)), ...
                app.Data_Meas_cache_Select.('Serviço')(app.EditFieldRow), ...
                app.Data_Meas_cache_Select.('Latitude da Estação')(app.EditFieldRow), ...
                app.Data_Meas_cache_Select.('Longitude da Estação')(app.EditFieldRow), ...
                app.Data_Meas_cache_Select.('Data da Medição')(app.EditFieldRow), ...
                app.Data_Meas_cache_Select.('Emáx (V/m)')(app.EditFieldRow), ...
                app.Data_Meas_cache_Select.('Latitude Emáx')(app.EditFieldRow), ...
                app.Data_Meas_cache_Select.('Longitude Emáx')(app.EditFieldRow), ...
                app.Data_Meas_cache_Select.('> 14 V/M')(app.EditFieldRow), ...
                app.Data_Meas_cache_Select.('Justificativa (apenas NV)')(app.EditFieldRow), ...
                app.Data_Meas_cache_Select.('Observações importantes')(app.EditFieldRow));

                app.HTML.HTMLSource = htmlContent;

                % Obtém as coordenadas da estação selecionada pra plotar no mapAxes
                Lat_Est  = app.Data_Meas_cache_Select.('Latitude da Estação')(app.EditFieldRow);
                Long_Est = app.Data_Meas_cache_Select.('Longitude da Estação')(app.EditFieldRow);
         
                % Obtém as coordeandas das coordenadas onde está o ponto de Emáx
                Lat_Est_EMax  =  app.Data_Meas_cache_Select{app.EditFieldRow,11};
                Long_Est_EMax =  app.Data_Meas_cache_Select{app.EditFieldRow,12};

                % Abre o plot para as inserções dos vários pontos
                hold(app.mapAxes, 'on');
                
                % Antes de plotar, apaga os plots que não mais fazem sentido.
                plotTag = 'tableSelectionRow';
                delete(findobj(app.mapAxes.Children, 'Tag', plotTag))
                
                % Plot the point station
                EstPlot = geoscatter(app.mapAxes, Lat_Est, Long_Est, 'Marker', 'p', 'MarkerEdgeColor', 'red', 'MarkerFaceColor', 'red', 'Tag', plotTag);

                EstPlot.DataTipTemplate.DataTipRows(3) = sprintf('Estação nº: %d',app.Data_Meas_cache_Select.('N° da Estacao')(app.EditFieldRow));
                EstPlot.DataTipTemplate.DataTipRows = EstPlot.DataTipTemplate.DataTipRows([1, 2, 3]);

                % Desenha o cículo de raio Dist_Emax ao redor da estação 
                drawcircle(app.mapAxes, 'Position', [Lat_Est, Long_Est], ...
                                        'Radius', (app.EditFieldDistMedicoes.Value/100000), ...
                                        'Color', 'green', ...
                                        'FaceSelectable', 0, ...
                                        'InteractionsAllowed', 'none', ...
                                        'EdgeAlpha', 0, ...
                                        'Tag', plotTag)

                % Plot the point EMax
                EmaxPlot = geoscatter(app.mapAxes, Lat_Est_EMax, Long_Est_EMax, app.Data_Meas_cache_Select.('Emáx (V/m)')(app.EditFieldRow), 'LineWidth', 6, 'Marker','square', 'MarkerEdgeColor', 'yellow', 'Tag', plotTag);
                EmaxPlot.DataTipTemplate.DataTipRows = EstPlot.DataTipTemplate.DataTipRows([1, 2, 3]);
                EmaxPlot.DataTipTemplate.DataTipRows(3).Label = 'Nivel (Emáx)';
                EmaxPlot.DataTipTemplate.DataTipRows(3).Value = app.Data_Meas_cache_Select.('Emáx (V/m)')(app.EditFieldRow);
                EmaxPlot.DataTipTemplate.DataTipRows(3).Format = '%2.2f V/m';

                % Define limtes geográficos conforme arquivos de meidições
                geolimits(app.mapAxes, [Lat_Est-0.05, Lat_Est+0.05], [Long_Est-0.05, Long_Est+0.05]);

                % Fecha o mapAxes para inserção do pontos
                hold(app.mapAxes, 'off');
            
        end

        % Image clicked function: ImageOpenFile
        function ImageOpenFileClicked2(app, event)
            
            % Armazena as informações de latitude e longitude das estações 
            St_RNI_Lat  = app.Data_PA_RNI.('Latitude da Estação');
            St_RNI_Long = app.Data_PA_RNI.('Longitude da Estação');

            % Abre o diretório dos arquivos das medições das sondas
            initialPath = 'C:\P&D\AppRNI\DataBase\Meas_Sondas';
            
            % Abrir a caixa de diálogo para selecionar múltiplos arquivos
            [fileName, folderName] = uigetfile({'*.txt';'*.csv';'*.mat';'*.*'}, ...
                                              'Selecione arquivos', initialPath, ...
                                              'MultiSelect', 'on');
            %Mantém a Tela principal (figure) em primeiro plano
            figure(app.UIFigure);
            
            % Verifica se algum arquivo foi selecionado
            if isequal(fileName, 0)
                uialert(app.UIFigure, 'Nenhum arquivo selecionado! Selecione algum arquivo de medição de RNI', 'Aviso')
                return
            end

            % Verifica o tipo de arquivo que foi selecionado
            if ischar(fileName)
                fileName = {fileName};
            end

            fileFullName = {};
            for ii = 1:length(fileName)
                fileFullName{ii} = fullfile(folderName, fileName{ii});
            end
                
            % Descarta os arquivos já lidos.
            idxExistFile = ~ismember(fileFullName, {app.metaData.Filename});
            fileFullName = fileFullName(idxExistFile);


            for pp = 1:numel(fileFullName)
                 % Extrai do arquivo a informação sobre o tipo de sonda que gerou o arquivo de medição
                 Type_Meas_Probes = fcn.TypeMeasProbe(app, fileFullName{pp});

                 % Obtém todas os dados relavantes dos arquivos das medições de RNI
                 app.metaData(end+1) = fcn.ReadFile_Meas_Probes(app, Type_Meas_Probes, fileFullName{pp}, pp, numel(fileFullName));                 
            end

            % Armazenar os dados de medições dos arquivos
            listOfTables = {app.metaData.Data};            
            app.Data_Meas_Probes = sortrows(vertcat(listOfTables{:}), 'Timestamp', 'descend');

            LocalMaisProximo = {};

            for jj = 1:numel(app.metaData)    
                LatitudeLimSt = app.metaData(jj).LatitudeLimits;
                LongitudeLimSt = app.metaData(jj).LongitudeLimits;

                Distance = distance(mean(LatitudeLimSt), mean(LongitudeLimSt), St_RNI_Lat, St_RNI_Long);
                
                % Calcular a diferença entre cada elemento do vetor e o número alvo
                [~, idxMinDistance] = min(abs(Distance));
                
                % Encontrar o valor mais próximo
                LocalMaisProximo{end+1} = sprintf('%s - %s', app.Data_PA_RNI.UF{idxMinDistance}, app.Data_PA_RNI.Municipio{idxMinDistance});
            end 
            LocalMaisProximo = unique(LocalMaisProximo);
            
            % Escreve na app.Tree as informações relacionadas a localidade
            delete(app.TreeLocalRNI.Children);

            % Criar um nó raiz
            rootNode = uitreenode(app.TreeLocalRNI, 'Text', 'Localidades');

            for ll = 1:numel(LocalMaisProximo)
                childNode{ll} = uitreenode(rootNode, 'Text', LocalMaisProximo{ll});
            end
            expand(app.TreeLocalRNI)

            % Seleciona todos os nós da TreeLocalRNI (CheckedNodes)
            app.TreeLocalRNI.CheckedNodes = app.TreeLocalRNI.Children;

            TreeLocalRNICheckedNodesChanged(app, event);

            % Define configurações do mapAxes
            Colors_FieldValue(app);
        
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            d = drawrectangle(app.mapAxes);
            geolimits(app.mapAxes, [d.Position(1), d.Position(1)+d.Position(3)], [d.Position(2), d.Position(2)+d.Position(4)])
            delete(d)

        end

        % Callback function: TreeLocalRNI
        function TreeLocalRNICheckedNodesChanged(app, event)
            
            selectedNodes = app.TreeLocalRNI.CheckedNodes;

            if ~isempty(selectedNodes)
                idxLogical_Cach = [];
            
            for pp = 1:numel(selectedNodes)
                idxLogical  = find(strcmp(strcat(app.Data_Meas_cache.UF, " - ", app.Data_Meas_cache.Municipio), string(selectedNodes(pp).Text)));
                idxLogical  = unique([idxLogical_Cach; idxLogical], 'stable');
                idxLogical_Cach = idxLogical;
            end
            if ~isempty(idxLogical_Cach)
                app.UITable.Data          = app.UTTable_Formated(idxLogical_Cach,:);
                app.Data_Meas_cache_Select = app.Data_Meas_cache(idxLogical_Cach,:);
            else
                app.UITable.Data          = app.UITable.Data;
                app.Data_Meas_cache_Select = app.Data_Meas_cache;
            end

            % calcula o Emax das estações e os valores de Datatime e coordenadas associadas aos Emáx 
            Dist_Max_Level = app.EditFieldDistMedicoes.Value;
            Calc_Dist_Emax(app, Dist_Max_Level);

            else
                app.UITable.Data = app.UTTable_Formated;
            end                       

        end

        % Callback function
        function InserirnatabelaButtonPushed(app, event)
            
            app.UITable.Data = [];
            app.UITable.ColumnName = class.Constants.GUIColumnsSelect;
            app.UITable.Data{1, 1} = app.EditFieldUFPont.Value;
            app.UITable.Data{1, 2} = app.EditFieldLocalPont.Value;
            app.UITable.Data{1, 3} = app.EditFieldServPont.Value;
            app.UITable.Data{1, 4} = app.EditFieldNEstPont.Value;
            app.UITable.Data{1, 5} = '';
            app.UITable.Data{1, 6} = '';
            app.UITable.Data{1, 7} = '';
            app.UITable.Data{1, 8} = '';
            app.UITable.Data{1, 9} = '';
            app.UITable.Data{1, 10} = '';
            app.UITable.Data{1, 11} = '';

            visibleData = app.UITable.Data(:, [3, 4, 5, 7, 10, 11, 12, 13, 14, 15, 16]);
            app.UITable.Data = visibleData;
            app.UITable.ColumnEditable = [false false false false false false false false false true true];
            % app.UITable.BackgroundColor = 'yellow';
            uialert(app.UIFigure, 'Cálculos dos Campos elétricos máximos (EMax) realizados com sucesso!', 'Tudo certo!','Icon','success')

        end

        % Button pushed function: GravarButton
        function GravarButtonPushed(app, event)
           
            % Caminho relativo do arquivo do PA_RNI
            DirApp = 'C:\P&D\AppRNI';
            % Especifica o nome do arquivo CSV com os cálculos que será criado
            filename = '\DataBase\PA_RNI\Dados_PA_RNI_Calculados.xlsx';

            % Obter o caminho absoluto do Arquivo de saida gerado
            Path_Data_PA_RNI_Out = fullfile(DirApp, filename);
           
            % Lendo os dados existentes do arquivo CSV
            try
                opts = detectImportOptions(Path_Data_PA_RNI_Out);
                opts.PreserveVariableNames = true;
                app.Data_PA_RNI_Out = readtable(Path_Data_PA_RNI_Out, opts);
            catch
                startupFcn(app);
            end
      
            index_out = find(~isnan(app.UITable.Data.('Emáx (V/m)')));

            for pp = 1: numel(index_out)
                Idx_Station = find(strcmp(string(app.Data_Meas_cache.('N° da Estacao')),  string(app.UITable.Data.('N° da Estacao')(index_out(pp)))));

                app.Data_PA_RNI_Out.('Data da Medição'){Idx_Station} = app.UITable.Data.('Data da Medição')(index_out(pp));
                app.Data_PA_RNI_Out.('Emáx (V/m)'){Idx_Station} = app.UITable.Data.('Emáx (V/m)')(index_out(pp));
                app.Data_PA_RNI_Out.('Latitude Emáx'){Idx_Station} = app.UITable.Data.('Latitude Emáx')(index_out(pp));
                app.Data_PA_RNI_Out.('Longitude Emáx'){Idx_Station} = app.UITable.Data.('Longitude Emáx')(index_out(pp));
                app.Data_PA_RNI_Out.('> 14 V/M')(Idx_Station) = app.UITable.Data.('> 14 V/M')(index_out(pp));
                app.Data_PA_RNI_Out.('Justificativa (apenas NV)'){Idx_Station} = app.UITable.Data.('Justificativa (apenas NV)')(index_out(pp));
                app.Data_PA_RNI_Out.('Observações importantes'){Idx_Station} = app.UITable.Data.('Observações importantes')(index_out(pp));
            end
            
            % Escrevendo os dados atualizados de volta no arquivo xlsx
            writetable(app.Data_PA_RNI_Out, Path_Data_PA_RNI_Out);
            uialert(app.UIFigure, 'Arquivo XLSX atualizado com sucesso!', 'Feito!','Icon','success');

        end

        % Value changed function: DropDown_UF
        function DropDown_UFValueChanged(app, event)
         
            %Armazena em ER_UO as informações das URs da tabela do PA_RNI  
            RNI_UF = app.Data_Localidades.('UF');
            
            %Busca na Base de dados do PA_RNI as localidades da Unidade Operacional selecionada 
            Index_Local_UF   = find(strcmp(RNI_UF, app.DropDown_UF.Value));

            RNI_Localidades  = unique(app.Data_Localidades.Municipios(Index_Local_UF)); 

            app.DropDown_Local.Items = "";

            app.DropDown_Local.Items = [""; RNI_Localidades];

            %Armazena em ER_UO as informações das URs da tabela do PA_RNI  
            RNI_Serv = app.Data_Serv_Rad_Tel.Servico;

            app.DropDown_Serv.Items = "";

            app.DropDown_Serv.Items = [""; RNI_Serv];
            
        end

        % Button pushed function: CalcularMaioresnveisButton
        function CalcularMaioresnveisButtonPushed(app, event)

            Dist_Max_Level = app.EditField_DistPont.Value;
            Latitude_Pont  = app.EditField_LatGrau.Value ; 
            Longitude_Pont = app.EditField_LongGrau.Value;

            app.UITable.Data = app.UITable.Data([],:);
            app.UITable.Data.UF(1)                = app.DropDown_UF.Value;
            app.UITable.Data.Municipio(1)         = app.DropDown_Local.Value;
            app.UITable.Data.('Serviço') (1)      = app.DropDown_Serv.Value;
            app.UITable.Data.('N° da Estacao')(1) = app.EditFieldNEstPont.Value;
  
            app.UITable.Data.('Data da Medição')(1) = "";
            app.UITable.Data.('Emáx (V/m)')(1)      = "";
            app.UITable.Data.('Latitude Emáx') (1)  = "";
            app.UITable.Data.('Longitude Emáx')(1)  = "";
            app.UITable.Data.('> 14 V/M')(1)        = 0;
            
            app.UITable.Data.('Justificativa (apenas NV)')(1) = "";
            app.UITable.Data.('Observações importantes') (1)  = "";

            app.Data_Meas_cache_Select.('N° da Estacao')(1) = app.EditFieldNEstPont.Value;
            app.Data_Meas_cache_Select.Municipio(1) = app.DropDown_Local.Value;
            app.Data_Meas_cache_Select.UF(1) = app.DropDown_UF.Value;
            app.Data_Meas_cache_Select.('Serviço')(1) = app.DropDown_Serv.Value;
            app.Data_Meas_cache_Select.('Latitude da Estação')(1) = Latitude_Pont;
            app.Data_Meas_cache_Select.('Longitude da Estação')(1) = Longitude_Pont;    

            Calc_Dist_Emax(app, app.EditField_DistPont.Value);

            app.GravarButton.Enable = false;

            % Hold the current plot to add new points without erasing the existing ones
            hold(app.mapAxes, 'on');

            % Antes de plotar, apaga os plots que não mais fazem sentido.
            plotTag = 'tableSelectionRow';
            delete(findobj(app.mapAxes.Children, 'Tag', plotTag))
            

            % Obtém as coordenadas da estação selecionada pra plotar no mapAxes
            Lat_Est  = app.EditField_LatGrau.Value;
            Long_Est = app.EditField_LongGrau.Value;
         
            % Obtém as coordeandas das coordenadas onde está o ponto de Emáx
            Lat_Est_EMax  =  app.UITable.Data.('Latitude Emáx');
            Long_Est_EMax =  app.UITable.Data.('Longitude Emáx');

            % Plot the point station
            EstPlot = geoscatter(app.mapAxes, Lat_Est, Long_Est, 'Marker', 'p', 'MarkerEdgeColor', 'red', 'MarkerFaceColor', 'red', 'Tag', plotTag);

            EstPlot.DataTipTemplate.DataTipRows(3) = sprintf('Estação nº: %d',app.Data_Meas_cache_Select.('N° da Estacao')(app.EditFieldRow));
            EstPlot.DataTipTemplate.DataTipRows = EstPlot.DataTipTemplate.DataTipRows([1, 2, 3]);

            % Desenha o cículo de raio Dist_Emax ao redor da estação 
            drawcircle(app.mapAxes, 'Position', [Lat_Est, Long_Est], ...
                                    'Radius', (app.EditFieldDistMedicoes.Value/100000), ...
                                    'Color', 'green', ...
                                    'FaceSelectable', 0, ...
                                    'InteractionsAllowed', 'none', ...
                                    'EdgeAlpha', 0, ...
                                    'Tag', plotTag)

            % Plot the points
            for yy=1:numel(app.Data_Points)
                Adress_Point = geoscatter(app.mapAxes, app.Data_Points(yy).Latitude, app.Data_Points(yy).Longitude, 'Marker', 'diamond', 'MarkerEdgeColor', 'red', 'MarkerFaceColor', 'cyan', 'Tag', plotTag);
                Adress_Point.DataTipTemplate.DataTipRows(3) = sprintf('Ponto nº: %d',yy);
                Adress_Point.DataTipTemplate.DataTipRows = Adress_Point.DataTipTemplate.DataTipRows([1, 2, 3]);
            end

            % Plot the point EMax
            EmaxPlot = geoscatter(app.mapAxes, Lat_Est_EMax, Long_Est_EMax, app.Data_Meas_cache_Select.('Emáx (V/m)')(app.EditFieldRow), 'LineWidth', 6, 'Marker','square', 'MarkerEdgeColor', 'yellow', 'Tag', plotTag);
            EmaxPlot.DataTipTemplate.DataTipRows = EstPlot.DataTipTemplate.DataTipRows([1, 2, 3]);
            EmaxPlot.DataTipTemplate.DataTipRows(3).Label = 'Nivel (Emáx)';
            EmaxPlot.DataTipTemplate.DataTipRows(3).Value = app.Data_Meas_cache_Select.('Emáx (V/m)')(app.EditFieldRow);
            EmaxPlot.DataTipTemplate.DataTipRows(3).Format = '%2.2f V/m';

            % Define limtes geográficos conforme arquivos de meidições
            geolimits(app.mapAxes, [Lat_Est-0.05, Lat_Est+0.05], [Long_Est-0.05, Long_Est+0.05]);

            % Fecha o mapAxes para inserção do pontos
            hold(app.mapAxes, 'off');

        end

        % Button pushed function: Button_AddPoints
        function Button_AddPointsPushed(app, event)

            Incr_Node = app.EditFieldIncrPoints.Value+1;

            % % Criar um nó raiz 'Locais'
            % rootNode = uitreenode(app.Tree_CoordEnd, 'Text', 'Locais');
            
            struct_points = struct('Type_Point', app.DropDown_Tipo.Value, ...
                                   'Latitude'  , app.EditField_LatGrau_2.Value, ...
                                   'Longitude' , app.EditField_LongGrau_2.Value);

            AllValues = sprintf('%dº) Tipo: %s: Lat = %.6f, Long = %.6f',Incr_Node,app.DropDown_Tipo.Value, app.EditField_LatGrau_2.Value, app.EditField_LongGrau_2.Value);

            app.Data_Points = [app.Data_Points, struct_points];

            % Escreve na app.Tree as informações relacionadas a localidade correpondente a Unidade Regional selecionada 
            uitreenode(app.Tree_CoordEnd, 'Text', AllValues);
           
            expand(app.Tree_CoordEnd)

            app.EditFieldIncrPoints.Value = app.EditFieldIncrPoints.Value +1; 

        end

        % Callback function
        function EditFieldDistMedicoesValueChanged(app, event)

            app.MedicoesLabel.Text = string(app.EditFieldDistMedicoes.Value);
            
            % Recalcula o Emax das estações e os valores de Datatime e coordenadas associadas aos Emáx 
            Dist_Max_Level = app.MedicoesLabel.Text;
            Calc_Dist_Emax(app, Dist_Max_Level);

        end

        % Callback function
        function EditFieldDist_PARNIValueChanged(app, event)
            
            app.PARNILabel.Text    = string(app.EditFieldDist_PARNI.Value);
            
            % Recalcula o Emax das estações e os valores de Datatime e coordenadas associadas aos Emáx 
            Dist_Max_Level = app.PARNILabel.Text;
            Calc_Dist_Emax(app, Dist_Max_Level);

        end

        % Button pushed function: PlotarKMLButton
        function PlotarKMLButtonPushed(app, event)

            % Criar arquivo KML
            outputFileName = 'C:\P&D\AppRNI\DataBase\PA_RNI\mapa_pontos.kml';
            
            % Usar kmlwrite para gerar o arquivo KML
            kmlwrite(outputFileName, app.Data_Meas_Probes.Latitude, app.Data_Meas_Probes.Longitude);
            
            disp('Arquivo KML gerado com sucesso!');

        end

        % Value changed function: DropDownIntesidade
        function DropDownIntesidadeValueChanged(app, event)
            
            % Altera da intensiade da intensidade de cores do mapa
            colormap(app.mapAxes, app.DropDownIntesidade.Value);

        end

        % Value changed function: DropDownLocalBarra
        function DropDownLocalBarraValueChanged(app, event)
            
            % Altera o local da barra de níveis no mapa
            colorbar(app.mapAxes, 'off');  % Remove a colorbar do geoaxes;
            colorbar(app.mapAxes, 'Location', app.DropDownLocalBarra.Value);

        end

        % Value changed function: DropDownlocalNivelMenor
        function DropDownlocalNivelMenorValueChanged(app, event)
         
            % Altera o range do intensidade de campo (V/m)
            if app.DropDownlocalNivelMenor.Value == "auto"
                app.mapAxes.CLimMode = 'auto';
            elseif app.DropDownlocalNivelMenor.Value ~= "auto" && app.DropDownlocalNivelMaior.Value ~= "auto"
                app.mapAxes.CLim = [str2double(app.DropDownlocalNivelMenor.Value), str2double(app.DropDownlocalNivelMaior.Value)];
            end

        end

        % Value changed function: EditFieldDistMedicoes
        function EditFieldDistMedicoesValueChanged2(app, event)
           
            app.MedicoesLabel.Text = string(app.EditFieldDistMedicoes.Value);
            
            % Recalcula o Emax das estações e os valores de Datatime e coordenadas associadas aos Emáx 
            Dist_Max_Level = app.EditFieldDistMedicoes.Value;
            Calc_Dist_Emax(app, Dist_Max_Level);
            app.TabGroup.SelectedTab = app.MedicoesTab;
            
        end

        % Value changed function: EditFieldDist_PARNI
        function EditFieldDist_PARNIValueChanged2(app, event)
            
            app.PARNILabel.Text    = string(app.EditFieldDist_PARNI.Value);
            
            % Recalcula o Emax das estações e os valores de Datatime e coordenadas associadas aos Emáx 
            Dist_Max_Level = app.EditFieldDist_PARNI.Value;
            Calc_Dist_Emax(app, Dist_Max_Level);
            app.TabGroup.SelectedTab =  app.MeasUFTab;
            
        end

        % Value changed function: DropDownlocalNivelMaior
        function DropDownlocalNivelMaiorValueChanged(app, event)
            
            % Altera o range maior do intensidade de campo (V/m)
            if app.DropDownlocalNivelMaior.Value == "auto"
                app.mapAxes.CLimMode = 'auto';
            elseif app.DropDownlocalNivelMenor.Value ~= "auto" && app.DropDownlocalNivelMaior.Value ~= "auto"
                app.mapAxes.CLim = [str2double(app.DropDownlocalNivelMenor.Value), str2double(app.DropDownlocalNivelMaior.Value)];
            end
        end

        % Value changed function: EditFieldNEstPont
        function EditFieldNEstPontValueChanged(app, event)

            % Formato da representação do número
            app.EditFieldNEstPont.ValueDisplayFormat = '%d';

        end

        % Value changed function: EditField_LatGrau
        function EditField_LatGrauValueChanged(app, event)

            % Formato da representação do número
            app.EditField_LatGrau.ValueDisplayFormat = '%2.6f';
            
        end

        % Value changed function: EditField_LongGrau
        function EditField_LongGrauValueChanged(app, event)

            % Formato da representação do número
            app.EditField_LongGrau.ValueDisplayFormat = '%2.6f';

        end

        % Value changed function: EditField_LatGrau_2
        function EditField_LatGrau_2ValueChanged(app, event)

            % Formato da representação do número
            app.EditField_LatGrau_2.ValueDisplayFormat = '%2.6f';
            
        end

        % Value changed function: EditField_LongGrau_2
        function EditField_LongGrau_2ValueChanged(app, event)

            % Formato da representação do número
            app.EditField_LongGrau_2.ValueDisplayFormat = '%2.6f';

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
            % Acessar o nó raiz ou nó principal
            rootNode = app.TreeLocalRNI;
            
            % Verificar se o nó tem filhos e deletá-los
            if ~isempty(rootNode.Children)
                delete(rootNode.Children);  % Deletar todos os filhos do nó
            end

            app.UITable.Data = app.Data_PA_RNI;
            Format_UITable(app);

        end

        % Button pushed function: ClearButtonCyties
        function ClearButtonCytiesPushed(app, event)
            
            % Acessar o nó raiz ou nó principal
            rootNode = app.Tree;
            
            % Verificar se o nó tem filhos e deletá-los
            if ~isempty(rootNode.Children)
                delete(rootNode.Children);  % Deletar todos os filhos do nó
            end

            app.UITable.Data = app.Data_PA_RNI;
            Format_UITable(app);
        end

        % Value changed function: MapaCheckBox
        function MapaCheckBoxValueChanged(app, event)
            
            % Executa a função Mapa_Table para definição da apresentação do Mapa e/ou da tabela
            Mapa_Table(app);

        end

        % Value changed function: TabelaCheckBox
        function TabelaCheckBoxValueChanged(app, event)
            
            % Executa a função Mapa_Table para definição da apresentação do Mapa e/ou da tabela
            Mapa_Table(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ListaPARNILabel = uilabel(app.GridLayout);
            app.ListaPARNILabel.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MedicoesTab = uitab(app.TabGroup);
            app.MedicoesTab.Title = 'Meas All';
            app.GridLayout6 = uigridlayout(app.MedicoesTab);
            app.LocaisdasmediesdasRNILabel = uilabel(app.GridLayout6);
            app.LocaisdasmediesdasRNILabel.Text = 'Locais das medições das RNI:';
            app.DistMedicoesLabel = uilabel(app.GridLayout6);
            app.DistMedicoesLabel.Text = 'Distância maiores níveis E(V/m) em metros:';
            app.TreeLocalRNI = uitree(app.GridLayout6, 'checkbox');
            % Assign Checked Nodes
            app.TreeLocalRNI.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeLocalRNICheckedNodesChanged, true);
            app.ClearButton = uibutton(app.GridLayout6, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = 'Redmine_512.png';
            app.ClearButton.Text = 'Clear';
            app.MedicoesLabel = uilabel(app.GridLayout6);
            app.MedicoesLabel.Text = '';
            app.MeasUFTab = uitab(app.TabGroup);
            app.MeasUFTab.Title = 'Meas UF';
            app.GridLayout2 = uigridlayout(app.MeasUFTab);
            app.ListBox_GR_UO = uilistbox(app.GridLayout2);
            app.ListBox_GR_UO.Items = {'GR01', 'GR02', 'GR03', 'GR04', 'GR05', 'GR06', 'GR07', 'GR08', 'GR09', 'GR10', 'GR11', 'UO001', 'UO021', 'UO031', 'UO061', 'UO062', 'UO071', 'UO072', 'UO073', 'UO081', 'UO091', 'UO092', 'UO101', 'UO111', 'UO112', 'UO113'};
            app.ListBox_GR_UO.Multiselect = 'on';
            app.ListBox_GR_UO.ValueChangedFcn = createCallbackFcn(app, @ListBox_GR_UOValueChanged, true);
            app.ListBox_GR_UO.Value = {};
            app.SelecioneasLocalidadesdesejadasLabel = uilabel(app.GridLayout2);
            app.SelecioneasLocalidadesdesejadasLabel.Text = 'Selecione a(s) Localidade(s) desejada(s):';
            app.Tree = uitree(app.GridLayout2, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.SelecioneosGRUOsdesejadosLabel = uilabel(app.GridLayout2);
            app.SelecioneosGRUOsdesejadosLabel.Text = 'Selecione o(s) GR/UO(s) desejado(s):';
            app.DistMaiorRNILabel = uilabel(app.GridLayout2);
            app.DistMaiorRNILabel.Text = 'Distância maiores níveis E(V/m) em metros:';
            app.PARNILabel = uilabel(app.GridLayout2);
            app.PARNILabel.Text = '';
            app.ClearButtonCyties = uibutton(app.GridLayout2, 'push');
            app.ClearButtonCyties.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonCytiesPushed, true);
            app.ClearButtonCyties.Icon = 'Redmine_512.png';
            app.ClearButtonCyties.Text = 'Clear';
            app.PointsTab = uitab(app.TabGroup);
            app.PointsTab.Title = 'Points';
            app.GridLayout7 = uigridlayout(app.PointsTab);
            app.DemandaspontuaisMPFentidadesexternasetcLabel = uilabel(app.GridLayout7);
            app.DemandaspontuaisMPFentidadesexternasetcLabel.Text = 'Demandas pontuais (MPF, entidades externas, etc)';
            app.NEstaoLabel = uilabel(app.GridLayout7);
            app.NEstaoLabel.Text = 'Nº Estação:';
            app.LocalidadeLabel = uilabel(app.GridLayout7);
            app.LocalidadeLabel.Text = 'Localidade:';
            app.ServioLabel = uilabel(app.GridLayout7);
            app.ServioLabel.Text = 'Serviço:';
            app.LatitudeLabel = uilabel(app.GridLayout7);
            app.LatitudeLabel.Text = 'Latitude:';
            app.metrosLabel = uilabel(app.GridLayout7);
            app.metrosLabel.Text = 'metros';
            app.DistnciaLabel = uilabel(app.GridLayout7);
            app.DistnciaLabel.Text = 'Distância:';
            app.PlotarKMLButton = uibutton(app.GridLayout7, 'push');
            app.PlotarKMLButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarKMLButtonPushed, true);
            app.PlotarKMLButton.Text = 'Plotar KML';
            app.UFLabel = uilabel(app.GridLayout7);
            app.UFLabel.Text = 'UF:';
            app.CalcularMaioresnveisButton = uibutton(app.GridLayout7, 'push');
            app.CalcularMaioresnveisButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularMaioresnveisButtonPushed, true);
            app.CalcularMaioresnveisButton.Text = 'Calcular Maiores níveis';
            app.LongitudeLabel = uilabel(app.GridLayout7);
            app.LongitudeLabel.Text = 'Longitude:';
            app.DropDown_UF = uidropdown(app.GridLayout7);
            app.DropDown_UF.Items = {};
            app.DropDown_UF.ValueChangedFcn = createCallbackFcn(app, @DropDown_UFValueChanged, true);
            app.DropDown_UF.Value = {};
            app.DropDown_Local = uidropdown(app.GridLayout7);
            app.DropDown_Local.Items = {};
            app.DropDown_Local.Value = {};
            app.DropDown_Serv = uidropdown(app.GridLayout7);
            app.DropDown_Serv.Items = {''};
            app.DropDown_Serv.Value = '';
            app.EditField_DistPont = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_LatGrau = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_LatGrau.ValueChangedFcn = createCallbackFcn(app, @EditField_LatGrauValueChanged, true);
            app.EditField_LongGrau = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_LongGrau.ValueChangedFcn = createCallbackFcn(app, @EditField_LongGrauValueChanged, true);
            app.EndereoscrticosondeforamavaliadosasRNIsLabel = uilabel(app.GridLayout7);
            app.EndereoscrticosondeforamavaliadosasRNIsLabel.Text = 'Endereços críticos onde foram avaliados as RNI''s:';
            app.EditField_LatGrau_2 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_LatGrau_2.ValueChangedFcn = createCallbackFcn(app, @EditField_LatGrau_2ValueChanged, true);
            app.LatitudeLabel_3 = uilabel(app.GridLayout7);
            app.LatitudeLabel_3.Text = 'Latitude:';
            app.LongitudeLabel_3 = uilabel(app.GridLayout7);
            app.LongitudeLabel_3.Text = 'Longitude:';
            app.Button_AddPoints = uibutton(app.GridLayout7, 'push');
            app.Button_AddPoints.ButtonPushedFcn = createCallbackFcn(app, @Button_AddPointsPushed, true);
            app.Button_AddPoints.Text = '+';
            app.Tree_CoordEnd = uitree(app.GridLayout7);
            app.EditFieldNEstPont = uieditfield(app.GridLayout7, 'numeric');
            app.EditFieldNEstPont.ValueChangedFcn = createCallbackFcn(app, @EditFieldNEstPontValueChanged, true);
            app.TipologiaLabel = uilabel(app.GridLayout7);
            app.TipologiaLabel.Text = 'Tipologia:';
            app.DropDown_Tipo = uidropdown(app.GridLayout7);
            app.DropDown_Tipo.Items = {};
            app.DropDown_Tipo.Value = {};
            app.EditFieldIncrPoints = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_LongGrau_2 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_LongGrau_2.ValueChangedFcn = createCallbackFcn(app, @EditField_LongGrau_2ValueChanged, true);
            app.StationTab = uitab(app.TabGroup);
            app.StationTab.Title = 'Station';
            app.GridLayout5 = uigridlayout(app.StationTab);
            app.HTML = uihtml(app.GridLayout5);
            app.HTML.HTMLSource = '''';
            app.MapaCheckBox = uicheckbox(app.GridLayout5);
            app.MapaCheckBox.ValueChangedFcn = createCallbackFcn(app, @MapaCheckBoxValueChanged, true);
            app.MapaCheckBox.Text = 'Mapa';
            app.GravarButton = uibutton(app.GridLayout5, 'push');
            app.GravarButton.ButtonPushedFcn = createCallbackFcn(app, @GravarButtonPushed, true);
            app.GravarButton.Text = 'Gravar';
            app.GravardadosinseridosnatabelaLabel = uilabel(app.GridLayout5);
            app.GravardadosinseridosnatabelaLabel.Text = 'Gravar dados inseridos na tabela ';
            app.VisualizaoLabel = uilabel(app.GridLayout5);
            app.VisualizaoLabel.Text = 'Visualização:';
            app.TabelaCheckBox = uicheckbox(app.GridLayout5);
            app.TabelaCheckBox.ValueChangedFcn = createCallbackFcn(app, @TabelaCheckBoxValueChanged, true);
            app.TabelaCheckBox.Text = 'Tabela';
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.GridLayout8 = uigridlayout(app.ConfigTab);
            app.ConfiguraesdasdistnciasdoAppRNILabel = uilabel(app.GridLayout8);
            app.ConfiguraesdasdistnciasdoAppRNILabel.Text = 'Configurações das distâncias do App RNI:';
            app.DisMedicoesLabel = uilabel(app.GridLayout8);
            app.DisMedicoesLabel.Text = 'Distância da estação (Medições) em metros:';
            app.DistPARNILabel = uilabel(app.GridLayout8);
            app.DistPARNILabel.Text = 'Distância da estação (PA_RNI) em metros:';
            app.ConfiguraesdoMapadoAppRNILabel = uilabel(app.GridLayout8);
            app.ConfiguraesdoMapadoAppRNILabel.Text = 'Configurações do Mapa do App RNI:';
            app.VariaodascoresmapaLabel = uilabel(app.GridLayout8);
            app.VariaodascoresmapaLabel.Text = 'Variação das cores mapa:';
            app.LocaldabarradeEVmLabel = uilabel(app.GridLayout8);
            app.LocaldabarradeEVmLabel.Text = 'Local da barra de E(V/m):';
            app.NveisdeEmxVmLabel = uilabel(app.GridLayout8);
            app.NveisdeEmxVmLabel.Text = 'Níveis de Emáx (V/m):';
            app.DropDownIntesidade = uidropdown(app.GridLayout8);
            app.DropDownIntesidade.Items = {'default', 'turbo', 'summer', 'winter', 'hot'};
            app.DropDownIntesidade.ValueChangedFcn = createCallbackFcn(app, @DropDownIntesidadeValueChanged, true);
            app.DropDownIntesidade.Value = 'default';
            app.DropDownLocalBarra = uidropdown(app.GridLayout8);
            app.DropDownLocalBarra.Items = {'northoutside', 'southoutside', 'eastoutside', 'westoutside'};
            app.DropDownLocalBarra.ValueChangedFcn = createCallbackFcn(app, @DropDownLocalBarraValueChanged, true);
            app.DropDownLocalBarra.Value = 'eastoutside';
            app.DropDownlocalNivelMenor = uidropdown(app.GridLayout8);
            app.DropDownlocalNivelMenor.Items = {'auto', '0', '1', '2', '3', '4'};
            app.DropDownlocalNivelMenor.ValueChangedFcn = createCallbackFcn(app, @DropDownlocalNivelMenorValueChanged, true);
            app.DropDownlocalNivelMenor.Value = 'auto';
            app.DropDownlocalNivelMaior = uidropdown(app.GridLayout8);
            app.DropDownlocalNivelMaior.Items = {'auto', '4', '6', '8', '10', '12', '14', '16', '18', '20', '22', '24', '26', '28'};
            app.DropDownlocalNivelMaior.ValueChangedFcn = createCallbackFcn(app, @DropDownlocalNivelMaiorValueChanged, true);
            app.DropDownlocalNivelMaior.Value = 'auto';
            app.RangeLabel = uilabel(app.GridLayout8);
            app.RangeLabel.Text = 'até';
            app.EditFieldDistMedicoes = uieditfield(app.GridLayout8, 'numeric');
            app.EditFieldDistMedicoes.ValueChangedFcn = createCallbackFcn(app, @EditFieldDistMedicoesValueChanged2, true);
            app.EditFieldDistMedicoes.Value = 200;
            app.EditFieldDist_PARNI = uieditfield(app.GridLayout8, 'numeric');
            app.EditFieldDist_PARNI.ValueChangedFcn = createCallbackFcn(app, @EditFieldDist_PARNIValueChanged2, true);
            app.EditFieldDist_PARNI.Value = 200;
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.UIpanel = uipanel(app.GridLayout);
            app.SelectFilesLabel = uilabel(app.GridLayout);
            app.SelectFilesLabel.Text = 'Selecione o(s) arquivo(s) da(s) medições(s):';
            app.ImageOpenFile = uiimage(app.GridLayout);
            app.ImageOpenFile.ImageClickedFcn = createCallbackFcn(app, @ImageOpenFileClicked2, true);
            app.ImageOpenFile.ImageSource = fullfile(pathToMLAPP, 'files.png');
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppRNI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end