classdef winExternalRequest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        SubTabGroup               matlab.ui.container.TabGroup
        SubTab1                   matlab.ui.container.Tab
        SubGrid1                  matlab.ui.container.GridLayout
        TreePoints                matlab.ui.container.Tree
        AddNewPointPanel          matlab.ui.container.Panel
        AddNewPointGrid           matlab.ui.container.GridLayout
        NewPointDescription       matlab.ui.control.EditField
        NewPointDescriptionLabel  matlab.ui.control.Label
        NewPointLongitude         matlab.ui.control.NumericEditField
        NewPointLongitudeLabel    matlab.ui.control.Label
        NewPointLatitude          matlab.ui.control.NumericEditField
        NewPointLatitudeLabel     matlab.ui.control.Label
        NewPointStation           matlab.ui.control.NumericEditField
        NewPointStationLabel      matlab.ui.control.Label
        NewPointType              matlab.ui.control.DropDown
        NewPointTypeLabel         matlab.ui.control.Label
        AddNewPointCancel         matlab.ui.control.Image
        AddNewPointConfirm        matlab.ui.control.Image
        AddNewPointMode           matlab.ui.control.Image
        TreePointsLabel           matlab.ui.control.Label
        TreeFileLocations         matlab.ui.container.Tree
        TreeFileLocationsLabel    matlab.ui.control.Label
        SubTab2                   matlab.ui.container.Tab
        SubGrid2                  matlab.ui.container.GridLayout
        reportPanel               matlab.ui.container.Panel
        reportGrid                matlab.ui.container.GridLayout
        reportVersion             matlab.ui.control.DropDown
        reportVersionLabel        matlab.ui.control.Label
        reportModelName           matlab.ui.control.DropDown
        reportModelNameLabel      matlab.ui.control.Label
        reportLabel               matlab.ui.control.Label
        eFiscalizaPanel           matlab.ui.container.Panel
        eFiscalizaGrid            matlab.ui.container.GridLayout
        reportIssue               matlab.ui.control.NumericEditField
        reportIssueLabel          matlab.ui.control.Label
        reportUnit                matlab.ui.control.DropDown
        reportUnitLabel           matlab.ui.control.Label
        reportSystem              matlab.ui.control.DropDown
        reportSystemLabel         matlab.ui.control.Label
        eFiscalizaLabel           matlab.ui.control.Label
        DockModule                matlab.ui.container.GridLayout
        dockModule_Close          matlab.ui.control.Image
        dockModule_Undock         matlab.ui.control.Image
        Document                  matlab.ui.container.GridLayout
        AxesToolbar               matlab.ui.container.GridLayout
        axesTool_RegionZoom       matlab.ui.control.Image
        axesTool_RestoreView      matlab.ui.control.Image
        plotPanel                 matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        Toolbar                   matlab.ui.container.GridLayout
        tool_UploadFinalFile      matlab.ui.control.Image
        tool_GenerateReport       matlab.ui.control.Image
        tool_OpenPopupProject     matlab.ui.control.Image
        tool_PeakLabel            matlab.ui.control.Label
        tool_PeakIcon             matlab.ui.control.Image
        tool_Separator2           matlab.ui.control.Image
        tool_ExportFiles          matlab.ui.control.Image
        tool_TableVisibility      matlab.ui.control.Image
        tool_Separator1           matlab.ui.control.Image
        tool_PanelVisibility      matlab.ui.control.Image
        ContextMenu               matlab.ui.container.ContextMenu
        DeletePoint               matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
        Context = 'EXTERNALREQUEST'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
        popupContainer

        projectData
        measData
        measTable

        UIAxes
        restoreView = struct( ...
            'ID', {}, ...
            'xLim', {}, ...
            'yLim', {}, ...
            'cLim', {} ...
        )
    end


    methods
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)

                    case 'customForm'
                        switch event.HTMLEventData.uuid
                            case 'eFiscalizaSignInPage'
                                report_uploadInfoController(app.mainApp, event.HTMLEventData, 'uploadDocument')

                            otherwise
                                error('UnexpectedEvent')
                        end

                    case 'auxApp.winExternalRequest.TreePoints'
                        DeleteSelectedPoint(app, struct('ContextObject', app.TreePoints))

                    otherwise
                        error('UnexpectedEvent')
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function ipcSecondaryMatlabCallsHandler(app, callingApp, eventName, varargin)
            try
                switch class(callingApp)
                    case {'winMonitorRNI', 'winMonitorRNI_exported'}
                        switch eventName
                            case {'onFileListAdded', 'onFileListRemoved', 'onFileListUnmerged', 'onFileListMerged'}
                                app.measData = app.mainApp.measData;
                                TreeFileLocationBuilding(app)

                                if ~isempty(app.measData)
                                    Analysis(app)
                                end

                            case 'onAnalysisParameterChanged'
                                app.UITable.ColumnName{4} = sprintf('Qtd.|> %.0f V/m', app.mainApp.General.context.EXTERNALREQUEST.electricFieldStrengthThreshold);
                                updateAnalysis(app.projectData, app.measData, app.mainApp.General, eventName);
                                Analysis(app)

                            case 'onAxesParameterChanged'
                                if ~isequal(app.UIAxes.Basemap, app.mainApp.General.plot.geographicAxes.basemap)
                                    app.UIAxes.Basemap = app.mainApp.General.plot.geographicAxes.basemap;

                                    switch app.mainApp.General.plot.geographicAxes.basemap
                                        case {'darkwater', 'none'}
                                            app.UIAxes.Grid = 'on';
                                        otherwise
                                            app.UIAxes.Grid = 'off';
                                    end
                                end

                                plot.axes.Colormap(app.UIAxes, app.mainApp.General.plot.geographicAxes.colormap)
                                plot.axes.Colorbar(app.UIAxes, app.mainApp.General.plot.geographicAxes.colorbar)

                            case 'onPlotParameterChanged'
                                Analysis(app)

                            otherwise
                                error('model:winExternalRequest:UnexpectedCall', 'Unexpected call "%s"', eventName)
                        end
    
                    otherwise
                        error('model:winExternalRequest:UnexpectedCaller', 'Unexpected caller "%s"', class(callingApp))
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;

            switch tabIndex
                case 1
                    appName = class(app);
                    elToModify = {
                        app.AxesToolbar;
                        app.AddNewPointMode;
                        app.AddNewPointConfirm;
                        app.AddNewPointCancel;
                        app.tool_PanelVisibility;
                        app.tool_TableVisibility;
                        app.tool_ExportFiles;
                        app.tool_PeakIcon;
                        app.tool_OpenPopupProject;
                        app.tool_GenerateReport;
                        app.tool_UploadFinalFile;
                        app.dockModule_Undock;
                        app.dockModule_Close;
                        app.TreePoints
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.AxesToolbar.UserData.id,    'styleImportant', struct('borderTopLeftRadius', '0', 'borderTopRightRadius', '0')), ...
                            struct('appName', appName, 'dataTag', app.AddNewPointMode.UserData.id,       'tooltip', struct('defaultPosition', 'top',    'textContent', 'Alterna visibilidade do painel de inclusão de ponto')), ...
                            struct('appName', appName, 'dataTag', app.AddNewPointConfirm.UserData.id,    'tooltip', struct('defaultPosition', 'top',    'textContent', 'Confirma edição')), ...
                            struct('appName', appName, 'dataTag', app.AddNewPointCancel.UserData.id,     'tooltip', struct('defaultPosition', 'top',    'textContent', 'Cancela edição')), ...
                            struct('appName', appName, 'dataTag', app.tool_PanelVisibility.UserData.id,  'tooltip', struct('defaultPosition', 'top',    'textContent', 'Alterna visibilidade do painel')), ...
                            struct('appName', appName, 'dataTag', app.tool_TableVisibility.UserData.id,  'tooltip', struct('defaultPosition', 'top',    'textContent', 'Alterna entre três layouts do conjunto plot+tabela<br>(apenas plot, apenas tabela ou plot+tabela)')), ...
                            struct('appName', appName, 'dataTag', app.tool_ExportFiles.UserData.id,      'tooltip', struct('defaultPosition', 'top',    'textContent', 'Exporta análise (.xlsx, .kml)')), ...
                            struct('appName', appName, 'dataTag', app.tool_PeakIcon.UserData.id,         'tooltip', struct('defaultPosition', 'top',    'textContent', 'Aplica zoom em torno do local de valor máximo')), ...
                            struct('appName', appName, 'dataTag', app.tool_OpenPopupProject.UserData.id, 'tooltip', struct('defaultPosition', 'top',    'textContent', 'Edita informações do projeto<br>(fiscalizada, arquivo de backup etc)')), ...
                            struct('appName', appName, 'dataTag', app.tool_GenerateReport.UserData.id,   'tooltip', struct('defaultPosition', 'top',    'textContent', 'Gera relatório')), ...
                            struct('appName', appName, 'dataTag', app.tool_UploadFinalFile.UserData.id,  'tooltip', struct('defaultPosition', 'top',    'textContent', 'Upload relatório')), ...
                            struct('appName', appName, 'dataTag', app.dockModule_Undock.UserData.id,     'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Reabre módulo em outra janela')), ...
                            struct('appName', appName, 'dataTag', app.dockModule_Close.UserData.id,      'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Fecha módulo')), ...
                            struct('appName', appName, 'dataTag', app.TreePoints.UserData.id, 'listener', struct('componentName', 'auxApp.winExternalRequest.TreePoints', 'keyEvents', {{'Delete', 'Backspace'}})) ...
                        });
                    catch
                    end

                case 2
                    context = app.Context;

                    if isempty(app.projectData.modules.(context).ui.system) && ~isequal(app.projectData.modules.(context).ui.system, app.mainApp.General.reportLib.system)
                        app.projectData.modules.(context).ui.system = app.mainApp.General.reportLib.system;
                    end
                    
                    if isempty(app.projectData.modules.(context).ui.unit)   && ~isequal(app.projectData.modules.(context).ui.unit,   app.mainApp.General.reportLib.unit)
                        app.projectData.modules.(context).ui.unit   = app.mainApp.General.reportLib.unit;
                    end

                    if ~isdeployed()
                        app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS', 'eFiscaliza HM', 'eFiscaliza DS'};
                    end
                    app.reportSystem.Value     = app.projectData.modules.(context).ui.system;

                    set(app.reportUnit, 'Items', app.mainApp.General.eFiscaliza.defaultValues.unit, ...
                                        'Value', app.projectData.modules.(context).ui.unit)
                    app.reportIssue.Value     = app.projectData.modules.(context).ui.issue;
                    app.reportModelName.Items = app.projectData.modules.(context).ui.templates;
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            % ...
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable = 1;
            end

            if app.mainApp.General.context.EXTERNALREQUEST.electricFieldStrengthThreshold ~= 14
                app.UITable.ColumnName{4} = sprintf('Qtd.|> %.0f V/m', app.mainApp.General.context.EXTERNALREQUEST.electricFieldStrengthThreshold);
            end

            [app.UIAxes, app.restoreView] = plot.axesCreationController(app.plotPanel, app.mainApp.General);

            TreeFileLocationBuilding(app)
            if ~isempty(app.measData)
                Analysis(app)
            end
            
            app.tool_TableVisibility.UserData = 1;

            % Especificidades do auxApp.winExternalRequest em relação ao
            % auxApp.winMonitoringPlan:
            TreePointsBuilding(app)
            app.NewPointType.Items = [{''}; app.mainApp.General.context.EXTERNALREQUEST.locationType];
            layout_newPointPanel(app, 'off')
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            % ...
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function Analysis(app)
            app.progressDialog.Visible = 'visible';

            % Identifica arquivos selecionados pelo usuário, atualizando a
            % tabela de referência para o plot (app.measTable) e a relação
            % de localidades selecionadas na GUI (em app.projectData).
            [idxFile, selectedFileLocations] = FileIndex(app);
            app.measTable = createMeasTable(app.measData(idxFile));

            updateSelectedListOfLocations(app.projectData, selectedFileLocations, app.Context)

            initialSelection = updateTable(app);
            layout_TableStyle(app)

            % Atualiza outros elementos da GUI, inclusive painel com quantitativo 
            % de estações.
            updateToolbar(app)            

            % Atualiza plot.
            plot_MeasuresAndPoints(app)

            if ~isempty(initialSelection)
                [~, idxRow] = ismember(initialSelection, app.UITable.Data.ID);
                if idxRow
                    app.UITable.Selection = idxRow;
                    UITableSelectionChanged(app, struct('PreviousSelection', [], 'Selection', idxRow))
                end
            end

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function [idxFile, selectedFileLocations] = FileIndex(app)
            if ~isempty(app.TreeFileLocations.SelectedNodes)
                selectedFileLocations = {app.TreeFileLocations.SelectedNodes.Text};
                idxFile = find(ismember({app.measData.Location}, selectedFileLocations));
            else
                selectedFileLocations = {};
                idxFile = [];
            end
        end

        %-----------------------------------------------------------------%
        function initialSelection = updateTable(app)
            initialSelection = [];
            if ~isempty(app.UITable.Selection)
                initialSelection = app.UITable.Data.ID{app.UITable.Selection};
            end

            table2Render = app.projectData.modules.EXTERNALREQUEST.pointsTable(:, {'ID',                    ...
                                                                                   'Description',           ...                                                                                    
                                                                                   'numberOfMeasures',      ...
                                                                                   'numberOfRiskMeasures',  ...
                                                                                   'minDistanceForMeasure', ...
                                                                                   'minFieldValue',         ...
                                                                                   'meanFieldValue',        ...
                                                                                   'maxFieldValue',         ...
                                                                                   'Justificativa'});
            set(app.UITable, 'Data', table2Render, 'Selection', [])
        end

        %-----------------------------------------------------------------%
        function updateToolbar(app)
            context = app.Context;

            measDataNonEmpty                = ~isempty(app.measData);
            measTableNonEmpty               = ~isempty(app.measTable);
            pointsTableNonEmpty             = ~isempty(app.projectData.modules.(context).pointsTable);
            reportModelSelected             = ~isempty(app.reportModelName.Value);
            reportFinalVersionGenerated     = ~isempty(app.projectData.modules.(context).generatedFiles.lastHTMLDocFullPath);
            
            app.tool_ExportFiles.Enable     = pointsTableNonEmpty & measDataNonEmpty;
            app.tool_GenerateReport.Enable  = pointsTableNonEmpty & measDataNonEmpty & reportModelSelected;
            app.tool_UploadFinalFile.Enable = reportFinalVersionGenerated;

            app.tool_PeakLabel.Visible      = measTableNonEmpty;
            app.tool_PeakIcon.Enable        = measTableNonEmpty;

            if measTableNonEmpty
                [~, maxIndex] = max(app.measTable.FieldValue);

                app.tool_PeakLabel.Text    = sprintf('%.2f V/m\n(%.6f, %.6f)', app.measTable.FieldValue(maxIndex), ...
                                                                               app.measTable.Latitude(maxIndex),   ...
                                                                               app.measTable.Longitude(maxIndex));
                app.tool_PeakIcon.UserData = struct('idxMax',    maxIndex,                         ...
                                                    'Latitude',  app.measTable.Latitude(maxIndex), ...
                                                    'Longitude', app.measTable.Longitude(maxIndex));
            end
        end

        %-----------------------------------------------------------------%
        function plot_RestartAxes(app)
            cla(app.UIAxes)
            geolimits(app.UIAxes, 'auto')
            app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');
        end

        %-----------------------------------------------------------------%
        function plot_MeasuresAndPoints(app)
            % prePlot
            plot_RestartAxes(app)

            % Measures
            if ~isempty(app.measTable)
                plot.draw.Measures(app.UIAxes, app.measTable, app.mainApp.General.context.EXTERNALREQUEST.electricFieldStrengthThreshold, app.mainApp.General);

                % Abaixo estabelece como limites do eixo os limites atuais,
                % configurados automaticamente pelo MATLAB. Ao fazer isso,
                % contudo, esses limites serão orientados às medidas e não às
                % estações.
                plot_AxesDefaultLimits(app, 'measures')
            end

            % Stations/Points
            if ~isempty(app.UITable.Data)
                refPointsTable = app.projectData.modules.EXTERNALREQUEST.pointsTable;

                plot.draw.Points(app.UIAxes, refPointsTable, 'Pontos críticos', app.mainApp.General)
            end
            plot_AxesDefaultLimits(app, 'stations/points')
        end

        %-----------------------------------------------------------------%
        function plot_SelectedPoint(app)
            delete(findobj(app.UIAxes.Children, 'Tag', 'SelectedPoint'))

            if ~isempty(app.UITable.Selection)
                idxSelectedPoint   = app.UITable.Selection;
                selectedPointTable = app.projectData.modules.EXTERNALREQUEST.pointsTable(idxSelectedPoint, :);

                plot.draw.SelectedPoint(app.UIAxes, selectedPointTable, app.mainApp.General, class(app))
            end
        end

        %-----------------------------------------------------------------%
        function plot_AxesDefaultLimits(app, zoomOrientation)
            arguments
                app
                zoomOrientation {mustBeMember(zoomOrientation, {'stations/points', 'measures'})}
            end

            if strcmp(app.mainApp.General.plot.geographicAxes.zoomOrientation, zoomOrientation) || (isempty(app.measTable) && strcmp(app.mainApp.General.plot.geographicAxes.zoomOrientation, 'measures'))
                geolimits(app.UIAxes, app.UIAxes.LatitudeLimits, app.UIAxes.LongitudeLimits)
                app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');
            end
        end

        %-----------------------------------------------------------------%
        function [idxMissingInfo, idxRiskMeasures] = layout_searchUnexpectedTableValues(app)
            idxMissingInfo   = [];
            idxRiskMeasures  = [];

            if ~isempty(app.UITable.Data)
                idxMissingInfo  = find(((app.projectData.modules.EXTERNALREQUEST.pointsTable.numberOfMeasures == 0) & (app.projectData.modules.EXTERNALREQUEST.pointsTable.("Justificativa") == "-")));
                idxRiskMeasures = find(app.projectData.modules.EXTERNALREQUEST.pointsTable.numberOfRiskMeasures > 0);
            end
        end

        %-----------------------------------------------------------------%
        function layout_TableStyle(app)
            removeStyle(app.UITable)

            tableDataNonEmpty = ~isempty(app.UITable.Data);
            if tableDataNonEmpty
                % Identifica pontos que NÃO tiveram medições no seu entorno. 
                % Ou pontos que apresentaram medições com níveis acima de 14 V/m.
                [idxMissingInfo, idxRiskMeasures] = layout_searchUnexpectedTableValues(app);

                if ~isempty(idxMissingInfo)
                    columnIndex1 = find(ismember(app.UITable.Data.Properties.VariableNames, 'Justificativa'));
                    s1 = uistyle('BackgroundColor', '#c80b0f', 'FontColor', 'white');                
                    addStyle(app.UITable, s1, "cell", [idxMissingInfo, repmat(columnIndex1, numel(idxMissingInfo), 1)])
                end

                if ~isempty(idxRiskMeasures)
                    columnIndex2 = find(ismember(app.UITable.Data.Properties.VariableNames, 'numberOfRiskMeasures'));

                    s2 = uistyle('BackgroundColor', '#c80b0f', 'FontColor', 'white');
                    addStyle(app.UITable, s2, "cell", [idxRiskMeasures, repmat(columnIndex2, numel(idxRiskMeasures), 1)])
                end
            end
        end

        %-----------------------------------------------------------------%
        function TreeFileLocationBuilding(app)
            if ~isempty(app.TreeFileLocations.Children)
                delete(app.TreeFileLocations.Children)
            end

            locationGroups = unique({app.measData.Location});
            selectedNodes = [];
            for ii = 1:numel(locationGroups)
                treeNode = uitreenode(app.TreeFileLocations, 'Text', locationGroups{ii});

                if strcmp(locationGroups{ii}, app.projectData.modules.EXTERNALREQUEST.ui.selectedGroup)
                    selectedNodes = [selectedNodes; treeNode];
                end
            end

            measDataNonEmpty = ~isempty(app.measData);
            if measDataNonEmpty
                if isempty(selectedNodes)
                    selectedNodes = app.TreeFileLocations.Children(1);
                elseif ~isscalar(selectedNodes)
                    selectedNodes = selectedNodes(1);
                end
                app.TreeFileLocations.SelectedNodes = selectedNodes;

            else
                app.measTable = [];

                plot_RestartAxes(app)
                updateTable(app);
            end

            updateToolbar(app)
        end

        %-----------------------------------------------------------------%
        function TreePointsBuilding(app)
            if ~isempty(app.TreePoints.Children)
                delete(app.TreePoints.Children)
            end

            for ii = 1:height(app.projectData.modules.EXTERNALREQUEST.pointsTable)
                uitreenode(app.TreePoints, 'Text', app.projectData.modules.EXTERNALREQUEST.pointsTable.ID{ii}, 'NodeData', ii, 'ContextMenu', app.ContextMenu);
            end
        end

        %-----------------------------------------------------------------%
        function layout_newPointPanel(app, editionStatus)
            arguments
                app 
                editionStatus char {mustBeMember(editionStatus, {'on', 'off'})}
            end            

            switch editionStatus
                case 'on'
                    app.AddNewPointMode.ImageSource = 'addFiles_32Filled.png';
                    app.AddNewPointMode.UserData.status = true;
                    
                    app.SubGrid1.RowHeight{5} = 170;
                    app.SubGrid1.ColumnWidth(end-1:end) = {18, 18};
                    app.AddNewPointConfirm.Enable = 1;
                    app.AddNewPointCancel.Enable  = 1;

                case 'off'
                    app.AddNewPointMode.ImageSource = 'addFiles_32.png';
                    app.AddNewPointMode.UserData.status = false;

                    app.SubGrid1.RowHeight{5} = 0;
                    app.SubGrid1.ColumnWidth(end-1:end) = {0,0};
                    app.AddNewPointConfirm.Enable = 0;
                    app.AddNewPointCancel.Enable  = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            try
                app.projectData = mainApp.projectData;
                app.measData    = mainApp.measData;

                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', app.Context)
            delete(app)
            
        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function DockModuleGroup_ButtonPushed(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;
                    
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];

                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Image clicked function: tool_PanelVisibility, tool_PeakIcon, 
        % ...and 1 other component
        function Toolbar_InteractionImageClicked(app, event)
            
            switch event.Source
                case app.tool_PanelVisibility
                    if app.SubTabGroup.Visible
                        app.tool_PanelVisibility.ImageSource = 'layout-sidebar-left-off.svg';
                        app.SubTabGroup.Visible = 0;
                        app.Document.Layout.Column = [2 5];
                    else
                        app.tool_PanelVisibility.ImageSource = 'layout-sidebar-left.svg';
                        app.SubTabGroup.Visible = 1;
                        app.Document.Layout.Column = [4 5];
                    end

                case app.tool_TableVisibility
                    app.tool_TableVisibility.UserData = mod(app.tool_TableVisibility.UserData+1, 3);

                    switch app.tool_TableVisibility.UserData
                        case 0
                            app.UITable.Visible = 0;
                            app.Document.RowHeight = {24,'1x', 0, 0};
                        case 1
                            app.UITable.Visible = 1;
                            app.Document.RowHeight = {24, '1x', 10, 186};
                        case 2
                            app.UITable.Visible = 1;
                            app.Document.RowHeight = {0, 0, 0, '1x'};
                    end

                case app.tool_PeakIcon
                    if ~isempty(app.tool_PeakIcon.UserData)
                        ReferenceDistance_km = 1;
                        plot.zoom(app.UIAxes, app.tool_PeakIcon.UserData.Latitude, app.tool_PeakIcon.UserData.Longitude, ReferenceDistance_km)
                        plot.datatip.Create(app.UIAxes, 'Measures', app.tool_PeakIcon.UserData.idxMax)
                    end
            end

        end

        % Image clicked function: tool_ExportFiles
        function Toolbar_ExportTableAsExcelSheet(app, event)
            
            context = app.Context;
            indexes = FileIndex(app);

            pointsTable = app.projectData.modules.(context).pointsTable;
            if isempty(pointsTable)
                warningMessages = 'Funcionalidade aplicável apenas quando há ao menos um ponto crítico';
                ui.Dialog(app.UIFigure, 'warning', warningMessages);
                return
            end

            if ~isempty(indexes)
                % <VALIDAÇÕES>
                if numel(indexes) < numel(app.measData)
                    initialQuestion  = 'Deseja exportar arquivos de análise preliminar (.xlsx / .kml) que contemplem informações de TODAS as localidades de agrupamento, ou apenas da SELECIONADA?';
                    initialSelection = ui.Dialog(app.UIFigure, 'uiconfirm', initialQuestion, {'Todas', 'Selecionada', 'Cancelar'}, 1, 3);

                    switch initialSelection
                        case 'Cancelar'
                            return
                        case 'Todas'
                            indexes = 1:numel(app.measData);
                    end
                end

                if ~isempty(layout_searchUnexpectedTableValues(app))
                    warningMessages = ['Há registro de pontos críticos localizados na(s) localidade(s) sob análise para os quais '     ...
                                       'não foram identificadas medidas no entorno. Nesse caso específico, deve-se preencher ' ...
                                       'o campo "Justificativa" e anotar os registros, caso aplicável.<br><br>Deseja ignorar ' ...
                                       'esse alerta, exportando PRÉVIA da análise?'];
                    userSelection   = ui.Dialog(app.UIFigure, 'uiconfirm', warningMessages, {'Sim', 'Não'}, 2, 2);
                    if userSelection == "Não"
                        return
                    end
                end
                % </VALIDAÇÕES>
    
                % <PROCESSO>
                % (a) Solicita ao usuário nome do arquivo de saída...
                appName       = class.Constants.appName;
                nameFormatMap = {'*.zip', [appName, ' (*.zip)']};                
                defaultName   = appEngine.util.DefaultFileName(app.mainApp.General.fileFolder.userPath, [appName '_Preview']);
                fileZIP       = ui.Dialog(app.UIFigure, 'uiputfile', '', nameFormatMap, defaultName);
                if isempty(fileZIP)
                    return
                end
    
                d = ui.Dialog(app.UIFigure, 'progressdlg', 'Em andamento a criação do arquivo de medidas no formato ".xlsx".');
    
                savedFiles   = {};
                errorFiles   = {};
    
                % (b) Gera a tabela global de medidas (englobando todas as localidades 
                %     de agrupamento).
                measTableGlobal    = createMeasTable(app.measData(indexes));

                % (c) Arquivo no formato .XLSX
                fileName_XLSX = fullfile(app.mainApp.General.fileFolder.tempPath, 'Demanda externa (Preview).xlsx');
                [status, msgError] = fileWriter.ExternalRequest(fileName_XLSX, pointsTable, timetable2table(measTableGlobal), app.mainApp.General.context.EXTERNALREQUEST.electricFieldStrengthThreshold, app.mainApp.General.context.EXTERNALREQUEST.exportOptions.xlsx);
                if status
                    savedFiles{end+1} = fileName_XLSX;
                else
                    errorFiles{end+1} = msgError;
                end
        
                % (d) Arquivos no formato .KML: "Measures" e "Route" 
                if app.mainApp.General.context.EXTERNALREQUEST.exportOptions.kml
                    d.Message = textFormatGUI.HTMLParagraph('Em andamento a criação dos arquivos de medidas e rotas no formato ".kml".');

                    groupLocations = unique({app.measData(indexes).Location});

                    for ii = 1:numel(groupLocations)
                        groupLocation = groupLocations{ii};
                        [~, groupLocationIndex] = ismember(groupLocation, {app.TreeFileLocations.Children.Text});

                        if ~isequal(app.TreeFileLocations.SelectedNodes, app.TreeFileLocations.Children(groupLocationIndex))
                            app.TreeFileLocations.SelectedNodes = app.TreeFileLocations.Children(groupLocationIndex);
                            TreeFileLocationsSelectionChanged(app, struct('SelectedNodes', app.TreeFileLocations.Children(groupLocationIndex)))
                        end

                        groupLocationText    = replace(app.TreeFileLocations.SelectedNodes.Text, '/', '-');
                        groupLocationIndexes = FileIndex(app);
                        groupLocationMeasTable = createMeasTable(app.measData(groupLocationIndexes));

                        % MEDIDAS
                        hMeasPlot = findobj(app.UIAxes.Children, 'Tag', 'Measures');                        
                        KML1File  = fullfile(app.mainApp.General.fileFolder.tempPath, sprintf('%s (Measures).kml', groupLocationText));
                        [status1, msgError1] = fileWriter.KML(KML1File, 'Measures', timetable2table(groupLocationMeasTable), hMeasPlot);
                        if status1
                            savedFiles{end+1} = KML1File;
                        else
                            errorFiles{end+1} = msgError1;
                        end

                        % ROTA
                        KML2File = fullfile(app.mainApp.General.fileFolder.tempPath, sprintf('%s (Route).kml', groupLocationText));
                        [status2, msgError2] = fileWriter.KML(KML2File, 'Route', timetable2table(groupLocationMeasTable));
                        if status2
                            savedFiles{end+1} = KML2File;
                        else
                            errorFiles{end+1} = msgError2;
                        end
                    end
                end

                % (e) Arquivo no formato .ZIP
                if ~isempty(savedFiles)
                    zip(fileZIP, savedFiles)
    
                    [~, fileName, fileExt] = fileparts(savedFiles);
                    savedFiles = strcat('•&thinsp;', fileName, fileExt);
                    ui.Dialog(app.UIFigure, 'none', sprintf('Lista de arquivos criados:\n%s', strjoin(savedFiles, '\n')));
                end
    
                if ~isempty(errorFiles)
                    ui.Dialog(app.UIFigure, 'error', strjoin(errorFiles, '\n'));
                end

                delete(d)
            end

        end

        % Image clicked function: tool_GenerateReport
        function Toolbar_GenerateReportImageClicked(app, event)
            
            context = app.Context;
            indexes = FileIndex(app);

            if ~isempty(indexes)
                % <VALIDAÇÕES>
                if numel(indexes) < numel(app.measData)
                    initialQuestion  = 'Deseja gerar relatório que contemple informações de TODAS as localidades de agrupamento, ou apenas da SELECIONADA?';
                    initialSelection = ui.Dialog(app.UIFigure, 'uiconfirm', initialQuestion, {'Todas', 'Selecionada', 'Cancelar'}, 1, 3);

                    switch initialSelection
                        case 'Cancelar'
                            return
                        case 'Todas'
                            indexes = 1:numel(app.measData);
                    end
                end

                warningMessages = {};
                if ~report_checkEFiscalizaIssueId(app.mainApp, app.projectData.modules.(context).ui.issue)
                    warningMessages{end+1} = sprintf('O número da inspeção "%.0f" é inválido.', app.projectData.modules.(context).ui.issue);
                end
                
                if ~isempty(layout_searchUnexpectedTableValues(app))
                    warningMessages{end+1} = ['Há registro de pontos críticos localizados na(s) localidade(s) sob análise para os quais '     ...
                                              'não foram identificadas medidas no entorno. Nesse caso específico, deve-se preencher ' ...
                                              'o campo "Justificativa" e anotar os registros, caso aplicável.'];
                end

                if ~isempty(warningMessages)
                    warningMessages = strjoin(warningMessages, '<br><br>');

                    switch app.reportVersion.Value
                        case 'Definitiva'
                            warningMessages = [warningMessages, '<br><br>Isso impossibilita a geração da versão DEFINITIVA do relatório.'];
                            ui.Dialog(app.UIFigure, "warning", warningMessages);
                            return

                        otherwise % 'Preliminar'
                            warningMessages = [warningMessages, '<br><br>Deseja ignorar esse alerta, gerando a versão PRÉVIA do relatório?'];
                            userSelection   = ui.Dialog(app.UIFigure, 'uiconfirm', warningMessages, {'Sim', 'Não'}, 2, 2);
                            if userSelection == "Não"
                                return
                            end
                    end
                end
                % </VALIDAÇÕES>

                % <PROCESSO>
                app.progressDialog.Visible = 'visible';

                try
                    reportSettings = struct('system',        app.reportSystem.Value, ...
                                            'unit',          app.reportUnit.Value, ...
                                            'issue',         app.reportIssue.Value, ...
                                            'model',         app.reportModelName.Value, ...
                                            'reportVersion', app.reportVersion.Value);
                    reportLibConnection.Controller.Run(app, app.projectData, app.measData(indexes), reportSettings, app.mainApp.General)
                catch ME
                    ui.Dialog(app.UIFigure, 'error', getReport(ME));
                end

                updateToolbar(app)

                app.progressDialog.Visible = 'hidden';
                % </PROCESSO>
            end

        end

        % Image clicked function: tool_UploadFinalFile
        function Toolbar_UploadFinalFileImageClicked(app, event)
            
            % <VALIDAÇÕES>
            context = app.Context;
            lastHTMLDocFullPath = getGeneratedDocumentFileName(app.projectData, '.html', context);

            msg = '';
            if isempty(lastHTMLDocFullPath)
                msg = 'A versão definitiva do relatório ainda não foi gerada.';
            elseif ~isfile(lastHTMLDocFullPath)
                msg = sprintf('O arquivo "%s" não foi encontrado.', lastHTMLDocFullPath);
            elseif ~isfolder(app.mainApp.General.fileFolder.DataHub_POST)
                msg = 'Pendente mapear pasta do Sharepoint';
            elseif ~report_checkEFiscalizaIssueId(app.mainApp, app.projectData.modules.(context).ui.issue)
                msg = sprintf('O número da inspeção "%.0f" é inválido.', app.projectData.modules.(context).ui.issue);
            elseif isempty(app.projectData.modules.(context).ui.system)
                msg = 'Ambiente do eFiscaliza precisa ser selecionado.';
            elseif isempty(app.projectData.modules.(context).ui.unit)
                msg = 'Unidade geradora do documento precisa ser selecionada.';
            end

            if ~isempty(msg)
                ui.Dialog(app.UIFigure, 'warning', msg);
                return
            end
            % </VALIDAÇÕES>

            % <PROCESSO>
            if isempty(app.mainApp.eFiscalizaObj)
                dialogBox    = struct('id', 'login',    'label', 'Usuário: ', 'type', 'text');
                dialogBox(2) = struct('id', 'password', 'label', 'Senha: ',   'type', 'password');
                sendEventToHTMLSource(app.jsBackDoor, 'customForm', struct('UUID', 'eFiscalizaSignInPage', 'Fields', dialogBox, 'Context', context))
            else
                report_uploadInfoController(app.mainApp, [], 'uploadDocument', context)
            end
            % </PROCESSO>

        end

        % Selection change function: SubTabGroup
        function SubTabGroupSelectionChanged(app, event)

            [~, tabIndex] = ismember(app.SubTabGroup.SelectedTab, app.SubTabGroup.Children);
            applyJSCustomizations(app, tabIndex)

        end

        % Image clicked function: axesTool_RegionZoom, axesTool_RestoreView
        function AxesToolbarImageClicked(app, event)
            
            switch event.Source
                case app.axesTool_RestoreView
                    geolimits(app.UIAxes, app.restoreView(1).xLim, app.restoreView(1).yLim)

                case app.axesTool_RegionZoom
                    plot.axes.Interactivity.GeographicRegionZoomInteraction(app.UIAxes, app.axesTool_RegionZoom)
            end

        end

        % Selection changed function: TreePoints
        function TreePointsSelectionChanged(app, event)
            
            idxPoint = [];
            if ~isempty(app.TreePoints.SelectedNodes)
                idxPoint = app.TreePoints.SelectedNodes.NodeData;
            end

            % Nessa operação, o foco sai de app.TreePoints e vai pra app.UITable.
            previousSelection = app.UITable.Selection;
            app.UITable.Selection = idxPoint;
            drawnow            

            UITableSelectionChanged(app, struct('PreviousSelection', previousSelection, 'Selection', idxPoint))            
            focus(app.TreePoints)

        end

        % Selection changed function: TreeFileLocations
        function TreeFileLocationsSelectionChanged(app, event)
            
            if isempty(event.SelectedNodes)
                app.TreeFileLocations.SelectedNodes = event.PreviousSelectedNodes;
                return
            end

            Analysis(app)

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
            if ~ismember(event.EditData, app.mainApp.General.context.EXTERNALREQUEST.noMeasurementReasons)
                app.UITable.Data.("Justificativa") = app.projectData.modules.EXTERNALREQUEST.pointsTable.("Justificativa");
                return
            end

            idxPoint = event.Indices(1);
            app.projectData.modules.EXTERNALREQUEST.pointsTable.("Justificativa")(idxPoint) = event.NewData;
            
            layout_TableStyle(app)

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            
            if exist('event', 'var') && ~isempty(event.PreviousSelection) && isequal(event.Selection, event.PreviousSelection)
                return
            end

            if isempty(event.Selection)
                app.UITable.ContextMenu = [];
                app.TreePoints.SelectedNodes = [];
            else
                if isempty(app.UITable.ContextMenu)
                    app.UITable.ContextMenu = app.ContextMenu;
                end
                app.TreePoints.SelectedNodes = app.TreePoints.Children(event.Selection);
            end

            plot_SelectedPoint(app)

        end

        % Value changed function: NewPointType
        function NewPointTypeValueChanged(app, event)
            
            switch app.NewPointType.Value
                case 'Estação'
                    app.NewPointStation.Enable = 1;
                    set(app.NewPointLatitude,  'Value', [], 'Enable', 0)
                    set(app.NewPointLongitude, 'Value', [], 'Enable', 0)

                otherwise
                    set(app.NewPointStation, 'Value', -1, 'Enable', 0)
                    app.NewPointLatitude.Enable  = 1;
                    app.NewPointLongitude.Enable = 1;
            end

        end

        % Value changed function: NewPointStation
        function NewPointStationValueChanged(app, event)
            
            idxRFDataHub = find(app.mainApp.rfDataHub.Station == app.NewPointStation.Value, 1);

            if ~isempty(idxRFDataHub)
                latStation  = round(app.mainApp.rfDataHub.Latitude(idxRFDataHub), 6);
                longStation = round(app.mainApp.rfDataHub.Longitude(idxRFDataHub), 6);

                set(app.NewPointLatitude,  'Value', latStation,  'Enable', 1)
                set(app.NewPointLongitude, 'Value', longStation, 'Enable', 1)

            else
                set(app.NewPointLatitude,  'Value', [], 'Enable', 1)
                set(app.NewPointLongitude, 'Value', [], 'Enable', 1)
            end

            focus(app.NewPointLatitude);

        end

        % Value changed function: NewPointLatitude
        function NewPointLatitudeValueChanged(app, event)
            
            focus(app.NewPointLongitude)
            
        end

        % Image clicked function: AddNewPointCancel, AddNewPointConfirm, 
        % ...and 1 other component
        function AddNewPointEditionModeCallbacks(app, event)
            
            switch event.Source
                case app.AddNewPointMode
                    app.AddNewPointMode.UserData.status = ~app.AddNewPointMode.UserData.status;
                    
                    if app.AddNewPointMode.UserData.status
                        layout_newPointPanel(app, 'on')
                    else
                        layout_newPointPanel(app, 'off')
                    end

                case app.AddNewPointConfirm
                    % VALIDAÇÃO
                    msgWarning = '';
                    if isempty(app.NewPointType.Value) || isempty(app.NewPointLatitude.Value) || isempty(app.NewPointLongitude.Value)
                        msgWarning = 'Um novo ponto crítico somente poderá ser incluído se definido o seu tipo e coordenadas geográficas.';

                    elseif app.NewPointLatitude.Value  < app.mainApp.General.context.EXTERNALREQUEST.locationLimits.lat.min || ...
                           app.NewPointLatitude.Value  > app.mainApp.General.context.EXTERNALREQUEST.locationLimits.lat.max || ...
                           app.NewPointLongitude.Value < app.mainApp.General.context.EXTERNALREQUEST.locationLimits.lng.min || ...
                           app.NewPointLongitude.Value > app.mainApp.General.context.EXTERNALREQUEST.locationLimits.lng.max
                        
                        msgWarning = sprintf([ ...
                            'Um novo ponto crítico somente poderá ser incluído ' ...
                            'se definido dentro dos limites:<br>' ...
                            '• Latitude: %.2fº a %.2fº<br>' ...
                            '• Longitude: %.2fº a %.2fº' ...
                        ], app.mainApp.General.context.EXTERNALREQUEST.locationLimits.lat.min, app.mainApp.General.context.EXTERNALREQUEST.locationLimits.lat.max, app.mainApp.General.context.EXTERNALREQUEST.locationLimits.lng.min, app.mainApp.General.context.EXTERNALREQUEST.locationLimits.lng.max);

                    end

                    if ~isempty(msgWarning)
                        ui.Dialog(app.UIFigure, 'warning', msgWarning);
                        return
                    end
        
                    % CRIA ID DO NOVO PONTO
                    switch app.NewPointType.Value
                        case 'Estação'
                            ID = num2str(app.NewPointStation.Value);
                        otherwise
                            ID = app.NewPointType.Value;
                    end
                    ID = sprintf('%s @ (%.6f, %.6f)', ID, app.NewPointLatitude.Value, app.NewPointLongitude.Value);
        
                    % VERIFICA SE ESSE ID JÁ TINHA SIDO INCLUÍDO
                    if any(strcmp(app.projectData.modules.EXTERNALREQUEST.pointsTable_I.ID, ID))
                        ui.Dialog(app.UIFigure, 'warning', 'Registro já consta na lista de pontos sob análise.');
                        return
                    end

                    columsn2Fill = {'ID', 'Type', 'Station', 'Latitude', 'Longitude', 'Description', 'Justificativa', 'AnalysisFlag'};        
                    app.projectData.modules.EXTERNALREQUEST.pointsTable_I(end+1, columsn2Fill) = {ID,                            ...
                                                                                                app.NewPointType.Value,        ...
                                                                                                app.NewPointStation.Value,     ...
                                                                                                app.NewPointLatitude.Value,    ...
                                                                                                app.NewPointLongitude.Value,   ...
                                                                                                app.NewPointDescription.Value, ...
                                                                                                categorical("-"),             ...
                                                                                                false};
                    app.projectData.modules.EXTERNALREQUEST.pointsTable_I = model.projectLib.generateHash(app.projectData.modules.EXTERNALREQUEST.pointsTable_I, 'pointsTable');
                    app.projectData.modules.EXTERNALREQUEST.pointsTable(end+1, :) = app.projectData.modules.EXTERNALREQUEST.pointsTable_I(end, :);
                    updateAnalysis(app.projectData, app.measData, app.mainApp.General, 'ExternalRequest:PointsTableChanged');
        
                    % ATUALIZA ÁRVORE DE PONTOS CRÍTICOS
                    TreePointsBuilding(app)
        
                    % ANÁLISA DOS PONTOS CRÍTICOS, ATUALIZANDO TABELA E PLOT
                    Analysis(app)

                    % DESABILITA MODO DE INCLUSÃO DE PONTO
                    layout_newPointPanel(app, 'off')

                case app.AddNewPointCancel
                    layout_newPointPanel(app, 'off')
            end

        end

        % Menu selected function: DeletePoint
        function DeleteSelectedPoint(app, event)
            
            idxPoint = [];

            if ismember(app.TreePoints, [event.ContextObject, event.ContextObject.Parent])
                if ~isempty(app.TreePoints.SelectedNodes)
                    idxPoint = app.TreePoints.SelectedNodes.NodeData;
                end
            elseif event.ContextObject == app.UITable
                idxPoint = app.UITable.Selection;
            end

            if ~isempty(idxPoint)
                app.projectData.modules.EXTERNALREQUEST.pointsTable_I(idxPoint, :) = [];
                app.projectData.modules.EXTERNALREQUEST.pointsTable(idxPoint, :)   = [];
                updateAnalysis(app.projectData, app.measData, app.mainApp.General, 'ExternalRequest:PointsTableChanged');

                % ATUALIZA ÁRVORE DE PONTOS CRÍTICOS
                TreePointsBuilding(app)
    
                % ANÁLISA DOS PONTOS CRÍTICOS, ATUALIZANDO TABELA E PLOT
                Analysis(app)
            end

        end

        % Value changed function: reportModelName
        function reportModelNameValueChanged(app, event)
            
            updateToolbar(app)

        end

        % Value changed function: reportIssue, reportSystem, reportUnit
        function reportInfoValueChanged(app, event)
            
            context = app.Context;

            switch event.Source
                case app.reportSystem
                    updateUiInfo(app.projectData, context, 'system', app.reportSystem.Value)
                case app.reportUnit
                    updateUiInfo(app.projectData, context, 'unit',   app.reportUnit.Value)
                case app.reportIssue
                    updateUiInfo(app.projectData, context, 'issue',  app.reportIssue.Value)
            end

        end

        % Image clicked function: tool_OpenPopupProject
        function tool_OpenPopupProjectImageClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorRNI';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.tool_PanelVisibility = uiimage(app.Toolbar);
            app.tool_PanelVisibility.ImageClickedFcn = createCallbackFcn(app, @Toolbar_InteractionImageClicked, true);
            app.tool_PanelVisibility.ImageSource = 'layout-sidebar-left.svg';
            app.tool_Separator1 = uiimage(app.Toolbar);
            app.tool_Separator1.Enable = 'off';
            app.tool_Separator1.ImageSource = 'LineV.svg';
            app.tool_TableVisibility = uiimage(app.Toolbar);
            app.tool_TableVisibility.ImageClickedFcn = createCallbackFcn(app, @Toolbar_InteractionImageClicked, true);
            app.tool_TableVisibility.ImageSource = 'View_16.png';
            app.tool_ExportFiles = uiimage(app.Toolbar);
            app.tool_ExportFiles.ImageClickedFcn = createCallbackFcn(app, @Toolbar_ExportTableAsExcelSheet, true);
            app.tool_ExportFiles.Enable = 'off';
            app.tool_ExportFiles.ImageSource = 'Export_16.png';
            app.tool_Separator2 = uiimage(app.Toolbar);
            app.tool_Separator2.Enable = 'off';
            app.tool_Separator2.ImageSource = 'LineV.svg';
            app.tool_PeakIcon = uiimage(app.Toolbar);
            app.tool_PeakIcon.ImageClickedFcn = createCallbackFcn(app, @Toolbar_InteractionImageClicked, true);
            app.tool_PeakIcon.Enable = 'off';
            app.tool_PeakIcon.ImageSource = 'Detection_18.png';
            app.tool_PeakLabel = uilabel(app.Toolbar);
            app.tool_PeakLabel.Visible = 'off';
            app.tool_PeakLabel.Text = {'5.3 V/m'; '(-12.354321, -38.123456)'};
            app.tool_OpenPopupProject = uiimage(app.Toolbar);
            app.tool_OpenPopupProject.ImageClickedFcn = createCallbackFcn(app, @tool_OpenPopupProjectImageClicked, true);
            app.tool_OpenPopupProject.ImageSource = 'organization-20px-black.svg';
            app.tool_GenerateReport = uiimage(app.Toolbar);
            app.tool_GenerateReport.ImageClickedFcn = createCallbackFcn(app, @Toolbar_GenerateReportImageClicked, true);
            app.tool_GenerateReport.Enable = 'off';
            app.tool_GenerateReport.ImageSource = 'Publish_HTML_16.png';
            app.tool_UploadFinalFile = uiimage(app.Toolbar);
            app.tool_UploadFinalFile.ImageClickedFcn = createCallbackFcn(app, @Toolbar_UploadFinalFileImageClicked, true);
            app.tool_UploadFinalFile.Enable = 'off';
            app.tool_UploadFinalFile.ImageSource = 'up-20px.png';
            app.Document = uigridlayout(app.GridLayout);
            app.UITable = uitable(app.Document);
            app.UITable.ColumnName = {'ID'; 'DESCRIÇÃO'; 'Qtd.|Medidas'; 'Qtd.|> 14 V/m'; 'Dmin|(km)'; 'Emin|(V/m)'; 'Emean|(V/m)'; 'Emax|(V/m)'; 'JUSTIFICATIVA'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false false false false false false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.plotPanel = uipanel(app.Document);
            app.AxesToolbar = uigridlayout(app.Document);
            app.axesTool_RestoreView = uiimage(app.AxesToolbar);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @AxesToolbarImageClicked, true);
            app.axesTool_RestoreView.ImageSource = 'Home_18.png';
            app.axesTool_RegionZoom = uiimage(app.AxesToolbar);
            app.axesTool_RegionZoom.ImageClickedFcn = createCallbackFcn(app, @AxesToolbarImageClicked, true);
            app.axesTool_RegionZoom.ImageSource = 'ZoomRegion_20.png';
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.dockModule_Undock = uiimage(app.DockModule);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            app.dockModule_Close = uiimage(app.DockModule);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            app.SubTabGroup = uitabgroup(app.GridLayout);
            app.SubTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SubTabGroupSelectionChanged, true);
            app.SubTab1 = uitab(app.SubTabGroup);
            app.SubTab1.Title = 'DEMANDA EXTERNA';
            app.SubGrid1 = uigridlayout(app.SubTab1);
            app.TreeFileLocationsLabel = uilabel(app.SubGrid1);
            app.TreeFileLocationsLabel.Interpreter = 'html';
            app.TreeFileLocationsLabel.Text = {'LOCALIDADES DE AGRUPAMENTO:'; '<font style="color: gray; font-size: 9px;">(relacionadas aos arquivos de medição)</font>'};
            app.TreeFileLocations = uitree(app.SubGrid1);
            app.TreeFileLocations.SelectionChangedFcn = createCallbackFcn(app, @TreeFileLocationsSelectionChanged, true);
            app.TreePointsLabel = uilabel(app.SubGrid1);
            app.TreePointsLabel.Interpreter = 'html';
            app.TreePointsLabel.Text = {'PONTOS CRÍTICOS SOB ANÁLISE:'; '<font style="color: gray; font-size: 9px;">(relacionado àquilo que fora pedido pelo demandante)</font>'};
            app.AddNewPointMode = uiimage(app.SubGrid1);
            app.AddNewPointMode.ImageClickedFcn = createCallbackFcn(app, @AddNewPointEditionModeCallbacks, true);
            app.AddNewPointMode.ImageSource = 'addFiles_32.png';
            app.AddNewPointConfirm = uiimage(app.SubGrid1);
            app.AddNewPointConfirm.ImageClickedFcn = createCallbackFcn(app, @AddNewPointEditionModeCallbacks, true);
            app.AddNewPointConfirm.ImageSource = 'Ok_32Green.png';
            app.AddNewPointCancel = uiimage(app.SubGrid1);
            app.AddNewPointCancel.ImageClickedFcn = createCallbackFcn(app, @AddNewPointEditionModeCallbacks, true);
            app.AddNewPointCancel.ImageSource = 'Delete_32Red.png';
            app.AddNewPointPanel = uipanel(app.SubGrid1);
            app.AddNewPointGrid = uigridlayout(app.AddNewPointPanel);
            app.NewPointTypeLabel = uilabel(app.AddNewPointGrid);
            app.NewPointTypeLabel.Text = 'Tipo:';
            app.NewPointType = uidropdown(app.AddNewPointGrid);
            app.NewPointType.Items = {''};
            app.NewPointType.ValueChangedFcn = createCallbackFcn(app, @NewPointTypeValueChanged, true);
            app.NewPointType.Value = '';
            app.NewPointStationLabel = uilabel(app.AddNewPointGrid);
            app.NewPointStationLabel.Text = 'Estação:';
            app.NewPointStation = uieditfield(app.AddNewPointGrid, 'numeric');
            app.NewPointStation.Limits = [-1 Inf];
            app.NewPointStation.RoundFractionalValues = 'on';
            app.NewPointStation.ValueDisplayFormat = '%d';
            app.NewPointStation.ValueChangedFcn = createCallbackFcn(app, @NewPointStationValueChanged, true);
            app.NewPointStation.Enable = 'off';
            app.NewPointStation.Value = -1;
            app.NewPointLatitudeLabel = uilabel(app.AddNewPointGrid);
            app.NewPointLatitudeLabel.Text = 'Latitude:';
            app.NewPointLatitude = uieditfield(app.AddNewPointGrid, 'numeric');
            app.NewPointLatitude.Limits = [-90 90];
            app.NewPointLatitude.ValueDisplayFormat = '%.6f';
            app.NewPointLatitude.AllowEmpty = 'on';
            app.NewPointLatitude.ValueChangedFcn = createCallbackFcn(app, @NewPointLatitudeValueChanged, true);
            app.NewPointLatitude.Value = [];
            app.NewPointLongitudeLabel = uilabel(app.AddNewPointGrid);
            app.NewPointLongitudeLabel.Text = 'Longitude:';
            app.NewPointLongitude = uieditfield(app.AddNewPointGrid, 'numeric');
            app.NewPointLongitude.Limits = [-180 180];
            app.NewPointLongitude.ValueDisplayFormat = '%.6f';
            app.NewPointLongitude.AllowEmpty = 'on';
            app.NewPointLongitude.Value = [];
            app.NewPointDescriptionLabel = uilabel(app.AddNewPointGrid);
            app.NewPointDescriptionLabel.Text = 'Descrição:';
            app.NewPointDescription = uieditfield(app.AddNewPointGrid, 'text');
            app.TreePoints = uitree(app.SubGrid1);
            app.TreePoints.SelectionChangedFcn = createCallbackFcn(app, @TreePointsSelectionChanged, true);
            app.SubTab2 = uitab(app.SubTabGroup);
            app.SubTab2.Title = 'PROJETO';
            app.SubGrid2 = uigridlayout(app.SubTab2);
            app.eFiscalizaLabel = uilabel(app.SubGrid2);
            app.eFiscalizaLabel.Text = 'eFISCALIZA';
            app.eFiscalizaPanel = uipanel(app.SubGrid2);
            app.eFiscalizaGrid = uigridlayout(app.eFiscalizaPanel);
            app.reportSystemLabel = uilabel(app.eFiscalizaGrid);
            app.reportSystemLabel.Text = 'Sistema:';
            app.reportSystem = uidropdown(app.eFiscalizaGrid);
            app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS'};
            app.reportSystem.ValueChangedFcn = createCallbackFcn(app, @reportInfoValueChanged, true);
            app.reportSystem.Value = 'eFiscaliza';
            app.reportUnitLabel = uilabel(app.eFiscalizaGrid);
            app.reportUnitLabel.Text = 'Unidade responsável:';
            app.reportUnit = uidropdown(app.eFiscalizaGrid);
            app.reportUnit.Items = {};
            app.reportUnit.ValueChangedFcn = createCallbackFcn(app, @reportInfoValueChanged, true);
            app.reportUnit.Value = {};
            app.reportIssueLabel = uilabel(app.eFiscalizaGrid);
            app.reportIssueLabel.Text = {'Atividade de inspeção:'; '(# ID)'};
            app.reportIssue = uieditfield(app.eFiscalizaGrid, 'numeric');
            app.reportIssue.Limits = [-1 Inf];
            app.reportIssue.RoundFractionalValues = 'on';
            app.reportIssue.ValueDisplayFormat = '%d';
            app.reportIssue.ValueChangedFcn = createCallbackFcn(app, @reportInfoValueChanged, true);
            app.reportIssue.Value = -1;
            app.reportLabel = uilabel(app.SubGrid2);
            app.reportLabel.Text = 'RELATÓRIO';
            app.reportPanel = uipanel(app.SubGrid2);
            app.reportGrid = uigridlayout(app.reportPanel);
            app.reportModelNameLabel = uilabel(app.reportGrid);
            app.reportModelNameLabel.Text = 'Modelo (.json):';
            app.reportModelName = uidropdown(app.reportGrid);
            app.reportModelName.Items = {''};
            app.reportModelName.ValueChangedFcn = createCallbackFcn(app, @reportModelNameValueChanged, true);
            app.reportModelName.Value = '';
            app.reportVersionLabel = uilabel(app.reportGrid);
            app.reportVersionLabel.Text = 'Versão do relatório:';
            app.reportVersion = uidropdown(app.reportGrid);
            app.reportVersion.Items = {'Preliminar', 'Definitiva'};
            app.reportVersion.Value = 'Preliminar';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.Tag = 'auxApp.winExternalRequest';
            app.DeletePoint = uimenu(app.ContextMenu);
            app.DeletePoint.MenuSelectedFcn = createCallbackFcn(app, @DeleteSelectedPoint, true);
            app.DeletePoint.Text = '❌ Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winExternalRequest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end