classdef winMonitoringPlan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        SubTabGroup                 matlab.ui.container.TabGroup
        SubTab1                     matlab.ui.container.Tab
        SubGrid1                    matlab.ui.container.GridLayout
        Card4_stationsOutRoute      matlab.ui.control.Label
        Card3_stationsOnRoute       matlab.ui.control.Label
        Card2_numberOfRiskStations  matlab.ui.control.Label
        Card1_numberOfStations      matlab.ui.control.Label
        LocationList                matlab.ui.control.TextArea
        LocationListEdit            matlab.ui.control.Image
        LocationListLabel           matlab.ui.control.Label
        TreeFileLocations           matlab.ui.container.Tree
        config_geoAxesLabel_2       matlab.ui.control.Label
        SubTab2                     matlab.ui.container.Tab
        SubGrid2                    matlab.ui.container.GridLayout
        reportPanel                 matlab.ui.container.Panel
        reportGrid                  matlab.ui.container.GridLayout
        reportVersion               matlab.ui.control.DropDown
        reportVersionLabel          matlab.ui.control.Label
        reportModelName             matlab.ui.control.DropDown
        reportModelNameLabel        matlab.ui.control.Label
        reportLabel                 matlab.ui.control.Label
        eFiscalizaPanel             matlab.ui.container.Panel
        eFiscalizaGrid              matlab.ui.container.GridLayout
        reportIssue                 matlab.ui.control.NumericEditField
        reportIssueLabel            matlab.ui.control.Label
        reportUnit                  matlab.ui.control.DropDown
        reportUnitLabel             matlab.ui.control.Label
        reportSystem                matlab.ui.control.DropDown
        reportSystemLabel           matlab.ui.control.Label
        eFiscalizaLabel             matlab.ui.control.Label
        DockModule                  matlab.ui.container.GridLayout
        dockModule_Close            matlab.ui.control.Image
        dockModule_Undock           matlab.ui.control.Image
        Document                    matlab.ui.container.GridLayout
        AxesToolbar                 matlab.ui.container.GridLayout
        axesTool_RegionZoom         matlab.ui.control.Image
        axesTool_RestoreView        matlab.ui.control.Image
        plotPanel                   matlab.ui.container.Panel
        UITable                     matlab.ui.control.Table
        Toolbar                     matlab.ui.container.GridLayout
        tool_UploadFinalFile        matlab.ui.control.Image
        tool_GenerateReport         matlab.ui.control.Image
        tool_OpenPopupProject       matlab.ui.control.Image
        tool_PeakLabel              matlab.ui.control.Label
        tool_PeakIcon               matlab.ui.control.Image
        tool_Separator2             matlab.ui.control.Image
        tool_ExportFiles            matlab.ui.control.Image
        tool_TableEdition           matlab.ui.control.Image
        tool_TableVisibility        matlab.ui.control.Image
        tool_Separator1             matlab.ui.control.Image
        tool_PanelVisibility        matlab.ui.control.Image
        ContextMenu                 matlab.ui.container.ContextMenu
        EditSelectedUITableRow      matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
        Context = 'MONITORINGPLAN'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
        popupContainer

        projectData
        measData
        measTable

        UIAxes
        restoreView = struct( ...
            'ID', {}, ...
            'xLim', {}, ...
            'yLim', {}, ...
            'cLim', {} ...
        )
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)

                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function ipcSecondaryMatlabCallsHandler(app, callingApp, eventName, varargin)
            try
                switch class(callingApp)
                    case {'winMonitorRNI', 'winMonitorRNI_exported'}
                        switch eventName
                            % auxApp.dockListOfLocation >> winMonitorRNI >> auxApp.winMonitoringPlan
                            % auxApp.dockStationInfo    >> winMonitorRNI >> auxApp.winMonitoringPlan
                            case 'closeFcnCallFromPopupApp'
                                app.popupContainer.Parent.Visible = 0;
                                
                            % winMonitorRNI >> auxApp.winMonitoringPlan
                            case {'onFileListAdded', 'onFileListRemoved', 'onFileListUnmerged', 'onFileListMerged'}
                                app.measData = app.mainApp.measData;
                                TreeFileLocationBuilding(app)

                                if ~isempty(app.measData)
                                    Analysis(app)
                                end

                            % auxApp.winConfig >> winMonitorRNI >> auxApp.winMonitoringPlan
                            case 'onAnalysisParameterChanged'
                                app.UITable.ColumnName{5} = sprintf('Qtd.|> %.0f V/m', app.mainApp.General.context.MONITORINGPLAN.electricFieldStrengthThreshold);
                                updateAnalysis(app.projectData, app.measData, app.mainApp.General, eventName);
                                Analysis(app)

                            case 'onAxesParameterChanged'
                                if ~isequal(app.UIAxes.Basemap, app.mainApp.General.plot.geographicAxes.basemap)
                                    app.UIAxes.Basemap = app.mainApp.General.plot.geographicAxes.basemap;
                                    
                                    switch app.mainApp.General.plot.geographicAxes.basemap
                                        case {'darkwater', 'none'}
                                            app.UIAxes.Grid = 'on';
                                        otherwise
                                            app.UIAxes.Grid = 'off';
                                    end
                                end

                                plot.axes.Colormap(app.UIAxes, app.mainApp.General.plot.geographicAxes.colormap)
                                plot.axes.Colorbar(app.UIAxes, app.mainApp.General.plot.geographicAxes.colorbar)

                            case 'onPlotParameterChanged'
                                Analysis(app)

                            % auxApp.dockStationInfo >> winMonitorRNI >> auxApp.winMonitoringPlan
                            case 'onStationInfoChanged'
                                newReason      = varargin{1};
                                newObservation = varargin{2};

                                [idxStation, idxRow] = selectedStation(app);
                                app.projectData.modules.MONITORINGPLAN.stationTable.("Justificativa")(idxStation) = newReason;
                                app.projectData.modules.MONITORINGPLAN.stationTable.("Observações"){idxStation}   = newObservation;
                                updateAnnotationTable(app.projectData, app.Context,  'stationTable', 'save')

                                app.UITable.Data.("Justificativa")(idxRow) = newReason;
                                layout_TableStyle(app)

                            case 'onStationCoordinatesEdited'
                                newLatitude  = varargin{1};
                                newLongitude = varargin{2};

                                idxStation   = selectedStation(app);
                                app.projectData.modules.MONITORINGPLAN.stationTable.("Latitude")(idxStation)  = newLatitude;
                                app.projectData.modules.MONITORINGPLAN.stationTable.("Longitude")(idxStation) = newLongitude;
                                updateAnalysis(app.projectData, app.measData, app.mainApp.General, eventName)

                                Analysis(app)

                            case 'onStationSelectionChanged'
                                newRowSelection = varargin{1};
                                app.UITable.Selection = newRowSelection;
                                scroll(app.UITable, 'row', newRowSelection)

                                UITableSelectionChanged(app, struct('PreviousSelection', [], 'Selection', newRowSelection))

                            % auxApp.dockListOfLocation >> winMonitorRNI >> auxApp.winMonitoringPlan
                            case 'onLocationListModeChanged'
                                updateAnalysis(app.projectData, app.measData, app.mainApp.General, eventName)
                                Analysis(app)

                            otherwise
                                error('model:winMonitoringPlan:UnexpectedCall', 'Unexpected call "%s"', eventName)
                        end
    
                    otherwise
                        error('model:winMonitoringPlan:UnexpectedCaller', 'Unexpected caller "%s"', class(callingApp))
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;

            switch tabIndex
                case 1
                    appName = class(app);
                    elToModify = {
                        app.AxesToolbar;
                        app.LocationListEdit;
                        app.tool_PanelVisibility;
                        app.tool_TableVisibility;
                        app.tool_TableEdition;
                        app.tool_ExportFiles;
                        app.tool_PeakIcon;
                        app.tool_OpenPopupProject;
                        app.tool_GenerateReport;
                        app.tool_UploadFinalFile;
                        app.dockModule_Undock;
                        app.dockModule_Close
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.AxesToolbar.UserData.id,    'styleImportant', struct('borderTopLeftRadius', '0', 'borderTopRightRadius', '0')), ...
                            struct('appName', appName, 'dataTag', app.LocationListEdit.UserData.id,      'tooltip', struct('defaultPosition', 'top',    'textContent', 'Edita lista de localidades sob análise')), ...
                            struct('appName', appName, 'dataTag', app.tool_PanelVisibility.UserData.id,  'tooltip', struct('defaultPosition', 'top',    'textContent', 'Alterna visibilidade do painel')), ...
                            struct('appName', appName, 'dataTag', app.tool_TableVisibility.UserData.id,  'tooltip', struct('defaultPosition', 'top',    'textContent', 'Alterna entre três layouts do conjunto plot+tabela<br>(apenas plot, apenas tabela ou plot+tabela)')), ...
                            struct('appName', appName, 'dataTag', app.tool_TableEdition.UserData.id,     'tooltip', struct('defaultPosition', 'top',    'textContent', 'Edita, em formulário, as informações do registro selecionado na tabela')), ...
                            struct('appName', appName, 'dataTag', app.tool_ExportFiles.UserData.id,      'tooltip', struct('defaultPosition', 'top',    'textContent', 'Exporta análise (.xlsx, .kml)')), ...
                            struct('appName', appName, 'dataTag', app.tool_PeakIcon.UserData.id,         'tooltip', struct('defaultPosition', 'top',    'textContent', 'Aplica zoom em torno do local de valor máximo')), ...
                            struct('appName', appName, 'dataTag', app.tool_OpenPopupProject.UserData.id, 'tooltip', struct('defaultPosition', 'top',    'textContent', 'Edita informações do projeto<br>(fiscalizada, arquivo de backup etc)')), ...
                            struct('appName', appName, 'dataTag', app.tool_GenerateReport.UserData.id,   'tooltip', struct('defaultPosition', 'top',    'textContent', 'Gera relatório')), ...
                            struct('appName', appName, 'dataTag', app.tool_UploadFinalFile.UserData.id,  'tooltip', struct('defaultPosition', 'top',    'textContent', 'Upload relatório')), ...
                            struct('appName', appName, 'dataTag', app.dockModule_Undock.UserData.id,     'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Reabre módulo em outra janela')), ...
                            struct('appName', appName, 'dataTag', app.dockModule_Close.UserData.id,      'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Fecha módulo')) ...
                        });
                    catch
                    end

                case 2
                    context = app.Context;

                    if isempty(app.projectData.modules.(context).ui.system) && ~isequal(app.projectData.modules.(context).ui.system, app.mainApp.General.reportLib.system)
                        app.projectData.modules.(context).ui.system = app.mainApp.General.reportLib.system;
                    end
                    
                    if isempty(app.projectData.modules.(context).ui.unit)   && ~isequal(app.projectData.modules.(context).ui.unit,   app.mainApp.General.reportLib.unit)
                        app.projectData.modules.(context).ui.unit   = app.mainApp.General.reportLib.unit;
                    end

                    if ~isdeployed()
                        app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS', 'eFiscaliza HM', 'eFiscaliza DS'};
                    end
                    app.reportSystem.Value     = app.projectData.modules.(context).ui.system;

                    set(app.reportUnit, 'Items', app.mainApp.General.eFiscaliza.defaultValues.unit, ...
                                        'Value', app.projectData.modules.(context).ui.unit)
                    app.reportIssue.Value      = app.projectData.modules.(context).ui.issue;
                    app.reportModelName.Items  = app.projectData.modules.(context).ui.templates;
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            % ...
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable = 1;
            end

            if app.mainApp.General.context.MONITORINGPLAN.electricFieldStrengthThreshold ~= 14
                app.UITable.ColumnName{5} = sprintf('Qtd.|> %.0f V/m', app.mainApp.General.context.MONITORINGPLAN.electricFieldStrengthThreshold);
            end

            [app.UIAxes, app.restoreView] = plot.axesCreationController(app.plotPanel, app.mainApp.General);
            
            TreeFileLocationBuilding(app)
            if ~isempty(app.measData)
                Analysis(app)
            end
                        
            app.tool_TableVisibility.UserData = 1;
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            % ...
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function Analysis(app)
            app.progressDialog.Visible = 'visible';

            % Identifica arquivos selecionados pelo usuário, atualizando a
            % tabela de referência para o plot (app.measTable) e a relação
            % de localidades selecionadas na GUI (em app.projectData).
            [idxFile, selectedFileLocations] = FileIndex(app);
            app.measTable = createMeasTable(app.measData(idxFile));

            updateSelectedListOfLocations(app.projectData, selectedFileLocations, app.Context)

            fullListOfLocation = getFullListOfLocation( ...
                app.projectData, ...
                app.measData(idxFile), ...
                app.mainApp.General.context.MONITORINGPLAN.maxMeasurementDistanceKm ...
            );

            idxStations = find(ismember(app.projectData.modules.MONITORINGPLAN.stationTable.Location, fullListOfLocation));
            initialSelection = updateTable(app, idxStations);
            layout_TableStyle(app)

            % Atualiza outros elementos da GUI, inclusive painel com quantitativo 
            % de estações.
            updatePanel(app, fullListOfLocation, idxStations)
            updateToolbar(app)

            % Atualiza plot.
            plot_MeasuresAndPoints(app)

            if ~isempty(initialSelection)
                [~, idxRow] = ismember(initialSelection, app.UITable.UserData);
                if idxRow
                    app.UITable.Selection = idxRow;
                    UITableSelectionChanged(app, struct('PreviousSelection', [], 'Selection', idxRow))
                end
            end

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function [idxFile, selectedFileLocations] = FileIndex(app)
            if ~isempty(app.TreeFileLocations.SelectedNodes)
                selectedFileLocations = {app.TreeFileLocations.SelectedNodes.Text};
                idxFile = find(ismember({app.measData.Location}, selectedFileLocations));
            else
                selectedFileLocations = {};
                idxFile = [];
            end
        end

        %-----------------------------------------------------------------%
        function initialSelection = updateTable(app, idxStations)
            initialSelection = [];
            if ~isempty(app.UITable.Selection)
                initialSelection = app.UITable.UserData(app.UITable.Selection);
            end

            table2Render = app.projectData.modules.MONITORINGPLAN.stationTable(idxStations, {'Estação',               ...
                                                                                             'Location',              ...
                                                                                             'Serviço',               ...                                                                                      
                                                                                             'numberOfMeasures',      ...
                                                                                             'numberOfRiskMeasures',  ...
                                                                                             'minDistanceForMeasure', ...
                                                                                             'minFieldValue',         ...
                                                                                             'meanFieldValue',        ...
                                                                                             'maxFieldValue',         ...
                                                                                             'Justificativa'});
            set(app.UITable, 'Data', table2Render, 'UserData', idxStations, 'Selection', [])
        end

        %-----------------------------------------------------------------%
        function updatePanel(app, fullListOfLocation, idxStations)
            % Atualiza outros elementos da GUI, inclusive painel com quantitativo 
            % de estações.
            if ~isempty(fullListOfLocation)
                app.LocationList.Value = fullListOfLocation;
            else
                app.LocationList.Value = '';
            end
            
            nStations         = numel(idxStations);
            nRiskStations     = sum(app.UITable.Data.numberOfRiskMeasures > 0);
            nStationsOnRoute  = sum(app.UITable.Data.numberOfMeasures > 0);
            nStationsOutRoute = nStations - nStationsOnRoute;

            app.Card1_numberOfStations.Text     = sprintf('<p style="margin: 10 2 0 2px;"><font style="color: black;   font-size: 32px;">%d</font>\nESTAÇÕES INSTALADAS NAS LOCALIDADES SOB ANÁLISE</p>',                  nStations);
            app.Card2_numberOfRiskStations.Text = sprintf('<p style="margin: 10 2 0 2px;"><font style="color: #a2142f; font-size: 32px;">%d</font>\nESTAÇÕES NO ENTORNO DE REGISTROS DE NÍVEIS ACIMA DE %.0f V/m</p></p>', nRiskStations, app.mainApp.General.context.MONITORINGPLAN.electricFieldStrengthThreshold);
            app.Card3_stationsOnRoute .Text     = sprintf('<p style="margin: 10 2 0 2px;"><font style="color: black;   font-size: 32px;">%d</font>\nESTAÇÕES INSTALADAS NO ENTORNO DA ROTA</p>',                           nStationsOnRoute);
            app.Card4_stationsOutRoute.Text     = sprintf('<p style="margin: 10 2 0 2px;"><font style="color: #a2142f; font-size: 32px;">%d</font>\nESTAÇÕES INSTALADAS FORA DA ROTA</p>',                                 nStationsOutRoute);
        end

        %-----------------------------------------------------------------%
        function updateToolbar(app)
            measDataNonEmpty                = ~isempty(app.measData);
            measTableNonEmpty               = ~isempty(app.measTable);
            rowTableSelected                = ~isempty(app.UITable.Selection);
            reportModelSelected             = ~isempty(app.reportModelName.Value);
            reportFinalVersionGenerated     = ~isempty(app.projectData.modules.(app.Context).generatedFiles.lastHTMLDocFullPath);

            app.LocationListEdit.Enable     = measDataNonEmpty;
            app.tool_TableEdition.Enable    = measDataNonEmpty & rowTableSelected;
            app.tool_ExportFiles.Enable     = measDataNonEmpty;
            app.tool_GenerateReport.Enable  = measDataNonEmpty & reportModelSelected;
            app.tool_UploadFinalFile.Enable = reportFinalVersionGenerated;

            app.tool_PeakLabel.Visible      = measTableNonEmpty;
            app.tool_PeakIcon.Enable        = measTableNonEmpty;

            if measTableNonEmpty
                [~, maxIndex] = max(app.measTable.FieldValue);

                app.tool_PeakLabel.Text    = sprintf('%.2f V/m\n(%.6f, %.6f)', app.measTable.FieldValue(maxIndex), ...
                                                                               app.measTable.Latitude(maxIndex),   ...
                                                                               app.measTable.Longitude(maxIndex));
                app.tool_PeakIcon.UserData = struct('idxMax',    maxIndex,                         ...
                                                    'Latitude',  app.measTable.Latitude(maxIndex), ...
                                                    'Longitude', app.measTable.Longitude(maxIndex));
            end
        end

        %-----------------------------------------------------------------%
        function plot_RestartAxes(app)
            cla(app.UIAxes)
            geolimits(app.UIAxes, 'auto')
            app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');
        end

        %-----------------------------------------------------------------%
        function plot_MeasuresAndPoints(app)
            % prePlot
            plot_RestartAxes(app)

            % Measures
            if ~isempty(app.measTable)
                plot.draw.Measures(app.UIAxes, app.measTable, app.mainApp.General.context.MONITORINGPLAN.electricFieldStrengthThreshold, app.mainApp.General);

                % Abaixo estabelece como limites do eixo os limites atuais,
                % configurados automaticamente pelo MATLAB. Ao fazer isso,
                % contudo, esses limites serão orientados às medidas e não às
                % estações.
                plot_AxesDefaultLimits(app, 'measures')
            end

            % Stations/Points
            if ~isempty(app.UITable.Data)
                idxStations    = app.UITable.UserData;
                refPointsTable = app.projectData.modules.MONITORINGPLAN.stationTable(idxStations, :);

                plot.draw.Points(app.UIAxes, refPointsTable, 'Estações de referência PM-RNI', app.mainApp.General)
            end
            plot_AxesDefaultLimits(app, 'stations/points')
        end

        %-----------------------------------------------------------------%
        function plot_SelectedPoint(app)
            delete(findobj(app.UIAxes.Children, 'Tag', 'SelectedPoint'))

            if ~isempty(app.UITable.Selection)
                idxSelectedPoint   = app.UITable.UserData(app.UITable.Selection);
                selectedPointTable = app.projectData.modules.MONITORINGPLAN.stationTable(idxSelectedPoint, :);

                plot.draw.SelectedPoint(app.UIAxes, selectedPointTable, app.mainApp.General, class(app))
            end
        end

        %-----------------------------------------------------------------%
        function plot_AxesDefaultLimits(app, zoomOrientation)
            arguments
                app
                zoomOrientation {mustBeMember(zoomOrientation, {'stations/points', 'measures'})}
            end

            if strcmp(app.mainApp.General.plot.geographicAxes.zoomOrientation, zoomOrientation) || (isempty(app.measTable) && strcmp(app.mainApp.General.plot.geographicAxes.zoomOrientation, 'measures'))
                geolimits(app.UIAxes, app.UIAxes.LatitudeLimits, app.UIAxes.LongitudeLimits)
                app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');
            end
        end

        %-----------------------------------------------------------------%
        function [idxMissingInfo, idxManualEdition, idxRiskMeasures] = layout_searchUnexpectedTableValues(app, operationType)
            arguments
                app
                operationType char {mustBeMember(operationType, {'Todas', 'Selecionada'})} = 'Selecionada'
            end

            idxMissingInfo   = [];
            idxManualEdition = [];
            idxRiskMeasures  = [];

            switch operationType
                case 'Todas'
                    locations   = getFullListOfLocation(app.projectData, app.measData, app.projectData.modules.MONITORINGPLAN.distance_km);                        
                    idxStations = ismember(app.projectData.modules.MONITORINGPLAN.stationTable.Location, locations);
                case 'Selecionada'
                    idxStations = app.UITable.UserData;
            end

            relatedStationTable = app.projectData.modules.MONITORINGPLAN.stationTable(idxStations, :);

            if ~isempty(relatedStationTable)
                % "Registros anotados" por meio da inclusão de uma observação 
                % ou da edição das coordenadas geográficas da estação.
                addedObservationLogical = cellfun(@(x) ~isempty(x), relatedStationTable.("Observações"));
                editedLocationLogical   = (relatedStationTable.("Lat") ~= relatedStationTable.("Latitude")) | (relatedStationTable.("Long") ~= relatedStationTable.("Longitude"));                

                % Registros ainda pendentes de edição, seja por não ter sido 
                % especificada uma "Justificativa" válida (precisa ser diferente 
                % de "-" quando não identificada medição no entorno da estação) 
                % ou porque foi especificada uma válida, porém que demanda a 
                % inclusão de uma observação ou a edição das coordenadas da estação.
                reasonsThatRequireObservation = app.mainApp.General.context.MONITORINGPLAN.reasonsRequiringComment;
                reasonsThatRequireNewLocation = app.mainApp.General.context.MONITORINGPLAN.reasonsRequiringLocationUpdate;

                idxMissingInfo   = find(((relatedStationTable.numberOfMeasures == 0) & (relatedStationTable.("Justificativa") == "-"))             | ...
                                        (ismember(relatedStationTable.("Justificativa"), reasonsThatRequireObservation) & ~addedObservationLogical) | ...
                                        (ismember(relatedStationTable.("Justificativa"), reasonsThatRequireNewLocation) & ~editedLocationLogical));
                idxManualEdition = find(addedObservationLogical | editedLocationLogical);
                idxRiskMeasures  = find(relatedStationTable.numberOfRiskMeasures > 0);
            end
        end

        %-----------------------------------------------------------------%
        function layout_TableStyle(app)
            removeStyle(app.UITable)

            if ~isempty(app.UITable.Data)
                % Identifica estações que NÃO tiveram medições no seu entorno, 
                % apesar da rota englobar o município em que está instalada a
                % estação. Ou estações que apresentaram medições com níveis
                % acima de 14 V/m.
                [idxMissingInfo, idxManualEdition, idxRiskMeasures] = layout_searchUnexpectedTableValues(app);

                if ~isempty(idxMissingInfo)
                    columnIndex1 = find(ismember(app.UITable.Data.Properties.VariableNames, 'Justificativa'));
                    s1 = uistyle('BackgroundColor', '#c80b0f', 'FontColor', 'white');                
                    addStyle(app.UITable, s1, "cell", [idxMissingInfo, repmat(columnIndex1, numel(idxMissingInfo), 1)])
                end

                if ~isempty(idxManualEdition)
                    s2 = uistyle('Icon', 'Edit_32.png', 'IconAlignment', 'leftmargin');
                    addStyle(app.UITable, s2, "cell", [idxManualEdition, ones(numel(idxManualEdition), 1)])
                end

                if ~isempty(idxRiskMeasures)
                    columnIndex2 = find(ismember(app.UITable.Data.Properties.VariableNames, 'numberOfRiskMeasures'));

                    s3 = uistyle('BackgroundColor', '#c80b0f', 'FontColor', 'white');
                    addStyle(app.UITable, s3, "cell", [idxRiskMeasures, repmat(columnIndex2, numel(idxRiskMeasures), 1)])
                end
            end
        end

        %-----------------------------------------------------------------%
        function TreeFileLocationBuilding(app)
            if ~isempty(app.TreeFileLocations.Children)
                delete(app.TreeFileLocations.Children)
            end

            locationGroups = unique({app.measData.Location});
            selectedNodes = [];
            for ii = 1:numel(locationGroups)
                treeNode = uitreenode(app.TreeFileLocations, 'Text', locationGroups{ii});

                if strcmp(locationGroups{ii}, app.projectData.modules.MONITORINGPLAN.ui.selectedGroup)
                    selectedNodes = [selectedNodes; treeNode];
                end
            end

            measDataNonEmpty = ~isempty(app.measData);
            if measDataNonEmpty
                if isempty(selectedNodes)
                    selectedNodes = app.TreeFileLocations.Children(1);
                elseif ~isscalar(selectedNodes)
                    selectedNodes = selectedNodes(1);
                end
                app.TreeFileLocations.SelectedNodes = selectedNodes;

            else
                app.measTable = [];

                plot_RestartAxes(app)
                updateTable(app, []);
                updatePanel(app, {}, [])
            end

            updateToolbar(app)
        end

        %-----------------------------------------------------------------%
        function [idxStation, idxRow] = selectedStation(app)
            idxRow     = app.UITable.Selection;
            idxStation = app.UITable.UserData(idxRow);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)

            try
                app.projectData = mainApp.projectData;
                app.measData    = mainApp.measData;

                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', app.Context)
            delete(app)
            
        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function DockModuleGroup_ButtonPushed(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;
                    
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];

                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Image clicked function: tool_PanelVisibility, tool_PeakIcon, 
        % ...and 1 other component
        function Toolbar_InteractionImageClicked(app, event)
            
            switch event.Source
                case app.tool_PanelVisibility
                    if app.SubTabGroup.Visible
                        app.tool_PanelVisibility.ImageSource = 'layout-sidebar-left-off.svg';
                        app.SubTabGroup.Visible = 0;
                        app.Document.Layout.Column = [2 5];
                    else
                        app.tool_PanelVisibility.ImageSource = 'layout-sidebar-left.svg';
                        app.SubTabGroup.Visible = 1;
                        app.Document.Layout.Column = [4 5];
                    end

                case app.tool_TableVisibility
                    app.tool_TableVisibility.UserData = mod(app.tool_TableVisibility.UserData+1, 3);

                    switch app.tool_TableVisibility.UserData
                        case 0
                            app.UITable.Visible = 0;
                            app.Document.RowHeight = {24,'1x', 0, 0};
                        case 1
                            app.UITable.Visible = 1;
                            app.Document.RowHeight = {24, '1x', 10, 186};
                        case 2
                            app.UITable.Visible = 1;
                            app.Document.RowHeight = {0, 0, 0, '1x'};
                    end

                case app.tool_PeakIcon
                    if ~isempty(app.tool_PeakIcon.UserData)
                        ReferenceDistance_km = 1;
                        plot.zoom(app.UIAxes, app.tool_PeakIcon.UserData.Latitude, app.tool_PeakIcon.UserData.Longitude, ReferenceDistance_km)
                        plot.datatip.Create(app.UIAxes, 'Measures', app.tool_PeakIcon.UserData.idxMax)
                    end
            end

        end

        % Callback function: EditSelectedUITableRow, tool_TableEdition
        function Toolbar_OpenPopUpEditionMode(app, event)
            
            if ~isempty(app.UITable.Selection)
                ipcMainMatlabOpenPopupApp(app.mainApp, app, 'StationInfo', app.Context)
            end

        end

        % Image clicked function: tool_ExportFiles
        function Toolbar_ExportTableAsExcelSheet(app, event)
            
            context = app.Context;
            indexes = FileIndex(app);

            if ~isempty(indexes)
                % <VALIDAÇÕES>
                if numel(indexes) < numel(app.measData)
                    initialQuestion  = 'Deseja exportar arquivos de análise preliminar (.xlsx / .kml) que contemplem informações de TODAS as localidades de agrupamento, ou apenas da SELECIONADA?';
                    initialSelection = ui.Dialog(app.UIFigure, 'uiconfirm', initialQuestion, {'Todas', 'Selecionada', 'Cancelar'}, 1, 3);

                    switch initialSelection
                        case 'Cancelar'
                            return
                        case 'Todas'
                            indexes = 1:numel(app.measData);
                    end
                else
                    initialSelection = 'Todas';
                end

                if ~isempty(layout_searchUnexpectedTableValues(app, initialSelection))
                    warningMessages = ['Há registro de estações instaladas na(s) localidade(s) sob análise para as quais '     ...
                                       'não foram identificadas medidas no entorno. Nesse caso específico, deve-se preencher ' ...
                                       'o campo "Justificativa" e anotar os registros, caso aplicável.<br><br>Deseja ignorar ' ...
                                       'esse alerta, exportando PRÉVIA da análise?'];
                    userSelection   = ui.Dialog(app.UIFigure, 'uiconfirm', warningMessages, {'Sim', 'Não'}, 2, 2);
                    if userSelection == "Não"
                        return
                    end
                end
                % </VALIDAÇÕES>

                % <PROCESSO>
                % (a) Solicita ao usuário nome do arquivo de saída...
                appName       = class.Constants.appName;
                nameFormatMap = {'*.zip', [appName, ' (*.zip)']};                
                defaultName   = appEngine.util.DefaultFileName(app.mainApp.General.fileFolder.userPath, [appName '_Preview']);
                fileZIP       = ui.Dialog(app.UIFigure, 'uiputfile', '', nameFormatMap, defaultName);
                if isempty(fileZIP)
                    return
                end
    
                d = ui.Dialog(app.UIFigure, 'progressdlg', 'Em andamento a criação do arquivo de medidas no formato ".xlsx".');
    
                savedFiles   = {};
                errorFiles   = {};

                % (b) Gera a tabela global de medidas (englobando as localidades 
                %     de agrupamento selecionadas), além do recorte da planilha
                %     de referência do PM-RNI (stationTable).
                measTableGlobal    = createMeasTable(app.measData(indexes));
                stationTableGlobal = app.projectData.modules.(context).stationTable;

                locations      = getFullListOfLocation(app.projectData, app.measData(indexes), app.projectData.modules.(context).distance_km);                        
                stationIndexes = ismember(stationTableGlobal.Location, locations);                
                stationTable   = stationTableGlobal(stationIndexes, :);
    
                % (c) Arquivo no formato .XLSX
                fileName_XLSX = fullfile(app.mainApp.General.fileFolder.tempPath, 'PM-RNI (Preview).xlsx');
                [status, msgError] = fileWriter.MonitoringPlan(fileName_XLSX, stationTable, timetable2table(measTableGlobal), app.mainApp.General.context.MONITORINGPLAN.electricFieldStrengthThreshold, app.mainApp.General.context.MONITORINGPLAN.exportOptions.xlsx);
                if status
                    savedFiles{end+1} = fileName_XLSX;
                else
                    errorFiles{end+1} = msgError;
                end
        
                % (d) Arquivos no formato .KML: "Measures" e "Route" 
                if app.mainApp.General.context.MONITORINGPLAN.exportOptions.kml
                    d.Message = textFormatGUI.HTMLParagraph('Em andamento a criação dos arquivos de medidas e rotas no formato ".kml".');

                    groupLocations = unique({app.measData(indexes).Location});

                    for ii = 1:numel(groupLocations)
                        groupLocation = groupLocations{ii};
                        [~, groupLocationIndex] = ismember(groupLocation, {app.TreeFileLocations.Children.Text});

                        if ~isequal(app.TreeFileLocations.SelectedNodes, app.TreeFileLocations.Children(groupLocationIndex))
                            app.TreeFileLocations.SelectedNodes = app.TreeFileLocations.Children(groupLocationIndex);
                            TreeFileLocationsSelectionChanged(app, struct('SelectedNodes', app.TreeFileLocations.Children(groupLocationIndex)))
                        end

                        groupLocationText    = replace(app.TreeFileLocations.SelectedNodes.Text, '/', '-');
                        groupLocationIndexes = FileIndex(app);
                        groupLocationMeasTable = createMeasTable(app.measData(groupLocationIndexes));

                        % MEDIDAS
                        hMeasPlot = findobj(app.UIAxes.Children, 'Tag', 'Measures');                        
                        KML1File  = fullfile(app.mainApp.General.fileFolder.tempPath, sprintf('%s (Measures).kml', groupLocationText));
                        [status1, msgError1] = fileWriter.KML(KML1File, 'Measures', timetable2table(groupLocationMeasTable), hMeasPlot);
                        if status1
                            savedFiles{end+1} = KML1File;
                        else
                            errorFiles{end+1} = msgError1;
                        end

                        % ROTA
                        KML2File = fullfile(app.mainApp.General.fileFolder.tempPath, sprintf('%s (Route).kml', groupLocationText));
                        [status2, msgError2] = fileWriter.KML(KML2File, 'Route', timetable2table(groupLocationMeasTable));
                        if status2
                            savedFiles{end+1} = KML2File;
                        else
                            errorFiles{end+1} = msgError2;
                        end
                    end
                end
    
                % (e) Arquivo no formato .ZIP
                if ~isempty(savedFiles)
                    zip(fileZIP, savedFiles)
    
                    [~, fileName, fileExt] = fileparts(savedFiles);
                    savedFiles = strcat('•&thinsp;', fileName, fileExt);
                    ui.Dialog(app.UIFigure, 'none', sprintf('Lista de arquivos criados:\n%s', strjoin(savedFiles, '\n')));
                end
    
                if ~isempty(errorFiles)
                    ui.Dialog(app.UIFigure, 'error', strjoin(errorFiles, '\n'));
                end

                delete(d)
            end

        end

        % Image clicked function: tool_GenerateReport
        function Toolbar_GenerateReportImageClicked(app, event)

            context = app.Context;
            indexes = FileIndex(app);

            if ~isempty(indexes)
                % <VALIDAÇÕES>
                if numel(indexes) < numel(app.measData)
                    initialQuestion  = 'Deseja gerar relatório que contemple informações de TODAS as localidades de agrupamento, ou apenas da SELECIONADA?';
                    initialSelection = ui.Dialog(app.UIFigure, 'uiconfirm', initialQuestion, {'Todas', 'Selecionada', 'Cancelar'}, 1, 3);

                    switch initialSelection
                        case 'Cancelar'
                            return
                        case 'Todas'
                            indexes = 1:numel(app.measData);
                    end
                else
                    initialSelection = 'Todas';
                end

                warningMessages = {};
                if ~report_checkEFiscalizaIssueId(app.mainApp, app.projectData.modules.(context).ui.issue)
                    warningMessages{end+1} = sprintf('O número da inspeção "%.0f" é inválido.', app.projectData.modules.(context).ui.issue);
                end
                
                if ~isempty(layout_searchUnexpectedTableValues(app, initialSelection))
                    warningMessages{end+1} = ['Há registro de estações instaladas na(s) localidade(s) sob análise para as quais '     ...
                                              'não foram identificadas medidas no entorno. Nesse caso específico, deve-se preencher ' ...
                                              'o campo "Justificativa" e anotar os registros, caso aplicável.'];
                end
    
                if ~isempty(warningMessages)
                    warningMessages = strjoin(warningMessages, '<br><br>');

                    switch app.reportVersion.Value
                        case 'Definitiva'
                            warningMessages = [warningMessages, '<br><br>Isso impossibilita a geração da versão DEFINITIVA do relatório.'];
                            ui.Dialog(app.UIFigure, "warning", warningMessages);
                            return

                        otherwise % 'Preliminar'
                            warningMessages = [warningMessages, '<br><br>Deseja ignorar esse alerta, gerando a versão PRÉVIA do relatório?'];
                            userSelection   = ui.Dialog(app.UIFigure, 'uiconfirm', warningMessages, {'Sim', 'Não'}, 2, 2);
                            if userSelection == "Não"
                                return
                            end
                    end
                end
                % </VALIDAÇÕES>

                % <PROCESSO>
                app.progressDialog.Visible = 'visible';

                try
                    reportSettings = struct('system',        app.reportSystem.Value, ...
                                            'unit',          app.reportUnit.Value, ...
                                            'issue',         app.reportIssue.Value, ...
                                            'model',         app.reportModelName.Value, ...
                                            'reportVersion', app.reportVersion.Value);
                    reportLibConnection.Controller.Run(app, app.projectData, app.measData(indexes), reportSettings, app.mainApp.General)
                catch ME
                    ui.Dialog(app.UIFigure, 'error', getReport(ME));
                end

                updateToolbar(app)

                app.progressDialog.Visible = 'hidden';
                % </PROCESSO>
            end

        end

        % Image clicked function: tool_UploadFinalFile
        function Toolbar_UploadFinalFileImageClicked(app, event)
            
            % <VALIDAÇÕES>
            context = app.Context;
            lastHTMLDocFullPath = getGeneratedDocumentFileName(app.projectData, '.html', context);

            msg = '';
            if isempty(lastHTMLDocFullPath)
                msg = 'A versão definitiva do relatório ainda não foi gerada.';
            elseif ~isfile(lastHTMLDocFullPath)
                msg = sprintf('O arquivo "%s" não foi encontrado.', lastHTMLDocFullPath);
            elseif ~isfolder(app.mainApp.General.fileFolder.DataHub_POST)
                msg = 'Pendente mapear pasta do Sharepoint';
            elseif ~report_checkEFiscalizaIssueId(app.mainApp, app.projectData.modules.(context).ui.issue)
                msg = sprintf('O número da inspeção "%.0f" é inválido.', app.projectData.modules.(context).ui.issue);
            elseif isempty(app.projectData.modules.(context).ui.system)
                msg = 'Ambiente do eFiscaliza precisa ser selecionado.';
            elseif isempty(app.projectData.modules.(context).ui.unit)
                msg = 'Unidade geradora do documento precisa ser selecionada.';
            end

            if ~isempty(msg)
                ui.Dialog(app.UIFigure, 'warning', msg);
                return
            end
            % </VALIDAÇÕES>

            % <PROCESSO>
            if isempty(app.mainApp.eFiscalizaObj)
                dialogBox    = struct('id', 'login',    'label', 'Usuário: ', 'type', 'text');
                dialogBox(2) = struct('id', 'password', 'label', 'Senha: ',   'type', 'password');
                sendEventToHTMLSource(app.jsBackDoor, 'customForm', struct('UUID', 'eFiscalizaSignInPage', 'Fields', dialogBox, 'Context', context))
            else
                report_uploadInfoController(app.mainApp, [], 'uploadDocument', context)
            end
            % </PROCESSO>

        end

        % Selection change function: SubTabGroup
        function SubTabGroupSelectionChanged(app, event)
            
            [~, tabIndex] = ismember(app.SubTabGroup.SelectedTab, app.SubTabGroup.Children);
            applyJSCustomizations(app, tabIndex)

        end

        % Image clicked function: axesTool_RegionZoom, axesTool_RestoreView
        function AxesToolbarImageClicked(app, event)
            
            switch event.Source
                case app.axesTool_RestoreView
                    geolimits(app.UIAxes, app.restoreView(1).xLim, app.restoreView(1).yLim)

                case app.axesTool_RegionZoom
                    plot.axes.Interactivity.GeographicRegionZoomInteraction(app.UIAxes, app.axesTool_RegionZoom)
            end

        end

        % Selection changed function: TreeFileLocations
        function TreeFileLocationsSelectionChanged(app, event)
            
            if isempty(event.SelectedNodes)
                app.TreeFileLocations.SelectedNodes = event.PreviousSelectedNodes;
                return
            end

            Analysis(app)

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

            idxStations = app.UITable.UserData;
            idxSelectedStation = idxStations(event.Indices(1));
            
            if ~ismember(event.EditData, app.mainApp.General.context.MONITORINGPLAN.noMeasurementReasons)
                app.UITable.Data.("Justificativa") = app.projectData.modules.MONITORINGPLAN.stationTable.("Justificativa")(idxStations);
                return
            end
                        
            app.projectData.modules.MONITORINGPLAN.stationTable.("Justificativa")(idxSelectedStation) = event.NewData;
            updateAnnotationTable(app.projectData, app.Context,  'stationTable', 'save')
            
            layout_TableStyle(app)

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            
            if exist('event', 'var') && ~isempty(event.PreviousSelection) && isequal(event.Selection, event.PreviousSelection)
                return
            end

            if isempty(event.Selection)
                app.UITable.ContextMenu = [];
            else
                if isempty(app.UITable.ContextMenu)
                    app.UITable.ContextMenu = app.ContextMenu;
                end
            end

            updateToolbar(app)
            plot_SelectedPoint(app)
            
        end

        % Image clicked function: LocationListEdit
        function LocationListEditClicked(app, event)

            ipcMainMatlabOpenPopupApp(app.mainApp, app, 'ListOfLocation', app.Context, FileIndex(app))

        end

        % Value changed function: reportModelName
        function reportModelNameValueChanged(app, event)
            
            updateToolbar(app)

        end

        % Value changed function: reportIssue, reportSystem, reportUnit
        function reportInfoValueChanged(app, event)
            
            context = app.Context;

            switch event.Source
                case app.reportSystem
                    updateUiInfo(app.projectData, context, 'system', app.reportSystem.Value)
                case app.reportUnit
                    updateUiInfo(app.projectData, context, 'unit',   app.reportUnit.Value)
                case app.reportIssue
                    updateUiInfo(app.projectData, context, 'issue',  app.reportIssue.Value)
            end
            
        end

        % Image clicked function: tool_OpenPopupProject
        function tool_OpenPopupProjectClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorRNI';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.tool_PanelVisibility = uiimage(app.Toolbar);
            app.tool_PanelVisibility.ImageClickedFcn = createCallbackFcn(app, @Toolbar_InteractionImageClicked, true);
            app.tool_PanelVisibility.ImageSource = 'layout-sidebar-left.svg';
            app.tool_Separator1 = uiimage(app.Toolbar);
            app.tool_Separator1.Enable = 'off';
            app.tool_Separator1.ImageSource = 'LineV.svg';
            app.tool_TableVisibility = uiimage(app.Toolbar);
            app.tool_TableVisibility.ImageClickedFcn = createCallbackFcn(app, @Toolbar_InteractionImageClicked, true);
            app.tool_TableVisibility.ImageSource = 'View_16.png';
            app.tool_TableEdition = uiimage(app.Toolbar);
            app.tool_TableEdition.ImageClickedFcn = createCallbackFcn(app, @Toolbar_OpenPopUpEditionMode, true);
            app.tool_TableEdition.Enable = 'off';
            app.tool_TableEdition.ImageSource = 'Variable_edit_16.png';
            app.tool_ExportFiles = uiimage(app.Toolbar);
            app.tool_ExportFiles.ImageClickedFcn = createCallbackFcn(app, @Toolbar_ExportTableAsExcelSheet, true);
            app.tool_ExportFiles.Enable = 'off';
            app.tool_ExportFiles.ImageSource = 'Export_16.png';
            app.tool_Separator2 = uiimage(app.Toolbar);
            app.tool_Separator2.Enable = 'off';
            app.tool_Separator2.ImageSource = 'LineV.svg';
            app.tool_PeakIcon = uiimage(app.Toolbar);
            app.tool_PeakIcon.ImageClickedFcn = createCallbackFcn(app, @Toolbar_InteractionImageClicked, true);
            app.tool_PeakIcon.Enable = 'off';
            app.tool_PeakIcon.ImageSource = 'Detection_18.png';
            app.tool_PeakLabel = uilabel(app.Toolbar);
            app.tool_PeakLabel.Visible = 'off';
            app.tool_PeakLabel.Text = {'5.3 V/m'; '(-12.354321, -38.123456)'};
            app.tool_OpenPopupProject = uiimage(app.Toolbar);
            app.tool_OpenPopupProject.ImageClickedFcn = createCallbackFcn(app, @tool_OpenPopupProjectClicked, true);
            app.tool_OpenPopupProject.ImageSource = 'organization-20px-black.svg';
            app.tool_GenerateReport = uiimage(app.Toolbar);
            app.tool_GenerateReport.ImageClickedFcn = createCallbackFcn(app, @Toolbar_GenerateReportImageClicked, true);
            app.tool_GenerateReport.Enable = 'off';
            app.tool_GenerateReport.ImageSource = 'Publish_HTML_16.png';
            app.tool_UploadFinalFile = uiimage(app.Toolbar);
            app.tool_UploadFinalFile.ImageClickedFcn = createCallbackFcn(app, @Toolbar_UploadFinalFileImageClicked, true);
            app.tool_UploadFinalFile.Enable = 'off';
            app.tool_UploadFinalFile.ImageSource = 'up-20px.png';
            app.Document = uigridlayout(app.GridLayout);
            app.UITable = uitable(app.Document);
            app.UITable.ColumnName = {'ESTAÇÃO'; 'LOCALIDADE'; 'SERVIÇO'; 'Qtd.|Medidas'; 'Qtd.|> 14 V/m'; 'Dmin|(km)'; 'Emin|(V/m)'; 'Emean|(V/m)'; 'Emax|(V/m)'; 'JUSTIFICATIVA'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true true true true true false];
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false false false false false false false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.plotPanel = uipanel(app.Document);
            app.AxesToolbar = uigridlayout(app.Document);
            app.axesTool_RestoreView = uiimage(app.AxesToolbar);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @AxesToolbarImageClicked, true);
            app.axesTool_RestoreView.ImageSource = 'Home_18.png';
            app.axesTool_RegionZoom = uiimage(app.AxesToolbar);
            app.axesTool_RegionZoom.ImageClickedFcn = createCallbackFcn(app, @AxesToolbarImageClicked, true);
            app.axesTool_RegionZoom.ImageSource = 'ZoomRegion_20.png';
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.dockModule_Undock = uiimage(app.DockModule);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            app.dockModule_Close = uiimage(app.DockModule);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            app.SubTabGroup = uitabgroup(app.GridLayout);
            app.SubTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SubTabGroupSelectionChanged, true);
            app.SubTab1 = uitab(app.SubTabGroup);
            app.SubTab1.Title = 'PM-RNI';
            app.SubGrid1 = uigridlayout(app.SubTab1);
            app.config_geoAxesLabel_2 = uilabel(app.SubGrid1);
            app.config_geoAxesLabel_2.Interpreter = 'html';
            app.config_geoAxesLabel_2.Text = {'LOCALIDADES DE AGRUPAMENTO:'; '<font style="color: gray; font-size: 9px;">(relacionadas aos arquivos de medição)</font>'};
            app.TreeFileLocations = uitree(app.SubGrid1);
            app.TreeFileLocations.SelectionChangedFcn = createCallbackFcn(app, @TreeFileLocationsSelectionChanged, true);
            app.LocationListLabel = uilabel(app.SubGrid1);
            app.LocationListLabel.Interpreter = 'html';
            app.LocationListLabel.Text = {'LOCALIDADES SOB ANÁLISE:'; '<font style="color: gray; font-size: 9px;">(relacionadas às estações previstas no PM-RNI)</font>'};
            app.LocationListEdit = uiimage(app.SubGrid1);
            app.LocationListEdit.ImageClickedFcn = createCallbackFcn(app, @LocationListEditClicked, true);
            app.LocationListEdit.Enable = 'off';
            app.LocationListEdit.ImageSource = 'Edit_32.png';
            app.LocationList = uitextarea(app.SubGrid1);
            app.LocationList.Editable = 'off';
            app.Card1_numberOfStations = uilabel(app.SubGrid1);
            app.Card1_numberOfStations.Interpreter = 'html';
            app.Card1_numberOfStations.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: BLACK; font-size: 32px;">0</font>'; 'ESTAÇÕES INSTALADAS NAS LOCALIDADES SOB ANÁLISE</p>'};
            app.Card2_numberOfRiskStations = uilabel(app.SubGrid1);
            app.Card2_numberOfRiskStations.Interpreter = 'html';
            app.Card2_numberOfRiskStations.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: #a2142f; font-size: 32px;">0</font>'; 'ESTAÇÕES NO ENTORNO DE REGISTROS DE NÍVEIS ACIMA DE 14 V/m</p>'};
            app.Card3_stationsOnRoute = uilabel(app.SubGrid1);
            app.Card3_stationsOnRoute.Interpreter = 'html';
            app.Card3_stationsOnRoute.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: black; font-size: 32px;">0</font>'; 'ESTAÇÕES INSTALADAS NO ENTORNO DA ROTA</p>'};
            app.Card4_stationsOutRoute = uilabel(app.SubGrid1);
            app.Card4_stationsOutRoute.Interpreter = 'html';
            app.Card4_stationsOutRoute.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: #a2142f; font-size: 32px;">0</font>'; 'ESTAÇÕES INSTALADAS FORA DA ROTA</p>'};
            app.SubTab2 = uitab(app.SubTabGroup);
            app.SubTab2.Title = 'PROJETO';
            app.SubGrid2 = uigridlayout(app.SubTab2);
            app.eFiscalizaLabel = uilabel(app.SubGrid2);
            app.eFiscalizaLabel.Text = 'eFISCALIZA';
            app.eFiscalizaPanel = uipanel(app.SubGrid2);
            app.eFiscalizaGrid = uigridlayout(app.eFiscalizaPanel);
            app.reportSystemLabel = uilabel(app.eFiscalizaGrid);
            app.reportSystemLabel.Text = 'Sistema:';
            app.reportSystem = uidropdown(app.eFiscalizaGrid);
            app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS'};
            app.reportSystem.ValueChangedFcn = createCallbackFcn(app, @reportInfoValueChanged, true);
            app.reportSystem.Value = 'eFiscaliza';
            app.reportUnitLabel = uilabel(app.eFiscalizaGrid);
            app.reportUnitLabel.Text = 'Unidade responsável:';
            app.reportUnit = uidropdown(app.eFiscalizaGrid);
            app.reportUnit.Items = {};
            app.reportUnit.ValueChangedFcn = createCallbackFcn(app, @reportInfoValueChanged, true);
            app.reportUnit.Value = {};
            app.reportIssueLabel = uilabel(app.eFiscalizaGrid);
            app.reportIssueLabel.Text = {'Atividade de inspeção:'; '(# ID)'};
            app.reportIssue = uieditfield(app.eFiscalizaGrid, 'numeric');
            app.reportIssue.Limits = [-1 Inf];
            app.reportIssue.RoundFractionalValues = 'on';
            app.reportIssue.ValueDisplayFormat = '%d';
            app.reportIssue.ValueChangedFcn = createCallbackFcn(app, @reportInfoValueChanged, true);
            app.reportIssue.Value = -1;
            app.reportLabel = uilabel(app.SubGrid2);
            app.reportLabel.Text = 'RELATÓRIO';
            app.reportPanel = uipanel(app.SubGrid2);
            app.reportGrid = uigridlayout(app.reportPanel);
            app.reportModelNameLabel = uilabel(app.reportGrid);
            app.reportModelNameLabel.Text = 'Modelo (.json):';
            app.reportModelName = uidropdown(app.reportGrid);
            app.reportModelName.Items = {''};
            app.reportModelName.ValueChangedFcn = createCallbackFcn(app, @reportModelNameValueChanged, true);
            app.reportModelName.Value = '';
            app.reportVersionLabel = uilabel(app.reportGrid);
            app.reportVersionLabel.Text = 'Versão do relatório:';
            app.reportVersion = uidropdown(app.reportGrid);
            app.reportVersion.Items = {'Preliminar', 'Definitiva'};
            app.reportVersion.Value = 'Preliminar';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.Tag = 'auxApp.winMonitoringPlan';
            app.EditSelectedUITableRow = uimenu(app.ContextMenu);
            app.EditSelectedUITableRow.MenuSelectedFcn = createCallbackFcn(app, @Toolbar_OpenPopUpEditionMode, true);
            app.EditSelectedUITableRow.Text = '✏️ Editar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMonitoringPlan(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end