classdef winMonitorRNI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        NavBar                   matlab.ui.container.GridLayout
        AppInfo                  matlab.ui.control.Image
        FigurePosition           matlab.ui.control.Image
        DataHubLamp              matlab.ui.control.Image
        jsBackDoor               matlab.ui.control.HTML
        Tab5Button               matlab.ui.control.StateButton
        Tab4Button               matlab.ui.control.StateButton
        ButtonsSeparator2        matlab.ui.control.Image
        Tab3Button               matlab.ui.control.StateButton
        Tab2Button               matlab.ui.control.StateButton
        ButtonsSeparator1        matlab.ui.control.Image
        Tab1Button               matlab.ui.control.StateButton
        AppName                  matlab.ui.control.Label
        AppIcon                  matlab.ui.control.Image
        TabGroup                 matlab.ui.container.TabGroup
        Tab1_File                matlab.ui.container.Tab
        file_Grid                matlab.ui.container.GridLayout
        FileMetadata             matlab.ui.control.Label
        FileTree                 matlab.ui.container.Tree
        SubTabGroup              matlab.ui.container.TabGroup
        SubTab1                  matlab.ui.container.Tab
        SubGrid1                 matlab.ui.container.GridLayout
        file_FileSortMethodIcon  matlab.ui.control.Image
        file_FileSortMethod      matlab.ui.control.DropDown
        file_ModuleIntro         matlab.ui.control.Label
        file_toolGrid            matlab.ui.container.GridLayout
        tool_Separator           matlab.ui.control.Image
        tool_MergeFiles          matlab.ui.control.Image
        tool_ReadFiles           matlab.ui.control.Image
        Tab2_MonitoringPlan      matlab.ui.container.Tab
        Tab3_ExternalRequest     matlab.ui.container.Tab
        Tab4_RFDataHub           matlab.ui.container.Tab
        Tab5_Config              matlab.ui.container.Tab
        ContextMenu              matlab.ui.container.ContextMenu
        contextmenu_merge        matlab.ui.container.Menu
        contextmenu_del          matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'mainApp'
        Context = 'FILE'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        General
        General_I

        rootFolder
        tabGroupController
        renderCount = 0

        executionMode
        progressDialog
        popupContainer

        eFiscalizaObj

        projectData
        rfDataHub
        rfDataHubLOG
        rfDataHubSummary
        cacheData = model.measData.empty
        measData  = model.measData.empty
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        % COMUNICAÇÃO ENTRE PROCESSOS:
        % • ipcMainJSEventsHandler
        %   Eventos recebidos do objeto app.jsBackDoor por meio de chamada 
        %   ao método "sendEventToMATLAB" do objeto "htmlComponent" (no JS).
        %
        % • ipcMainMatlabCallsHandler
        %   Eventos recebidos dos apps secundários.
        %
        % • ipcMainMatlabCallAuxiliarApp
        %   Reencaminha eventos recebidos aos apps secundários, viabilizando
        %   comunicação entre apps secundários e, também, redirecionando os 
        %   eventos JS quando o app secundário é executado em modo DOCK (e, 
        %   por essa razão, usa o "jsBackDoor" do app principal).
        %
        % • ipcMainMatlabOpenPopupApp
        %   Abre um app secundário como popup, no mainApp.
        %-----------------------------------------------------------------%
        function ipcMainJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    % MATLAB-JS BRIDGE (matlabJSBridge.js)
                    case 'renderer'
                        MFilePath   = fileparts(mfilename('fullpath'));
                        parpoolFlag = false;

                        if ~app.renderCount
                            appEngine.activate(app, app.Role, MFilePath, parpoolFlag)
                        else
                            selectedNodes = app.FileTree.SelectedNodes;
                            if ~isempty(app.FileTree.SelectedNodes)
                                app.FileTree.SelectedNodes = [];
                                onTreeSelectionChanged(app)
                            end

                            appEngine.beforeReload(app, app.Role)
                            appEngine.activate(app, app.Role, MFilePath, parpoolFlag)

                            if ~isempty(selectedNodes)
                                app.FileTree.SelectedNodes = selectedNodes;
                                onTreeSelectionChanged(app)
                            end
                        end
                        
                        app.renderCount = app.renderCount+1;

                    case 'unload'
                        closeFcn(app)
                    
                    case 'customForm'
                        switch event.HTMLEventData.uuid
                            case 'eFiscalizaSignInPage'
                                report_uploadInfoController(app, event.HTMLEventData, 'uploadDocument', event.HTMLEventData.context)

                            case 'openDevTools'
                                if isequal(app.General.operationMode.DevTools, rmfield(event.HTMLEventData, 'uuid'))
                                    webWin = struct(struct(struct(app.UIFigure).Controller).PlatformHost).CEF;
                                    webWin.openDevTools();
                                end
                        end

                    case 'getNavigatorBasicInformation'
                        app.General.AppVersion.browser = event.HTMLEventData;

                    % MAINAPP
                    case 'mainApp.file_Tree'
                        ContextMenu_DeleteSelectedTreeNode(app)

                    % AUXAPP.WINEXTERNALREQUEST
                    case 'auxApp.winExternalRequest.TreePoints'
                        ipcMainMatlabCallAuxiliarApp(app, 'EXTERNALREQUEST', 'JS', event)

                    otherwise
                        error('UnexpectedEvent')
                end
                drawnow

            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME));
            end
        end

        %-----------------------------------------------------------------%
        function varargout = ipcMainMatlabCallsHandler(app, callingApp, eventName, varargin)
            varargout = {};

            try
                switch eventName
                    case 'closeFcn'
                        auxAppTag    = varargin{1};
                        closeModule(app.tabGroupController, auxAppTag, app.General, 'normal')

                    case 'dockButtonPushed'
                        auxAppTag    = varargin{1};
                        varargout{1} = {app};

                    otherwise
                        switch class(callingApp)
                            % auxApp.winConfig (CONFIG)
                            case {'auxApp.winConfig', 'auxApp.winConfig_exported'}
                                switch eventName
                                    case 'checkDataHubLampStatus'
                                        DataHubWarningLamp(app)
        
                                    case 'openDevTools'
                                        dialogBox    = struct('id', 'login',    'label', 'Usuário: ', 'type', 'text');
                                        dialogBox(2) = struct('id', 'password', 'label', 'Senha: ',   'type', 'password');
                                        sendEventToHTMLSource(app.jsBackDoor, 'customForm', struct('UUID', 'openDevTools', 'Fields', dialogBox))
        
                                    case 'onSimulationMode'
                                        if app.General.operationMode.Simulation
                                            Toolbar_SelectFileToReadImageClicked(app)
        
                                            % Muda programaticamente o modo p/ ARQUIVOS.
                                            app.Tab1Button.Value = true;                    
                                            onTabNavigatorButtonPushed(app, struct('Source', app.Tab1Button, 'PreviousValue', false))
                                        end
        
                                    case 'onRFDataHubUpdate'
                                        initializeRFDataHub(app)
                                        ipcMainMatlabCallAuxiliarApp(app, 'RFDATAHUB', 'MATLAB', eventName)
        
                                    case 'onFileSortMethodChanged'
                                        if ~strcmp(app.file_FileSortMethod.Value, app.General.context.FILE.sortMethod)
                                            app.file_FileSortMethod.Value = app.General.context.FILE.sortMethod;
                                            onFileSortMethodValueChanged(app)
                                        end
        
                                    case {'onAnalysisParameterChanged', ...
                                          'onAxesParameterChanged', ...
                                          'onPlotParameterChanged'}
                                        context = varargin{1};
                                        ipcMainMatlabCallAuxiliarApp(app, context, 'MATLAB', eventName)
        
                                    otherwise
                                        error('winMonitorRNI:UnexpectedCall', 'Unexpected call "%s"', eventName)
                                end

                            case {'auxApp.winMonitoringPlan',  'auxApp.winMonitoringPlan_exported', ...
                                  'auxApp.winExternalRequest', 'auxApp.winExternalRequest_exported'}
                                error('winMonitorRNI:UnexpectedCaller', 'Unexpected caller "%s"', class(callingApp))


                            % DOCKS:OTHERS
                            case {'auxApp.dockListOfLocation', 'auxApp.dockListOfLocation_exported', ...
                                  'auxApp.dockReportLib',      'auxApp.dockReportLib_exported',      ...
                                  'auxApp.dockStationInfo',    'auxApp.dockStationInfo_exported'}
                                switch eventName
                                    case 'closeFcnCallFromPopupApp'
                                        context = varargin{1};
                                        moduleTag = varargin{2};
        
                                        switch context
                                            case {app.Role, app.Context}
                                                hApp = app;
                                                app.popupContainer.Parent.Visible = 0;
                                            otherwise
                                                hApp = getAppHandle(app.tabGroupController, context);
                                                ipcMainMatlabCallAuxiliarApp(app, context, 'MATLAB', eventName)
                                        end
                                        
                                        if ~isempty(hApp)
                                            deleteContextMenu(app.tabGroupController, hApp.UIFigure, moduleTag)
                                        end

                                    case {'onLocationListModeChanged', ...
                                          'onStationCoordinatesEdited', ...
                                          'onStationInfoChanged', ...
                                          'onStationSelectionChanged'}
                                        context = varargin{1};
                                        varargin = [{eventName}, varargin(2:end)];
                                        ipcMainMatlabCallAuxiliarApp(app, context, 'MATLAB', varargin{:})

                                    otherwise
                                        error('winMonitorRNI:UnexpectedCall', 'Unexpected call "%s"', eventName)
                                end
            
                            otherwise
                                error('winMonitorRNI:UnexpectedCaller', 'Unexpected caller "%s"', class(callingApp))
                        end
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);            
            end

            % Caso um app auxiliar esteja em modo DOCK, o progressDialog do
            % app auxiliar coincide com o do appAnalise. Força-se, portanto, 
            % a condição abaixo para evitar possível bloqueio da tela.
            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function ipcMainMatlabCallAuxiliarApp(app, auxAppName, communicationType, varargin)
            hAuxApp = getAppHandle(app.tabGroupController, auxAppName);

            if ~isempty(hAuxApp)
                switch communicationType
                    case 'MATLAB'
                        operationType = varargin{1};
                        ipcSecondaryMatlabCallsHandler(hAuxApp, app, operationType, varargin{2:end});
                    case 'JS'
                        event = varargin{1};
                        ipcSecondaryJSEventsHandler(hAuxApp, event)
                end
            end
        end

        %-----------------------------------------------------------------%
        function ipcMainMatlabOpenPopupApp(app, callingApp, auxAppName, varargin)
            arguments
                app
                callingApp
                auxAppName char {mustBeMember(auxAppName, {'ReportLib', 'ListOfLocation', 'StationInfo'})}
            end

            arguments (Repeating)
                varargin 
            end

            switch auxAppName
                case 'ReportLib'
                    screenWidth  = 460;
                    screenHeight = 308;
                case 'ListOfLocation'
                    screenWidth  = 540;
                    screenHeight = 440;
                case 'StationInfo'
                    screenWidth  = 540;
                    screenHeight = 440;
            end

            requestVisibilityChange(callingApp.progressDialog, 'visible', 'unlocked')
            ui.PopUpContainer(callingApp, class.Constants.appName, screenWidth, screenHeight)

            % Executa o app auxiliar.
            inputArguments = [{app, callingApp}, varargin];
            auxDockAppName = sprintf('auxApp.dock%s', auxAppName);
            
            if app.General.operationMode.Debug
                eval(sprintf('auxApp.dock%s(inputArguments{:})', auxAppName))
            else
                eval([auxDockAppName '_exported(callingApp.popupContainer, inputArguments{:})'])
                
                callingApp.popupContainer.UserData.auxDockAppName = auxDockAppName;
                callingApp.popupContainer.Parent.Visible = 1;
            end

            requestVisibilityChange(callingApp.progressDialog, 'hidden', 'unlocked')
        end
    end

    
    methods (Access = public)
        %-----------------------------------------------------------------%
        function navigateToTab(app, clickedButton)
            onTabNavigatorButtonPushed(app, struct('Source', clickedButton, 'PreviousValue', false))
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;
            
            switch tabIndex
                case 1
                    appName = class(app);
                    elToModify = {
                        app.Tab1Button;
                        app.Tab2Button;
                        app.Tab3Button;
                        app.Tab4Button;
                        app.Tab5Button;
                        app.FileTree;
                        app.FileMetadata                        
                    };                            
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        ui.TextView.startup(app.jsBackDoor, app.FileMetadata, appName);
                    catch
                    end

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.Tab1Button.UserData.id, 'generation', 1, 'class', 'tab-navigator-button'), ...
                            struct('appName', appName, 'dataTag', app.Tab2Button.UserData.id, 'generation', 1, 'class', 'tab-navigator-button'), ...
                            struct('appName', appName, 'dataTag', app.Tab3Button.UserData.id, 'generation', 1, 'class', 'tab-navigator-button'), ...
                            struct('appName', appName, 'dataTag', app.Tab4Button.UserData.id, 'generation', 1, 'class', 'tab-navigator-button'), ...
                            struct('appName', appName, 'dataTag', app.Tab5Button.UserData.id, 'generation', 1, 'class', 'tab-navigator-button'), ...
                            struct('appName', appName, 'dataTag', app.FileTree.UserData.id, 'listener', struct('componentName', 'mainApp.file_Tree', 'keyEvents', {{'Delete', 'Backspace'}})) ...
                        });
                    catch
                    end

                otherwise
                    % Previsto pensando em evolução, caso adicionado uitab
                    % ao app.SubTabGrid...
            end
        end

        %-----------------------------------------------------------------%
        function loadConfigurationFile(app, appName, MFilePath)
            % "GeneralSettings.json"
            [app.General_I, msgWarning] = appEngine.util.generalSettingsLoad(appName, app.rootFolder);
            if ~isempty(msgWarning)
                ui.Dialog(app.UIFigure, 'error', msgWarning);
            end

            % Para criação de arquivos temporários, cria-se uma pasta da 
            % sessão.
            tempDir = tempname;
            mkdir(tempDir)
            app.General_I.fileFolder.tempPath  = tempDir;
            app.General_I.fileFolder.MFilePath = MFilePath;

            switch app.executionMode
                case 'webApp'
                    % Força a exclusão do SplashScreen do MATLAB Web Server.
                    sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");

                    app.General_I.operationMode.Debug = false;
                    app.General_I.operationMode.Dock  = true;
                    
                    % A pasta do usuário não é configurável, mas obtida por 
                    % meio de chamada a uiputfile. 
                    app.General_I.fileFolder.userPath = tempDir;

                    % A renderização do plot no MATLAB WebServer, enviando-o à uma 
                    % sessão do webapp como imagem Base64, é crítica por depender 
                    % das comunicações WebServer-webapp e WebServer-BaseMapServer. 
                    % Ao configurar o Basemap como "none", entretanto, elimina-se a 
                    % necessidade de comunicação com BaseMapServer, além de tornar 
                    % mais eficiente a comunicação com webapp porque as imagens
                    % Base64 são menores (uma imagem com Basemap "sattelite" pode 
                    % ter 500 kB, enquanto uma imagem sem Basemap pode ter 25 kB).
                    app.General_I.plot.geographicAxes.basemap = 'none';
                    app.General_I.reportLib.basemap           = 'none';

                otherwise    
                    % Resgata a pasta de trabalho do usuário (configurável).
                    userPaths = appEngine.util.UserPaths(app.General_I.fileFolder.userPath);
                    app.General_I.fileFolder.userPath = userPaths{end};

                    switch app.executionMode
                        case 'desktopStandaloneApp'
                            app.General_I.operationMode.Debug = false;
                        case 'MATLABEnvironment'
                            app.General_I.operationMode.Debug = true;
                    end
            end

            % "RFDataHub.mat"
            global RFDataHub
            global RFDataHub_info
        
            if isempty(RFDataHub) || isempty(RFDataHub_info)
                model.RFDataHub.read(appName, app.rootFolder, tempDir)
            end

            app.General            = app.General_I;
            app.General.AppVersion = util.getAppVersion(app.rootFolder, MFilePath, tempDir);
            sendEventToHTMLSource(app.jsBackDoor, 'getNavigatorBasicInformation')
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            initializeRFDataHub(app)
            app.projectData = model.projectLib(app, app.rootFolder, app.General);
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            app.tabGroupController = ui.TabNavigator(app.NavBar, app.TabGroup, app.progressDialog);
            addComponent(app.tabGroupController, "Built-in", "",                          app.Tab1Button, "AlwaysOn", struct('On', '', 'Off', ''), matlab.graphics.GraphicsPlaceholder, 1)
            addComponent(app.tabGroupController, "External", "auxApp.winMonitoringPlan",  app.Tab2Button, "AlwaysOn", struct('On', '', 'Off', ''), app.Tab1Button,                      2)
            addComponent(app.tabGroupController, "External", "auxApp.winExternalRequest", app.Tab3Button, "AlwaysOn", struct('On', '', 'Off', ''), app.Tab1Button,                      3)
            addComponent(app.tabGroupController, "External", "auxApp.winRFDataHub",       app.Tab4Button, "AlwaysOn", struct('On', '', 'Off', ''), app.Tab1Button,                      4)
            addComponent(app.tabGroupController, "External", "auxApp.winConfig",          app.Tab5Button, "AlwaysOn", struct('On', '', 'Off', ''), app.Tab1Button,                      5)
            app.tabGroupController.inlineSVG = true;

            addStyle(app.FileTree, uistyle('Interpreter', 'html'))
        end


        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            DataHubWarningLamp(app)
            app.file_FileSortMethod.Value = app.General.context.FILE.sortMethod;
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function initializeRFDataHub(app)
            global RFDataHub
            global RFDataHubLog

            app.rfDataHub        = RFDataHub;
            app.rfDataHubLOG     = RFDataHubLog;
            app.rfDataHubSummary = summary(RFDataHub(:, {'Source', 'State'}));

            % A coluna "Source" possui agrupamentos da fonte dos dados,
            % decorrente da mesclagem de estações.
            tempSourceList = cellfun(@(x) strsplit(x, ' | '), app.rfDataHubSummary.Source.Categories, 'UniformOutput', false);
            app.rfDataHubSummary.Source.RawCategories = unique(horzcat(tempSourceList{:}))';
        end

        %-----------------------------------------------------------------%
        function DataHubWarningLamp(app)
            if isfolder(app.General.fileFolder.DataHub_POST)
                app.DataHubLamp.Visible = 0;
            else
                app.DataHubLamp.Visible = 1;
            end
        end

        %-----------------------------------------------------------------%
        function file_ProjectRestart(app, indexes, updateType)
            arguments
                app
                indexes
                updateType char {mustBeMember(updateType, {'onFileListAdded', ...
                                                           'onFileListRemoved', ...
                                                           'onFileListUnmerged', ...
                                                           'onFileListMerged'})}
            end

            file_TreeBuilding(app, indexes)

            if ismember(updateType, {'onFileListAdded', 'onFileListRemoved'})
                updateAnalysis( ...
                    app.projectData, ...
                    app.measData, ...
                    app.General, ...
                    updateType ...
                );
            end
            
            ipcMainMatlabCallAuxiliarApp(app, 'MONITORINGPLAN',  'MATLAB', updateType)
            ipcMainMatlabCallAuxiliarApp(app, 'EXTERNALREQUEST', 'MATLAB', updateType)
        end
        
        %-----------------------------------------------------------------%
        function file_TreeBuilding(app, selectedNodeData)
            arguments
                app
                selectedNodeData = []
            end

            if ~isempty(app.FileTree.Children)
                app.FileMetadata.UserData = [];
                delete(app.FileTree.Children)
            end

            selectedNode = [];

            switch app.file_FileSortMethod.Value
                case 'ARQUIVO'
                    for ii = 1:numel(app.measData)
                        treeNode = file_createTreeElements(app, ii, app.FileTree, 'FILE');

                        if ismember(ii, selectedNodeData)
                            selectedNode = [selectedNode, treeNode];
                        end
                    end

                case 'LOCALIDADE'
                    locationList = {app.measData.Location};
                    locations    = unique(locationList);

                    for ii = 1:numel(locations)
                        location = locations{ii};
                        locationIndexes  = find(strcmp(locationList, location));
                        [~, idSort]      = sort(arrayfun(@(x) x.Data.Timestamp(1), app.measData(locationIndexes)));
                        locationIndexes  = locationIndexes(idSort);

                        locationTreeNode = uitreenode(app.FileTree, 'Text', location, ...
                                                                     'NodeData', locationIndexes, ...
                                                                     'ContextMenu', app.ContextMenu);

                        for jj = locationIndexes
                            treeNode = file_createTreeElements(app, jj, locationTreeNode, 'LOCATION');
    
                            if ismember(jj, selectedNodeData)
                                selectedNode = [selectedNode, treeNode];
                            end
                        end
                    end
            end

            expand(app.FileTree, 'all')

            if ~isempty(app.measData)
                if ~isempty(selectedNode)
                    app.FileTree.SelectedNodes = selectedNode;
                else
                    if isempty(app.FileTree.Children(1).Children)
                        app.FileTree.SelectedNodes = app.FileTree.Children(1);
                    else
                        app.FileTree.SelectedNodes = app.FileTree.Children(1).Children(1);
                    end
                end
            end

            onTreeSelectionChanged(app)
        end

        %-----------------------------------------------------------------%
        function treeNode = file_createTreeElements(app, index, treeNodeParent, treeNodePreffixType)
            arguments
                app
                index
                treeNodeParent
                treeNodePreffixType char {mustBeMember(treeNodePreffixType, {'FILE', 'LOCATION'})}
            end

            currentMeasData = app.measData(index);
            
            switch treeNodePreffixType
                case 'FILE'
                    treeNodePreffix = currentMeasData.Filename;
                case 'LOCATION'
                    treeNodePreffix = currentMeasData.ObservationTime;
            end
            
            [taskBegin, taskEnd] = bounds(currentMeasData.Data.Timestamp);
            
            mergeStatusIcon = '';
            if ~strcmp(currentMeasData.Location, currentMeasData.Location_I)
                mergeStatusIcon = '  ➕';
            end

            treeText = sprintf('%s  - ⌛%s  -  📐%s%s', treeNodePreffix, string(taskEnd-taskBegin), sprintf('[%.1f - %.1f] V/m', currentMeasData.FieldValueLimits(:)), mergeStatusIcon);
            treeNode = uitreenode(treeNodeParent, 'Text', treeText, ...
                                                  'NodeData', index, ...
                                                  'ContextMenu', app.ContextMenu);
        end

        %-----------------------------------------------------------------%
        function indexes = file_findSelectedNodeData(app)
            indexes = [];
            if ~isempty(app.FileTree.SelectedNodes)
                indexes = unique([app.FileTree.SelectedNodes.NodeData]);
            end
        end

        %-----------------------------------------------------------------%
        function updateToolbar(app)
            indexes = file_findSelectedNodeData(app);

            nonEmptySelection = ~isempty(indexes);
            nonScalarSelection = ~isscalar(indexes);

            locationMergedStatus = false;
            if ~isempty(indexes)
                locationMergedStatus = any(arrayfun(@(x) ~strcmp(x.Location, x.Location_I), app.measData(indexes)));
            end

            app.tool_MergeFiles.Enable   = nonEmptySelection && (nonScalarSelection || locationMergedStatus);
            app.contextmenu_merge.Enable = app.tool_MergeFiles.Enable;
            app.contextmenu_del.Enable   = nonEmptySelection;
        end

        %-----------------------------------------------------------------%
        function updateLastVisitedFolder(app, filePath)
            app.General_I.fileFolder.lastVisited = filePath;
            app.General.fileFolder.lastVisited   = filePath;

            appEngine.util.generalSettingsSave(class.Constants.appName, app.rootFolder, app.General_I, app.executionMode)
        end
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        % SISTEMA DE GESTÃO DA FISCALIZAÇÃO (eFiscaliza/SEI)
        %-----------------------------------------------------------------%                
        function status = report_checkEFiscalizaIssueId(app, issue)
            status = (issue > 0) && (issue < inf);
        end

        %-----------------------------------------------------------------%
        function report_uploadInfoController(app, credentials, operation, context)
            communicationStatus = report_sendHTMLDocToSEIviaEFiscaliza(app, credentials, operation, context);
            if communicationStatus && strcmp(app.projectData.modules.(context).ui.system, 'eFiscaliza')
                report_sendFilesToSharepoint(app, context)
            end
        end

        %-------------------------------------------------------------------------%
        function communicationStatus = report_sendHTMLDocToSEIviaEFiscaliza(app, credentials, operation, context)
            app.progressDialog.Visible = 'visible';
            communicationStatus = false;

            try
                if ~isempty(credentials)
                    app.eFiscalizaObj = ws.eFiscaliza(credentials.login, credentials.password);
                end

                % Parâmetros configurados no módulo auxiliar:
                env   = strsplit(app.projectData.modules.(context).ui.system);
                if numel(env) < 2; env = 'PD';
                else;              env = env{2};
                end
                unit  = app.projectData.modules.(context).ui.unit;
                issue = struct('type', 'ATIVIDADE DE INSPEÇÃO', 'id', app.projectData.modules.(context).ui.issue);                

                switch operation
                    case 'uploadDocument'
                        fileName = getGeneratedDocumentFileName(app.projectData, '.html', context);
                        docSpec  = app.General.eFiscaliza;
                        docSpec.originId = docSpec.internal.originId;
                        docSpec.typeId   = docSpec.internal.typeId;

                        msg = run(app.eFiscalizaObj, env, operation, issue, unit, docSpec, fileName);
        
                    otherwise
                        error('Unexpected call')
                end
                
                if ~contains(msg, 'Documento cadastrado no SEI', 'IgnoreCase', true)
                    error(msg)
                end

                modalWindowIcon     = 'success';
                modalWindowMessage  = msg;
                communicationStatus = true;

            catch ME
                app.eFiscalizaObj   = [];
                
                modalWindowIcon     = 'error';
                modalWindowMessage  = ME.message;
            end

            ui.Dialog(app.UIFigure, modalWindowIcon, modalWindowMessage);
            app.progressDialog.Visible = 'hidden';
        end

        %------------------------------------------------------------------------%
        function report_sendFilesToSharepoint(app, context)
            % Evita subir por engano, quando no ambiente de desenvolvimento,
            % de arquivos na pasta "POST".
            try
                if ~isdeployed()
                    error('ForceDebugMode')
                end
                sharepointFolder = app.General.fileFolder.DataHub_POST;
            catch
                sharepointFolder = app.General.fileFolder.userPath;
            end

            sharepointFileList = getGeneratedDocumentFileName(app.projectData, 'rawFiles', context);
            if strcmp(context, 'MonitoringPlan')
                sharepointFileList = [sharepointFileList, {getGeneratedDocumentFileName(app.projectData, '.xlsx', context)}];
            end
            
            for ii = 1:numel(sharepointFileList)
                tempFilename = sharepointFileList{ii};

                try
                    if isfile(tempFilename)
                        copyfile(tempFilename, sharepointFolder, 'f');
    
                        if ~endsWith(tempFilename, '.xlsx')
                            [~, basename]  = fileparts(tempFilename);
                            jsonFilename   = [basename '.json'];
                            [~, fileIndex] = ismember(tempFilename, {app.measData.Filename});
    
                            if fileIndex
                                fileWriter.RawFileMetaData(fullfile(sharepointFolder, jsonFilename), app.measData(fileIndex));
                            end
                        end
                    end
                catch ME
                    ui.Dialog(app.UIFigure, 'error', getReport(ME))
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            try
                appEngine.boot(app, app.Role)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            if strcmp(app.progressDialog.Visible, 'visible')
                app.progressDialog.Visible = 'hidden';
                return
            end

            % msgQuestion = '';
            % if checkIfUpdateNeeded(app.projectData, app.measData)
            %     msgQuestion = sprintf([ ...
            %         'O projeto "%s" foi modificado (nome, arquivo de saída, ' ...
            %         'lista de arquivos de entrada ou tabelas de anotação das ' ...
            %         'contas de resultado). Caso o aplicativo seja encerrado ' ...
            %         'agora, todas as alterações serão descartadas.\n\n' ...
            %         'Deseja realmente fechar o aplicativo?' ...
            %         ], app.projectData.name);
            % 
            % elseif ~strcmp(app.executionMode, 'webApp')
            %     msgQuestion = 'Deseja fechar o aplicativo?';
            % end
msgQuestion = 'Deseja fechar o aplicativo?';
            if ~isempty(msgQuestion)                
                userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                if userSelection == "Não"
                    return
                end
            end

            % Aspectos gerais (comum em todos os apps):
            appEngine.beforeDeleteApp(app.progressDialog, app.General_I.fileFolder.tempPath, app.tabGroupController, app.executionMode)
            delete(app)
            
        end

        % Callback function: AppInfo, DataHubLamp, FigurePosition, 
        % ...and 5 other components
        function onTabNavigatorButtonPushed(app, event)

            switch event.Source
                case {app.Tab1Button, app.Tab2Button, app.Tab3Button, app.Tab4Button, app.Tab5Button}
                    openModule(app.tabGroupController, event.Source, event.PreviousValue, app.General, app)

                case app.DataHubLamp
                    msg = [ ...
                        'Pendente mapear a pasta POST do SharePoint, de modo a viabilizar:<br>' ...
                        '•&thinsp;Upload do relatório final para o SEI.' ...
                    ];
                    ui.Dialog(app.UIFigure, 'error', msg);

                case app.FigurePosition
                    app.UIFigure.Position(3:4) = class.Constants.windowSize;
                    appEngine.util.setWindowPosition(app.UIFigure)
                    focus(findobj(app.NavBar.Children, 'Type', 'uistatebutton', 'Value', true))

                case app.AppInfo
                    appInfo = util.HtmlTextGenerator.AppInfo( ...
                        app.General, ...
                        app.rootFolder, ...
                        app.executionMode, ...
                        app.renderCount, ...
                        app.projectData, ...
                        "popup" ...
                    );
                    ui.Dialog(app.UIFigure, 'info', appInfo);
            end

        end

        % Selection changed function: FileTree
        function onTreeSelectionChanged(app, event)

            indexes = file_findSelectedNodeData(app);

            if isempty(indexes)
                app.FileMetadata.Text     = '';
                app.FileMetadata.UserData = [];

            else
                if isequal(app.FileMetadata.UserData, indexes)
                    return
                end

                app.FileMetadata.Text     = util.HtmlTextGenerator.SelectedFile(app.measData(indexes));
                app.FileMetadata.UserData = indexes;
            end

            updateToolbar(app)
            
        end

        % Value changed function: file_FileSortMethod
        function onFileSortMethodValueChanged(app, event)
            
            indexes = file_findSelectedNodeData(app);
            file_TreeBuilding(app, indexes)

        end

        % Image clicked function: tool_ReadFiles
        function Toolbar_SelectFileToReadImageClicked(app, event)

            d = [];
            fileFullName = {};

            if app.General.operationMode.Simulation
                app.General.operationMode.Simulation = false;
                
                [projectFolder, ...
                 programDataFolder] = appEngine.util.Path(class.Constants.appName, app.rootFolder);
                simulationFolders   = {programDataFolder, projectFolder};

                for ii = 1:numel(simulationFolders)
                    filePath    = fullfile(simulationFolders{ii}, 'Simulation');    
                    listOfFiles = dir(filePath);
                    fileName    = {listOfFiles.name};
                    fileName    = fileName(endsWith(lower(fileName), '.txt'));
                    
                    if ~isempty(fileName)
                        fileFullName = fullfile(filePath, fileName);
                        break
                    end
                end

                if isempty(fileFullName)
                    msgWarning = 'Nenhum arquivo de simulação foi identificado.';
                    ui.Dialog(app.UIFigure, "warning", msgWarning);
                    return
                end

            else
                switch app.General.context.FILE.input
                    case 'file'
                        [fileName, filePath] = uigetfile({'*.txt';'*.csv';'*.mat';'*.*'}, ...
                                                          '', app.General.fileFolder.lastVisited, 'MultiSelect', 'on');
                        figure(app.UIFigure)
            
                        if isequal(fileName, 0)
                            return
                        elseif ~iscellstr(fileName)
                            fileName = cellstr(fileName);
                        end
                        fileFullName = fullfile(filePath, fileName);
    
                    case 'folder'
                        filePath = uigetdir(app.General.fileFolder.lastVisited);
                        figure(app.UIFigure)
    
                        if isequal(filePath, 0)
                            return
                        end
    
                        d = ui.Dialog(app.UIFigure, "progressdlg", "Em andamento...");
                        [fileFullName, fileName] = util.getFilesFromFolder(filePath);
                end
                updateLastVisitedFolder(app, filePath)
            end

            if isempty(d)
                d = ui.Dialog(app.UIFigure, "progressdlg", "Em andamento...");
            end

            filesInCache = {};
            filesError   = struct('File', {}, 'Error', {});

            for ii = 1:numel(fileFullName)
                d.Message = textFormatGUI.HTMLParagraph(sprintf('Em andamento a leitura do arquivo %d de %d:<br>• <b>%s</b>', ii, numel(fileFullName), fileName{ii}));

                % Verifica se arquivo já foi lido, comparando o seu nome com 
                % a variável app.cacheData.
                [~, idxCache] = ismember(fileName{ii}, {app.cacheData.Filename});
                if ~idxCache
                    try
                        app.cacheData(end+1) = fileReader.CSV.Controller(fileFullName{ii});
                    catch ME
                        filesError(end+1) = struct('File', fileName{ii}, 'Error', ME.message);
                        continue
                    end
                    idxCache = numel(app.cacheData);
                end

                % Verifica se arquivo já está ativo, comparando o seu nome com 
                % a variável app.measData.
                [~, idxFile] = ismember(fileName{ii}, {app.measData.Filename});
                if ~idxFile
                    app.measData(end+1) = app.cacheData(idxCache);
                else
                    filesInCache{end+1} = fileName{ii};
                end
            end

            % LOG
            msgWarning = '';
            if ~isempty(filesError)
                msgWarning = sprintf('Arquivos que apresentaram erro na leitura:\n%s\n\n', strjoin(strcat({'<font style="font-size: 11px;">•&thinsp;'}, {filesError.File}, {': '}, {filesError.Error}), '</font>\n'));
            end

            if ~isempty(filesInCache)
                msgWarning = [msgWarning, sprintf('Arquivos já lidos:\n%s', textFormatGUI.cellstr2Bullets(filesInCache))];
            end

            if ~isempty(msgWarning)
                ui.Dialog(app.UIFigure, "warning", msgWarning);
            end
            
            indexes = file_findSelectedNodeData(app);
            file_ProjectRestart(app, indexes, 'onFileListAdded')

            delete(d)

        end

        % Callback function: contextmenu_merge, tool_MergeFiles
        function Toolbar_MergeFilesImageClicked(app, event)
            
            % O processo de "mesclagem" é apenas o controle da localidade
            % de agrupamento, não tendo impacto na análise, mas apenas na
            % visualização da informação no app (e no relatório).

            indexes = file_findSelectedNodeData(app);

            locationMergedStatus = any(arrayfun(@(x) ~strcmp(x.Location, x.Location_I), app.measData(indexes)));
            if locationMergedStatus
                msgQuestion   = util.HtmlTextGenerator.MergedFiles(app.measData(indexes), 'MergedStatusOn');
                userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);

                if userSelection == "Sim"
                    delLocationCache(app.projectData, app.measData, indexes)
                    
                    for ii = indexes
                        app.measData(ii).Location = app.measData(ii).Location_I;
                    end
                    file_ProjectRestart(app, indexes, 'onFileListUnmerged')
                    return
                end
            end

            if numel(indexes) >= 2
                locationList = unique({app.measData(indexes).Location});
                if isscalar(locationList)
                    ui.Dialog(app.UIFigure, 'info', util.HtmlTextGenerator.MergedFiles(app.measData(indexes), 'ScalarLocation'));
                    return
                elseif numel(locationList) > 3
                    ui.Dialog(app.UIFigure, 'info', util.HtmlTextGenerator.MergedFiles(app.measData(indexes), 'MoreThanThreeLocations'));
                    return
                end

                msgQuestion   = util.HtmlTextGenerator.MergedFiles(app.measData(indexes), 'FinalConfirmationBeforeEdition');
                userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, [locationList, {'Cancelar'}], numel(locationList)+1, numel(locationList)+1);

                if userSelection == "Cancelar"
                    return
                end

                for ii = indexes
                    app.measData(ii).Location = userSelection;
                end
                file_ProjectRestart(app, indexes, 'onFileListMerged')
            end

        end

        % Menu selected function: contextmenu_del
        function ContextMenu_DeleteSelectedTreeNode(app, event)
            
            if ~isempty(app.FileTree.SelectedNodes)
                indexes = [app.FileTree.SelectedNodes.NodeData];
                app.measData(indexes) = [];
                file_ProjectRestart(app, [], 'onFileListRemoved')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorRNI';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'icon_48.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1_File = uitab(app.TabGroup);
            app.Tab1_File.Title = 'FILE';
            app.file_Grid = uigridlayout(app.Tab1_File);
            app.file_toolGrid = uigridlayout(app.file_Grid);
            app.tool_ReadFiles = uiimage(app.file_toolGrid);
            app.tool_ReadFiles.ImageClickedFcn = createCallbackFcn(app, @Toolbar_SelectFileToReadImageClicked, true);
            app.tool_ReadFiles.Tooltip = {'Seleciona arquivos'};
            app.tool_ReadFiles.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'Import_16.png');
            app.tool_MergeFiles = uiimage(app.file_toolGrid);
            app.tool_MergeFiles.ImageClickedFcn = createCallbackFcn(app, @Toolbar_MergeFilesImageClicked, true);
            app.tool_MergeFiles.Enable = 'off';
            app.tool_MergeFiles.Tooltip = {'Mescla informações'};
            app.tool_MergeFiles.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'Merge_18.png');
            app.tool_Separator = uiimage(app.file_toolGrid);
            app.tool_Separator.Enable = 'off';
            app.tool_Separator.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'LineV.svg');
            app.SubTabGroup = uitabgroup(app.file_Grid);
            app.SubTab1 = uitab(app.SubTabGroup);
            app.SubTab1.Title = 'ARQUIVOS';
            app.SubGrid1 = uigridlayout(app.SubTab1);
            app.file_ModuleIntro = uilabel(app.SubGrid1);
            app.file_ModuleIntro.Text = 'Este aplicativo permite a leitura de arquivos gerados em medições de campo elétrico no âmbito do PM-RNI.';
            app.file_FileSortMethod = uidropdown(app.SubGrid1);
            app.file_FileSortMethod.Items = {'ARQUIVO', 'LOCALIDADE'};
            app.file_FileSortMethod.ValueChangedFcn = createCallbackFcn(app, @onFileSortMethodValueChanged, true);
            app.file_FileSortMethod.Value = 'LOCALIDADE';
            app.file_FileSortMethodIcon = uiimage(app.SubGrid1);
            app.file_FileSortMethodIcon.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'sort_az_ascending.png');
            app.FileTree = uitree(app.file_Grid);
            app.FileTree.Multiselect = 'on';
            app.FileTree.SelectionChangedFcn = createCallbackFcn(app, @onTreeSelectionChanged, true);
            app.FileMetadata = uilabel(app.file_Grid);
            app.FileMetadata.Interpreter = 'html';
            app.FileMetadata.Text = '';
            app.Tab2_MonitoringPlan = uitab(app.TabGroup);
            app.Tab2_MonitoringPlan.Title = 'MONITORINGPLAN';
            app.Tab3_ExternalRequest = uitab(app.TabGroup);
            app.Tab3_ExternalRequest.Title = 'EXTERNALREQUEST';
            app.Tab4_RFDataHub = uitab(app.TabGroup);
            app.Tab4_RFDataHub.Title = 'RFDATAHUB';
            app.Tab5_Config = uitab(app.TabGroup);
            app.Tab5_Config.Title = 'CONFIG';
            app.NavBar = uigridlayout(app.GridLayout);
            app.NavBar.Tag = 'COLORLOCKED';
            app.AppIcon = uiimage(app.NavBar);
            app.AppIcon.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'icon_48.png');
            app.AppName = uilabel(app.NavBar);
            app.AppName.Interpreter = 'html';
            app.AppName.Text = {'monitorRNI v. 1.0.0'; '<font style="font-size: 9px;">R2024a</font>'};
            app.Tab1Button = uibutton(app.NavBar, 'state');
            app.Tab1Button.ValueChangedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.Tab1Button.Tag = 'FILE';
            app.Tab1Button.Tooltip = {'Leitura de arquivos'};
            app.Tab1Button.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'folder-active-24px-yellow.svg');
            app.Tab1Button.Text = '';
            app.Tab1Button.Value = true;
            app.ButtonsSeparator1 = uiimage(app.NavBar);
            app.ButtonsSeparator1.Enable = 'off';
            app.ButtonsSeparator1.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'LineV_White.svg');
            app.Tab2Button = uibutton(app.NavBar, 'state');
            app.Tab2Button.ValueChangedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.Tab2Button.Tag = 'MONITORINGPLAN';
            app.Tab2Button.Tooltip = {'PM-RNI'};
            app.Tab2Button.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'graph-line-24px-white.svg');
            app.Tab2Button.Text = '';
            app.Tab3Button = uibutton(app.NavBar, 'state');
            app.Tab3Button.ValueChangedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.Tab3Button.Tag = 'EXTERNALREQUEST';
            app.Tab3Button.Tooltip = {'Demanda externa'};
            app.Tab3Button.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'report-24px-2hite.svg');
            app.Tab3Button.Text = '';
            app.ButtonsSeparator2 = uiimage(app.NavBar);
            app.ButtonsSeparator2.Enable = 'off';
            app.ButtonsSeparator2.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'LineV_White.svg');
            app.Tab4Button = uibutton(app.NavBar, 'state');
            app.Tab4Button.ValueChangedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.Tab4Button.Tag = 'RFDATAHUB';
            app.Tab4Button.Tooltip = {'RFDataHub'};
            app.Tab4Button.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'database-24px-white.svg');
            app.Tab4Button.Text = '';
            app.Tab5Button = uibutton(app.NavBar, 'state');
            app.Tab5Button.ValueChangedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.Tab5Button.Tag = 'CONFIG';
            app.Tab5Button.Tooltip = {'Configurações gerais'};
            app.Tab5Button.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'gear-24px-white.svg');
            app.Tab5Button.Text = '';
            app.jsBackDoor = uihtml(app.NavBar);
            app.DataHubLamp = uiimage(app.NavBar);
            app.DataHubLamp.ImageClickedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.DataHubLamp.Visible = 'off';
            app.DataHubLamp.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'red-circle-blink.gif');
            app.FigurePosition = uiimage(app.NavBar);
            app.FigurePosition.ImageClickedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.FigurePosition.Visible = 'off';
            app.FigurePosition.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'screen-normal-24px-white.svg');
            app.AppInfo = uiimage(app.NavBar);
            app.AppInfo.ImageClickedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.AppInfo.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'kebab-vertical-24px-white.svg');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.Tag = 'winMonitorRNI';
            app.contextmenu_merge = uimenu(app.ContextMenu);
            app.contextmenu_merge.MenuSelectedFcn = createCallbackFcn(app, @Toolbar_MergeFilesImageClicked, true);
            app.contextmenu_merge.Enable = 'off';
            app.contextmenu_merge.Text = '🔀 Mesclar';
            app.contextmenu_del = uimenu(app.ContextMenu);
            app.contextmenu_del.MenuSelectedFcn = createCallbackFcn(app, @ContextMenu_DeleteSelectedTreeNode, true);
            app.contextmenu_del.Enable = 'off';
            app.contextmenu_del.Text = '❌ Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMonitorRNI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end