classdef winRNI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        popupContainerGrid    matlab.ui.container.GridLayout
        SplashScreen          matlab.ui.control.Image
        menu_Grid             matlab.ui.container.GridLayout
        dockModule_Undock     matlab.ui.control.Image
        dockModule_Close      matlab.ui.control.Image
        AppInfo               matlab.ui.control.Image
        FigurePosition        matlab.ui.control.Image
        jsBackDoor            matlab.ui.control.HTML
        menu_Button4          matlab.ui.control.StateButton
        menu_Separator2       matlab.ui.control.Image
        menu_Button3          matlab.ui.control.StateButton
        menu_Button2          matlab.ui.control.StateButton
        menu_Separator1       matlab.ui.control.Image
        menu_Button1          matlab.ui.control.StateButton
        TabGroup              matlab.ui.container.TabGroup
        Tab1_File             matlab.ui.container.Tab
        file_Grid             matlab.ui.container.GridLayout
        file_panelGrid        matlab.ui.container.GridLayout
        file_MetadataPanel    matlab.ui.container.Panel
        file_MetadataGrid     matlab.ui.container.GridLayout
        file_Metadata         matlab.ui.control.HTML
        file_MetadataLabel    matlab.ui.control.Label
        file_docGrid          matlab.ui.container.GridLayout
        file_Tree             matlab.ui.container.Tree
        file_TreeLabel        matlab.ui.control.Label
        file_TitleGrid        matlab.ui.container.GridLayout
        file_Title            matlab.ui.control.Label
        file_TitleIcon        matlab.ui.control.Image
        file_toolGrid         matlab.ui.container.GridLayout
        file_OpenFileButton   matlab.ui.control.Image
        Tab2_MonitoringPlan   matlab.ui.container.Tab
        Tab3_ExternalRequest  matlab.ui.container.Tab
        Tab4_Config           matlab.ui.container.Tab
        file_ContextMenu      matlab.ui.container.ContextMenu
        file_TreeNodeDelete   matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        General
        General_I
        rootFolder

        % Essa propriedade registra o tipo de execução da aplicação, podendo
        % ser: 'built-in', 'desktopApp' ou 'webApp'.
        executionMode
        
        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj

        % O MATLAB não renderiza alguns dos componentes de abas (do TabGroup) 
        % não visíveis. E a customização de componentes, usando a lib ccTools, 
        % somente é possível após a sua renderização. Controla-se a aplicação 
        % da customizaçao por meio dessa propriedade jsBackDoorFlag.
        tabGroupController
        jsBackDoorFlag = {true, ...
                          true, ...
                          true, ...
                          true};

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        rfDataHub
        rfDataHubLOG
        rfDataHubSummary

        %-----------------------------------------------------------------%
        % ESPECIFICIDADES
        %-----------------------------------------------------------------%
        Data_PA_RNI            % Dados das estações do Plano Anual de RNI


        Path_Data_PA_RNI_Out   % Path do arquivo gerado pelo AppRNI
        Data_Meas_Probes       % Dados das medições obtidas pelas em campo pelas sondas       
        Data_Meas_cache        % Dados de todas as informações de Data_PA_RNI em cache 
        UTTable_Formated       % Dados de todas as informações da UITable inicial formatada em cache
        Data_Meas_cache_Select % Dados filtrados de Data_PA_RNI em cache
        Data_Meas_cache_All    % Dados de todas as informações Calculadas de de Data_PA_RNI em cache 
        Data_PA_RNI_Out        % Dados das  medições de RNI calculados que serão gravados no arquivo XLS  
        Data_Localidades       % Dados da Tabela das Localidades do Brasil
        Data_Serv_Rad_Tel      % Dados dos serviços de Radiodifusão e de Telecom fiscalizados pela Anatel
        
        metaData       = class.metaData.empty
        metaData_cache = class.metaData.empty
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        % JSBACKDOOR
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource           = ccTools.fcn.jsBackDoorHTMLSource();
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoor_Listener(app, evt);
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Listener(app, event)
            switch event.HTMLEventName
                case 'credentialDialog'
                    fiscalizaLibConnection.report_Connect(app, event.HTMLEventData, 'OpenConnection')

                case 'BackgroundColorTurnedInvisible'
                    switch event.HTMLEventData
                        case 'SplashScreen'
                            if isvalid(app.popupContainerGrid)
                                delete(app.popupContainerGrid)
                            end
                        otherwise
                            % ...
                    end

                case 'app.file_Tree'
                    file_ContextMenu_delTreeNodeSelected(app)
            end
            drawnow
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            % O menu gráfico controla, programaticamente, qual das abas de
            % app.ControlTabGroup estará visível. 

            % Lembrando que o MATLAB renderiza em tela apenas as abas visíveis.
            % Por isso as customizações de abas e suas subabas somente é possível 
            % após a renderização da aba.
            switch tabIndex
                case 0 % STARTUP
                    app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);

                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        'body',                           ...
                                                                                           'classAttributes', ['--tabButton-border-color: #fff;' ...
                                                                                                               '--tabContainer-border-color: #fff;']));

                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                           'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                               '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                               '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);'        ...
                                                                                                               '--mw-backgroundColor-tab: #fff;']));

                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                           'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));

                otherwise
                    if any(app.jsBackDoorFlag{tabIndex})
                        app.jsBackDoorFlag{tabIndex} = false;

                        switch tabIndex
                            case 1 % FILE
                                app.file_Tree.UserData = struct(app.file_Tree).Controller.ViewModel.Id;
                                sendEventToHTMLSource(app.jsBackDoor, 'addKeyDownListener', struct('componentName', 'app.file_Tree', 'componentDataTag', app.file_Tree.UserData, 'keyEvents', "Delete"))

                            otherwise
                                % ...
                        end
                    end
            end
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)

                drawnow
    
                app.executionMode = appUtil.ExecutionMode(app.UIFigure);
                switch app.executionMode
                    case 'webApp'
                        % ...
    
                    otherwise
                        % Configura o tamanho mínimo da janela.
                        app.FigurePosition.Visible = 1;
                        appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
                end
    
                appName           = class.Constants.appName;
                MFilePath         = fileparts(mfilename('fullpath'));
                app.rootFolder    = appUtil.RootFolder(appName, MFilePath);
                
                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app, 0)
                jsBackDoor_Customizations(app, 1)
    
                % Leitura do arquivo "GeneralSettings.json".
                startup_ConfigFileRead(app)
                startup_AppProperties(app)
                startup_GUIComponents(app)
    
                % Para criação de arquivos temporários, cria-se uma pasta da 
                % sessão.
                tempDir = tempname;
                mkdir(tempDir)
                app.General_I.fileFolder.tempPath = tempDir;
    
                switch app.executionMode
                    case 'webApp'
                        % Força a exclusão do SplashScreen do MATLAB WebDesigner.
                        sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");
    
                        % Webapp também não suporta outras janelas, de forma que os 
                        % módulos auxiliares devem ser abertos na própria janela
                        % do appAnalise.
                        app.dockModule_Undock.Visible     = 0;
    
                        app.General_I.operationMode.Debug = false;
                        app.General_I.operationMode.Dock  = true;
                        
                        % A pasta do usuário não é configurável, mas obtida por 
                        % meio de chamada a uiputfile. 
                        app.General_I.fileFolder.userPath = tempDir;
    
                    otherwise    
                        % Resgata a pasta de trabalho do usuário (configurável).
                        userPaths = appUtil.UserPaths(app.General_I.fileFolder.userPath);
                        app.General_I.fileFolder.userPath = userPaths{end};
    
                        switch app.executionMode
                            case 'desktopStandaloneApp'
                                app.General_I.operationMode.Debug = false;
                            case 'MATLABEnvironment'
                                app.General_I.operationMode.Debug = true;
                                % app.General_I.operationMode.Dock  = true;               % APENAS PARA TESTE, CASO QUEIRA VER OS MÓDULOS RENDERIZADOS NO PRÓPRIO APP! REMOVER DEPOIS.
                        end
                end
                app.General.operationMode = app.General_I.operationMode;
                app.General.fileFolder    = app.General_I.fileFolder;

                % Leitura de arquivo relacionado ao PM-RNI
                [app.Data_PA_RNI, app.Path_Data_PA_RNI_Out] = fcn.ReadFile_PA_RNI(app.rootFolder, app.General.fileFolder.userPath);
                
                % Torna visível o container do auxApp.popupContainer, forçando
                % a exclusão do SplashScreen.
                sendEventToHTMLSource(app.jsBackDoor, 'turningBackgroundColorInvisible', struct('componentName', 'SplashScreen', 'componentDataTag', struct(app.SplashScreen).Controller.ViewModel.Id));
                drawnow
    
                % Força a exclusão do SplashScreen.
                app.TabGroup.Visible = 1;
                if isvalid(app.SplashScreen)
                    pause(1)
                    delete(app.SplashScreen)
                    app.popupContainerGrid.Visible = 0;
                end
            end
        end

        %-----------------------------------------------------------------%
        function startup_ConfigFileRead(app)
            % "GeneralSettings.json"
            [app.General_I, msgWarning] = appUtil.generalSettingsLoad(class.Constants.appName, app.rootFolder);
            if ~isempty(msgWarning)
                appUtil.modalWindow(app.UIFigure, 'error', msgWarning);
            end

            app.General            = app.General_I;
            app.General.AppVersion = fcn.envVersion(app.rootFolder, 'full');
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)
            % app.rfDataHub
            global RFDataHub
            global RFDataHubLog
            
            app.rfDataHub = RFDataHub;
            app.rfDataHubLOG = RFDataHubLog;
            
            % Contorna erro da função inROI, que retorna como se todos os
            % pontos estivessem internos ao ROI, quando as coordenadas
            % estão em float32. No float64 isso não acontece... aberto BUG
            % na Mathworks, que indicou estar ciente.
            app.rfDataHub.Latitude    = double(app.rfDataHub.Latitude);
            app.rfDataHub.Longitude   = double(app.rfDataHub.Longitude);

            % app.rfDataHubSummary
            app.rfDataHubSummary = summary(RFDataHub);
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            % Cria o objeto que conecta o TabGroup com o GraphicMenu.
            app.tabGroupController = tabGroupGraphicMenu(app.menu_Grid, app.TabGroup, app.progressDialog, @app.jsBackDoor_Customizations, '');

            addComponent(app.tabGroupController, "Built-in", "",                          app.menu_Button1, "AlwaysOn", struct('On', 'OpenFile_32Yellow.png',         'Off', 'OpenFile_32White.png'),          matlab.graphics.GraphicsPlaceholder, 1)
            addComponent(app.tabGroupController, "External", "auxApp.winMonitoringPlan",  app.menu_Button2, "AlwaysOn", struct('On', 'DriveTestDensity_32Yellow.png', 'Off', 'DriveTestDensity_32White.png'),  app.menu_Button1,                    2)
            addComponent(app.tabGroupController, "External", "auxApp.winExternalRequest", app.menu_Button3, "AlwaysOn", struct('On', 'Report_32Yellow.png',           'Off', 'Report_32White.png'),            app.menu_Button1,                    3)
            addComponent(app.tabGroupController, "External", "auxApp.winConfig",          app.menu_Button4, "AlwaysOn", struct('On', 'Settings_36Yellow.png',         'Off', 'Settings_36White.png'),          app.menu_Button1,                    4)
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function inputArguments = menu_auxAppInputArguments(app, auxAppName)
            arguments
                app
                auxAppName char {mustBeMember(auxAppName, {'FILE', 'MONITORINGPLAN', 'EXTERNALREQUEST', 'CONFIG'})}
            end

            switch auxAppName
                case {'FILE', 'MONITORINGPLAN', 'EXTERNALREQUEST', 'CONFIG'}
                    inputArguments = {app};
            end
        end

        %-----------------------------------------------------------------%
        function file_TreeBuilding(app)
            delete(app.file_Tree.Children);

            for ii = 1:numel(app.metaData)
                uitreenode(app.file_Tree, 'Text',        app.metaData(ii).Filename, ...
                                          'NodeData',    ii,                        ...
                                          'ContextMenu', app.file_ContextMenu);
            end

            if ~isempty(app.metaData)
                app.file_Tree.SelectedNodes = app.file_Tree.Children(1);
                file_TreeSelectionChanged(app)
            else
                app.file_Metadata.HTMLSource = ' ';
            end
        end
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function appBackDoor(app, callingApp, operationType, varargin)
            try
                switch class(callingApp)
                    case {'auxApp.winMonitoringPlan',  'auxApp.winMonitoringPlan_exported',  ...
                          'auxApp.winExternalRequest', 'auxApp.winExternalRequest_exported', ...
                          'auxApp.winConfig',          'auxApp.winConfig_exported'}

                        switch operationType
                            case 'closeFcn'
                                auxAppTag = varargin{1};
                                closeModule(app.tabGroupController, auxAppTag, app.General)

                            otherwise
                                error('UnexpectedCall')
                        end
    
                    otherwise
                        error('UnexpectedCall')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);            
            end

            % Caso um app auxiliar esteja em modo DOCK, o progressDialog do
            % app auxiliar coincide com o do appAnalise. Força-se, portanto, 
            % a condição abaixo para evitar possível bloqueio da tela.
            app.progressDialog.Visible = 'hidden';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            try
                % WARNING MESSAGES
                appUtil.disablingWarningMessages()

                % <GUI>
                app.UIFigure.Position(4) = 660;
                app.popupContainerGrid.Layout.Row = [1,2];
                app.GridLayout.RowHeight(end) = [];
                % </GUI>

                appUtil.winPosition(app.UIFigure)
                jsBackDoor_Initialization(app)
                startup_timerCreation(app)

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            % TIMER
            h = timerfindall;
            if ~isempty(h)
                stop(h); delete(h); clear h
            end

            % PROGRESS DIALOG
            delete(app.progressDialog)

            % DELETE TEMP FILES
            rmdir(app.General_I.fileFolder.tempPath, 's');

            % DELETE APPS
            if isdeployed
                delete(findall(groot, 'Type', 'Figure'))
            else
                delete(app.tabGroupController)                
            end

            % MATLAB RUNTIME
            % Ao fechar um webapp, o MATLAB WebServer demora uns 10 segundos para
            % fechar o Runtime que suportava a sessão do webapp. Dessa forma, a 
            % liberação do recurso, que ocorre com a inicialização de uma nova 
            % sessão do Runtime, fica comprometida.
            appUtil.killingMATLABRuntime(app.executionMode)

            delete(app)
            
        end

        % Value changed function: menu_Button1, menu_Button2, 
        % ...and 2 other components
        function menu_mainButtonPushed(app, event)
            
            % em sendo o ADICIONAR TAREFA, verificar se existe alguma
            % tarefa selecionado em tabela. Caso sim, pergunta se se deseja
            % editar ou adicionar uma nova. ao incluir a tarefa, o módulo é
            % encerrado.

            clickedButton  = event.Source;
            auxAppTag      = clickedButton.Tag;
            inputArguments = menu_auxAppInputArguments(app, auxAppTag);

            openModule(app.tabGroupController, event.Source, event.PreviousValue, app.General, inputArguments{:})

        end

        % Image clicked function: file_OpenFileButton
        function file_OpenFileButtonImageClicked(app, event)

            % Abre o diretório dos arquivos das medições das sondas
            initialPath = fullfile(app.rootFolder, 'DataBase', 'Meas_Sondas');
            
            % Abrir a caixa de diálogo para selecionar múltiplos arquivos
            [fileName, folderName] = uigetfile({'*.txt';'*.csv';'*.mat';'*.*'}, ...
                                              'Selecione arquivos', initialPath, ...
                                              'MultiSelect', 'on');
            %Mantém a Tela principal (figure) em primeiro plano
            figure(app.UIFigure);
            
            % Verifica se algum arquivo foi selecionado
            if isequal(fileName, 0)
                uialert(app.UIFigure, 'Nenhum arquivo selecionado! Selecione algum arquivo de medição de RNI', 'Aviso')
                return
            end

            % Verifica o tipo de arquivo que foi selecionado
            if ischar(fileName)
                fileName = {fileName};
            end
            fileFullName = fullfile(folderName, fileName);

            % Atualiza a informação de app.metaData e app.metaDataCache,
            % caso necessário.

            ArquivosJaLidos = {};

            for ff = 1:numel(fileFullName)
                % (a) ESTÁ EM CACHE?
                [~, idxExistFileCache] = ismember(fileFullName{ff}, {app.metaData_cache.Filename});
                if ~idxExistFileCache
                    % Extrai do arquivo a informação sobre o tipo de sonda que gerou o arquivo de medição
                    Type_Meas_Probes = fcn.TypeMeasProbe(app, fileFullName{ff});
    
                    % Obtém todas os dados relavantes dos arquivos das medições de RNI
                    app.metaData_cache(end+1) = fcn.ReadFile_Meas_Probes(app, Type_Meas_Probes, fileFullName{ff}, ff, numel(fileFullName));
                    idxExistFileCache = numel(app.metaData_cache);
                end

                % (b) ESTÁ EM METADATA?
                [~, idxExistFile] = ismember(fileFullName{ff}, {app.metaData.Filename});
                if ~idxExistFile
                    app.metaData(end+1) = app.metaData_cache(idxExistFileCache);
                else
                    ArquivosJaLidos{end+1} = fileFullName{ff};
                end
            end

            if ~isempty(ArquivosJaLidos)
                msgWarning = sprintf('Arquivos já lidos: %s', strjoin(ArquivosJaLidos));
                appUtil.modalWindow(app.UIFigure, "warning", msgWarning);
            end

            % Armazenar os dados de medições dos arquivos
            listOfTables = {app.metaData.Data};            
            app.Data_Meas_Probes = sortrows(vertcat(listOfTables{:}), 'Timestamp', 'descend');
            
            % Escreve na app.Tree as informações relacionadas a localidade
            file_TreeBuilding(app)

        end

        % Image clicked function: AppInfo, FigurePosition
        function menu_ToolbarImageCliced(app, event)
            
            focus(app.jsBackDoor)

            switch event.Source
                case app.FigurePosition
                    app.UIFigure.Position(3:4) = class.Constants.windowSize;
                    appUtil.winPosition(app.UIFigure)

                case app.AppInfo
                    if isempty(app.AppInfo.Tag)
                        app.progressDialog.Visible = 'visible';
                        app.AppInfo.Tag = fcn.htmlCode_appInfo(app.General, app.rootFolder, app.executionMode);
                        app.progressDialog.Visible = 'hidden';
                    end

                    msgInfo = app.AppInfo.Tag;
                    appUtil.modalWindow(app.UIFigure, 'info', msgInfo);
            end

        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function menu_DockButtonPushed(app, event)
            
            clickedButton = findobj(app.menu_Grid, 'Type', 'uistatebutton', 'Value', true);
            auxAppTag     = clickedButton.Tag;

            switch event.Source
                case app.dockModule_Undock
                    initialDockState = app.General.operationMode.Dock;
                    app.General.operationMode.Dock = false;

                    inputArguments   = menu_auxAppInputArguments(app, auxAppTag);
                    closeModule(app.tabGroupController, auxAppTag, app.General)
                    openModule(app.tabGroupController, clickedButton, false, app.General, inputArguments{:})

                    app.General.operationMode.Dock = initialDockState;

                case app.dockModule_Close
                    closeModule(app.tabGroupController, auxAppTag, app.General)
            end

        end

        % Selection changed function: file_Tree
        function file_TreeSelectionChanged(app, event)
            
            % metaData       = struct('Filename', {}, 'Measures', {}, 'Sensor', {}, 'Data', {}, 'LatitudeLimits', {}, 'LongitudeLimits', {}, 'MetaDataProbe', {})
            idx = app.file_Tree.SelectedNodes.NodeData;
            
            app.file_Metadata.HTMLSource = sprintf(['<p style="font-family: Helvetica, Arial, sans-serif; font-size: 11; text-align: justify; ' ...
                                                    'line-height: 12px; margin: 5px; word-break: break-all;">Arquivo: %s<br>Sensor: %s<br>'     ...
                                                    'Localidade: %s</p>'], app.metaData(idx).Filename, app.metaData(idx).Sensor, app.metaData(idx).Location);
            
        end

        % Menu selected function: file_TreeNodeDelete
        function file_ContextMenu_delTreeNodeSelected(app, event)
            
            if ~isempty(app.file_Tree.SelectedNodes)
                idx = app.file_Tree.SelectedNodes.NodeData;
                app.metaData(idx) = [];
                file_TreeBuilding(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'RNI';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1_File = uitab(app.TabGroup);
            app.Tab1_File.Title = 'FILE';
            app.file_Grid = uigridlayout(app.Tab1_File);
            app.file_toolGrid = uigridlayout(app.file_Grid);
            app.file_OpenFileButton = uiimage(app.file_toolGrid);
            app.file_OpenFileButton.ImageClickedFcn = createCallbackFcn(app, @file_OpenFileButtonImageClicked, true);
            app.file_OpenFileButton.Tooltip = {'Seleciona arquivos'};
            app.file_OpenFileButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Import_16.png');
            app.file_docGrid = uigridlayout(app.file_Grid);
            app.file_TitleGrid = uigridlayout(app.file_docGrid);
            app.file_TitleGrid.Tag = 'COLORLOCKED';
            app.file_TitleIcon = uiimage(app.file_TitleGrid);
            app.file_TitleIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addFiles_32.png');
            app.file_Title = uilabel(app.file_TitleGrid);
            app.file_Title.Text = 'ARQUIVOS';
            app.file_TreeLabel = uilabel(app.file_docGrid);
            app.file_TreeLabel.Text = 'LISTA DE ARQUIVOS';
            app.file_Tree = uitree(app.file_docGrid);
            app.file_Tree.SelectionChangedFcn = createCallbackFcn(app, @file_TreeSelectionChanged, true);
            app.file_panelGrid = uigridlayout(app.file_Grid);
            app.file_MetadataLabel = uilabel(app.file_panelGrid);
            app.file_MetadataLabel.Text = 'METADADOS';
            app.file_MetadataPanel = uipanel(app.file_panelGrid);
            app.file_MetadataGrid = uigridlayout(app.file_MetadataPanel);
            app.file_Metadata = uihtml(app.file_MetadataGrid);
            app.file_Metadata.HTMLSource = ' ';
            app.Tab2_MonitoringPlan = uitab(app.TabGroup);
            app.Tab2_MonitoringPlan.Title = 'MONITORINGPLAN';
            app.Tab3_ExternalRequest = uitab(app.TabGroup);
            app.Tab3_ExternalRequest.Title = 'EXTERNALREQUEST';
            app.Tab4_Config = uitab(app.TabGroup);
            app.Tab4_Config.Title = 'CONFIG';
            app.menu_Grid = uigridlayout(app.GridLayout);
            app.menu_Grid.Tag = 'COLORLOCKED';
            app.menu_Button1 = uibutton(app.menu_Grid, 'state');
            app.menu_Button1.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button1.Tag = 'FILE';
            app.menu_Button1.Tooltip = {'Leitura de arquivos'};
            app.menu_Button1.Icon = fullfile(pathToMLAPP, 'Icons', 'OpenFile_32Yellow.png');
            app.menu_Button1.Text = '';
            app.menu_Button1.Value = true;
            app.menu_Separator1 = uiimage(app.menu_Grid);
            app.menu_Separator1.Enable = 'off';
            app.menu_Separator1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineV_White.png');
            app.menu_Button2 = uibutton(app.menu_Grid, 'state');
            app.menu_Button2.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button2.Tag = 'MONITORINGPLAN';
            app.menu_Button2.Tooltip = {'PM-RNI'};
            app.menu_Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'DriveTestDensity_32White.png');
            app.menu_Button2.Text = '';
            app.menu_Button3 = uibutton(app.menu_Grid, 'state');
            app.menu_Button3.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button3.Tag = 'EXTERNALREQUEST';
            app.menu_Button3.Tooltip = {'Demanda externa'};
            app.menu_Button3.Icon = fullfile(pathToMLAPP, 'Icons', 'Report_32White.png');
            app.menu_Button3.Text = '';
            app.menu_Separator2 = uiimage(app.menu_Grid);
            app.menu_Separator2.Enable = 'off';
            app.menu_Separator2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineV_White.png');
            app.menu_Button4 = uibutton(app.menu_Grid, 'state');
            app.menu_Button4.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button4.Tag = 'CONFIG';
            app.menu_Button4.Tooltip = {'Configurações gerais'};
            app.menu_Button4.Icon = fullfile(pathToMLAPP, 'Icons', 'Settings_36White.png');
            app.menu_Button4.Text = '';
            app.jsBackDoor = uihtml(app.menu_Grid);
            app.FigurePosition = uiimage(app.menu_Grid);
            app.FigurePosition.ImageClickedFcn = createCallbackFcn(app, @menu_ToolbarImageCliced, true);
            app.FigurePosition.Visible = 'off';
            app.FigurePosition.ImageSource = fullfile(pathToMLAPP, 'Icons', 'layout1_32White.png');
            app.AppInfo = uiimage(app.menu_Grid);
            app.AppInfo.ImageClickedFcn = createCallbackFcn(app, @menu_ToolbarImageCliced, true);
            app.AppInfo.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Dots_32White.png');
            app.dockModule_Close = uiimage(app.menu_Grid);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @menu_DockButtonPushed, true);
            app.dockModule_Close.Tag = 'DRIVETEST';
            app.dockModule_Close.Tooltip = {'Fecha módulo'};
            app.dockModule_Close.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Delete_12SVG_white.svg');
            app.dockModule_Undock = uiimage(app.menu_Grid);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @menu_DockButtonPushed, true);
            app.dockModule_Undock.Tag = 'DRIVETEST';
            app.dockModule_Undock.Tooltip = {'Reabre módulo em outra janela'};
            app.dockModule_Undock.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Undock_18White.png');
            app.popupContainerGrid = uigridlayout(app.GridLayout);
            app.SplashScreen = uiimage(app.popupContainerGrid);
            app.SplashScreen.ImageSource = fullfile(pathToMLAPP, 'Icons', 'SplashScreen.gif');
            app.file_ContextMenu = uicontextmenu(app.UIFigure);
            app.file_TreeNodeDelete = uimenu(app.file_ContextMenu);
            app.file_TreeNodeDelete.MenuSelectedFcn = createCallbackFcn(app, @file_ContextMenu_delTreeNodeSelected, true);
            app.file_TreeNodeDelete.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winRNI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end